classdef GSLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GSLabUIFigure                 matlab.ui.Figure
        Filterbefore                  matlab.ui.control.CheckBox
        vlabel                        matlab.ui.control.Label
        barra                         matlab.ui.control.Image
        hidecalib                     matlab.ui.control.Button
        ExportType                    matlab.ui.control.CheckBox
        AxisContainer                 matlab.ui.container.Panel
        TabEixos                      matlab.ui.control.UIAxes
        CalibHolder                   matlab.ui.container.Panel
        Calibrationtype               matlab.ui.container.ButtonGroup
        Manualcalib                   matlab.ui.control.RadioButton
        Filecalib                     matlab.ui.control.RadioButton
        CalibrationLifetimensLabel    matlab.ui.control.Label
        LT                            matlab.ui.control.NumericEditField
        DoCalib                       matlab.ui.control.CheckBox
        CalibrationPanel              matlab.ui.container.Panel
        manualcalibpanel              matlab.ui.container.Panel
        modL                          matlab.ui.control.Label
        phaL                          matlab.ui.control.Label
        modS                          matlab.ui.control.Spinner
        phaS                          matlab.ui.control.Spinner
        ClearButton                   matlab.ui.control.Button
        calTable                      matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        FileTab                       matlab.ui.container.Tab
        Tree                          matlab.ui.container.CheckBoxTree
        ImageTab                      matlab.ui.container.Tab
        integrateRegions              matlab.ui.control.CheckBox
        IntensityLabel_2              matlab.ui.control.Label
        Imin                          matlab.ui.control.NumericEditField
        Imax                          matlab.ui.control.NumericEditField
        Islide                        matlab.ui.control.RangeSlider
        Logp                          matlab.ui.control.CheckBox
        logint                        matlab.ui.control.CheckBox
        Excess                        matlab.ui.control.CheckBox
        hdr                           matlab.ui.control.CheckBox
        relative                      matlab.ui.control.CheckBox
        AutoIrangeButton              matlab.ui.control.Button
        GaussianFilter                matlab.ui.control.NumericEditField
        masktype                      matlab.ui.control.DropDown
        TomaskButton                  matlab.ui.control.Button
        Applymask                     matlab.ui.control.CheckBox
        Invertmask                    matlab.ui.control.CheckBox
        ClearButton_2                 matlab.ui.control.Button
        SavemaskButton                matlab.ui.control.Button
        LoadmaskButton                matlab.ui.control.Button
        overwritemask                 matlab.ui.control.CheckBox
        IntensityLabel                matlab.ui.control.Label
        histax                        matlab.ui.control.UIAxes
        MKax                          matlab.ui.control.UIAxes
        PhasorTab                     matlab.ui.container.Tab
        NormColunits                  matlab.ui.control.CheckBox
        CLabel                        matlab.ui.control.Label
        GLabel                        matlab.ui.control.Label
        SLabel                        matlab.ui.control.Label
        AnscombeCheckBox              matlab.ui.control.CheckBox
        WaveletFamilyDropDown         matlab.ui.control.DropDown
        WavSupp                       matlab.ui.control.DropDown
        PhasorfilterDropDown          matlab.ui.control.DropDown
        FilterLabel                   matlab.ui.control.Label
        AutoButton_3                  matlab.ui.control.Button
        AutoButton_2                  matlab.ui.control.Button
        Eb                            matlab.ui.control.NumericEditField
        Filtermatch                   matlab.ui.control.Label
        TiLab                         matlab.ui.control.Label
        Ti                            matlab.ui.control.NumericEditField
        Smax                          matlab.ui.control.NumericEditField
        Smin                          matlab.ui.control.NumericEditField
        Gmax                          matlab.ui.control.NumericEditField
        Gmin                          matlab.ui.control.NumericEditField
        PhasorresEditField            matlab.ui.control.NumericEditField
        Cmax                          matlab.ui.control.NumericEditField
        Cmin                          matlab.ui.control.NumericEditField
        AutoButton                    matlab.ui.control.Button
        PlotTrueSize                  matlab.ui.control.Button
        PhasorresEditFieldLabel       matlab.ui.control.Label
        CenterLabel                   matlab.ui.control.Label
        Gcenter                       matlab.ui.control.NumericEditField
        Scenter                       matlab.ui.control.NumericEditField
        SaveCursorsButton             matlab.ui.control.Button
        LoadCursorsButton             matlab.ui.control.Button
        Line1                         matlab.ui.control.Label
        MeasuredmagnitudeButtonGroup  matlab.ui.container.ButtonGroup
        Phaserad                      matlab.ui.control.RadioButton
        TauPhaserad                   matlab.ui.control.RadioButton
        Grad                          matlab.ui.control.RadioButton
        Srad                          matlab.ui.control.RadioButton
        Modrad                        matlab.ui.control.RadioButton
        TauModrad                     matlab.ui.control.RadioButton
        NormPhaserad                  matlab.ui.control.RadioButton
        TauNormrad                    matlab.ui.control.RadioButton
        CustomAngular                 matlab.ui.control.RadioButton
        CustomRadial                  matlab.ui.control.RadioButton
        CrangeLabel                   matlab.ui.control.Label
        GrangeLabel                   matlab.ui.control.Label
        SrangeLabel                   matlab.ui.control.Label
        DisplayTab                    matlab.ui.container.Tab
        contourOnly                   matlab.ui.control.CheckBox
        logDens                       matlab.ui.control.CheckBox
        normFile                      matlab.ui.control.CheckBox
        contours                      matlab.ui.control.CheckBox
        NormDecays                    matlab.ui.control.CheckBox
        LogDecays                     matlab.ui.control.CheckBox
        PlotDecays                    matlab.ui.control.Button
        tickfontsize                  matlab.ui.control.NumericEditField
        fontsizeLabel                 matlab.ui.control.Label
        ticks                         matlab.ui.control.CheckBox
        axes                          matlab.ui.control.CheckBox
        fontsizeLabel_2               matlab.ui.control.Label
        UCcb                          matlab.ui.control.CheckBox
        tickNumber                    matlab.ui.control.NumericEditField
        Harm                          matlab.ui.control.NumericEditField
        AFSef                         matlab.ui.control.NumericEditField
        PhBg                          matlab.ui.control.CheckBox
        SoftCursor                    matlab.ui.control.CheckBox
        imagewindowtile               matlab.ui.control.CheckBox
        imagerows                     matlab.ui.control.NumericEditField
        imagecols                     matlab.ui.control.NumericEditField
        margns                        matlab.ui.control.CheckBox
        fliprows                      matlab.ui.control.CheckBox
        tilinglabel                   matlab.ui.control.Label
        freqEF                        matlab.ui.control.NumericEditField
        freqLab                       matlab.ui.control.Label
        SpecMin                       matlab.ui.control.NumericEditField
        SpecMax                       matlab.ui.control.NumericEditField
        sBandLab                      matlab.ui.control.Label
        Style                         matlab.ui.container.ButtonGroup
        IvMagStyle                    matlab.ui.control.RadioButton
        UmapStyle                     matlab.ui.control.RadioButton
        SpectralStyle                 matlab.ui.control.RadioButton
        LifetimeStyle                 matlab.ui.control.RadioButton
        AxesfontsizeEditFieldLabel_2  matlab.ui.control.Label
        HarmonicLabel                 matlab.ui.control.Label
        ClusterTab                    matlab.ui.container.Tab
        ModelDropDown                 matlab.ui.control.DropDown
        ModelDropDownLabel            matlab.ui.control.Label
        TrainModelButton              matlab.ui.control.Button
        ClearModelButton              matlab.ui.control.Button
        dispStyle                     matlab.ui.container.ButtonGroup
        conttogether                  matlab.ui.control.RadioButton
        contseparate                  matlab.ui.control.RadioButton
        conf                          matlab.ui.control.NumericEditField
        elipse                        matlab.ui.control.RadioButton
        ApplyModelButton              matlab.ui.control.Button
        dispStyle2                    matlab.ui.container.ButtonGroup
        hard                          matlab.ui.control.RadioButton
        prob                          matlab.ui.control.RadioButton
        blendamount                   matlab.ui.control.NumericEditField
        blend                         matlab.ui.control.CheckBox
        gmmexport                     matlab.ui.control.CheckBox
        UnmixTab                      matlab.ui.container.Tab
        ShowComponents                matlab.ui.control.CheckBox
        AddLines                      matlab.ui.control.CheckBox
        ComponentTree                 matlab.ui.container.CheckBoxTree
        SourceButtonGroup             matlab.ui.container.ButtonGroup
        CursorButton                  matlab.ui.control.RadioButton
        FileButton                    matlab.ui.control.RadioButton
        InputButton                   matlab.ui.control.RadioButton
        CompImsExp                    matlab.ui.control.CheckBox
        CompImsCol                    matlab.ui.control.CheckBox
        singleimage                   matlab.ui.control.CheckBox
        groupfile                     matlab.ui.control.CheckBox
        addmargins                    matlab.ui.control.CheckBox
        mergecomps                    matlab.ui.control.CheckBox
        ComputeLR                     matlab.ui.control.Button
        ComputeHR                     matlab.ui.control.Button
        CompImsIntExp                 matlab.ui.control.CheckBox
        CompImsCSVExp                 matlab.ui.control.CheckBox
        includeint                    matlab.ui.control.CheckBox
        exportratiometric             matlab.ui.control.CheckBox
        comp1                         matlab.ui.control.NumericEditField
        comp2                         matlab.ui.control.NumericEditField
        ComponentvsLabel              matlab.ui.control.Label
        ratiocsv                      matlab.ui.control.CheckBox
        ComponentsPanel               matlab.ui.container.Panel
        minusB                        matlab.ui.control.Button
        plusB                         matlab.ui.control.Button
        ExportTab                     matlab.ui.container.Tab
        ImWinClipButton               matlab.ui.control.Button
        PhWinClipButton               matlab.ui.control.Button
        LabelCoords                   matlab.ui.control.CheckBox
        LabelFmt                      matlab.ui.control.DropDown
        GScoords                      matlab.ui.control.CheckBox
        CoordsFmtDD                   matlab.ui.control.DropDown
        BrowseResults                 matlab.ui.control.Button
        ReLab                         matlab.ui.control.Label
        ExDecay                       matlab.ui.control.CheckBox
        DeacayFileDropDown            matlab.ui.control.DropDown
        Cbar                          matlab.ui.control.CheckBox
        PhRb                          matlab.ui.control.CheckBox
        Expptrans                     matlab.ui.control.CheckBox
        ExPhasor                      matlab.ui.control.CheckBox
        ExportPhasorFileDropDown      matlab.ui.control.DropDown
        ExportLRButton                matlab.ui.control.Button
        ExportHRButton                matlab.ui.control.Button
        ExpImWinButton                matlab.ui.control.Button
        ExpPhWinButton                matlab.ui.control.Button
        ExInt                         matlab.ui.control.CheckBox
        ExportImageFileDropDown       matlab.ui.control.DropDown
        allasLabel                    matlab.ui.control.Label
        ExMagH                        matlab.ui.control.CheckBox
        ExMagS                        matlab.ui.control.CheckBox
        ExCurH                        matlab.ui.control.CheckBox
        ExCurS                        matlab.ui.control.CheckBox
        Combine                       matlab.ui.control.CheckBox
        groupfiles                    matlab.ui.control.CheckBox
        STab                          matlab.ui.container.Tab
        autotab                       matlab.ui.control.CheckBox
        AddFolders                    matlab.ui.control.CheckBox
        UpdateButton                  matlab.ui.control.Button
        DarkmodeCheckBox              matlab.ui.control.CheckBox
        UIcolorButton                 matlab.ui.control.Button
        RainbowcolormapDD             matlab.ui.control.DropDown
        CmapFlipDens                  matlab.ui.control.CheckBox
        PhasorcolormapDD              matlab.ui.control.DropDown
        CmapFlipMag                   matlab.ui.control.CheckBox
        MagcolLabel                   matlab.ui.control.Label
        PhasordensitycolorLabel       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        DownsamplingEditField         matlab.ui.control.NumericEditField
        DownsamplingEditFieldLabel    matlab.ui.control.Label
        HarmLim                       matlab.ui.control.NumericEditField
        MaxharmonicsEditFieldLabel    matlab.ui.control.Label
        LoadFLAME31nBwithBCheckBox    matlab.ui.control.CheckBox
        PromptFLIMlabsloadoptionCheckBox  matlab.ui.control.CheckBox
        LoadFLIMlabsrawCheckBox       matlab.ui.control.CheckBox
        IntDispTop                    matlab.ui.control.NumericEditField
        SrangeLabel_5                 matlab.ui.control.Label
        SrangeLabel_4                 matlab.ui.control.Label
        SrangeLabel_3                 matlab.ui.control.Label
        SrangeLabel_2                 matlab.ui.control.Label
        IntPtile                      matlab.ui.control.NumericEditField
        CoordPtile                    matlab.ui.control.NumericEditField
        ColPtile                      matlab.ui.control.NumericEditField
        CursorcolorsLabel             matlab.ui.control.Label
        Line2                         matlab.ui.control.Label
        Botonera                      matlab.ui.container.Panel
        X                             matlab.ui.control.Button
        PhasorPanel                   matlab.ui.container.Panel
        coolab                        matlab.ui.control.Label
        PP                            matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        CalibrationFileMenu           matlab.ui.container.Menu
        Load                          matlab.ui.container.Menu
        LoadTIFSequenceContextMenu    matlab.ui.container.Menu
        LoadFLAMErawContextMenu       matlab.ui.container.Menu
        LoadFALCONSnGContextMenu      matlab.ui.container.Menu
        LoadZeisslsmContextMenu       matlab.ui.container.Menu
        FLIMLABSjsonMenu              matlab.ui.container.Menu
        PicoQuantptuMenu              matlab.ui.container.Menu
        BeckerHicklsdtMenu            matlab.ui.container.Menu
        LoadSimFCSrefContextMenu      matlab.ui.container.Menu
        TracetxtcsvMenu               matlab.ui.container.Menu
        FLAMEcalibration              matlab.ui.container.Menu
        SavedSessionMenu              matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        asSimFCSrefMenu               matlab.ui.container.Menu
        CurrentSessionMenu            matlab.ui.container.Menu
        MoveMenu                      matlab.ui.container.Menu
        TopMenu                       matlab.ui.container.Menu
        UpMenu                        matlab.ui.container.Menu
        DownMenu                      matlab.ui.container.Menu
        BotMenu                       matlab.ui.container.Menu
        EvenOddMenu                   matlab.ui.container.Menu
        CheckMenu                     matlab.ui.container.Menu
        AllNoneMenu                   matlab.ui.container.Menu
        InvertMenu                    matlab.ui.container.Menu
        AlternateMenu                 matlab.ui.container.Menu
        EmphasisMenu                  matlab.ui.container.Menu
        SelectedMenu                  matlab.ui.container.Menu
        CheckedMenu                   matlab.ui.container.Menu
        NoneMenu                      matlab.ui.container.Menu
        ClearMenu                     matlab.ui.container.Menu
        AllMenu                       matlab.ui.container.Menu
        UncheckedMenu                 matlab.ui.container.Menu
    end


    properties (Access = private)
        % gg=addpath('scripts');
        folderC=[];
        folderD=[];
        folderR=[];
        folderS=[];% path to save params
        PCM='wSbZctglyGorrmp';% phasor colormap
        RCM='wvbclyorpw';% rainbow colormap
        CCM='rgbcmyauFltZ';%cursor colors
        single='rb';% single colors for cmap
        singhans=[];%handles for change color btn
        IIo=cell(0);SSo=cell(0);GGo=cell(0);% accumulate all images (HR dont change)
        IIa=cell(0);SSa=cell(0);GGa=cell(0);% accumulate all images (LR dont change)
        IIi=cell(0);SSi=cell(0);GGi=cell(0);% filtered versions (do change)
        MK=cell(0);MKp=cell(0);MKL=cell(0);% masks, first is for cursors, second is a saved version for mags, third is loaded
        dda=cell(0);nms=cell(0);nmsx=cell(0);pat=cell(0);%  decays and file names
        MKcmap=[0 0 0;.94 .94 .94;1 1 1];
        labeledmk=0;
        hdrkeep=cell(0);% keep last hdr
        % clus=cell(0);%
        curs1=[];% handle for mouse circular cursor
        blk=NaN(16,16);%blank cursor
        curs=0;
        filtnams=[]; % filternames (wavelets)
        filtinds=[];%matrix save last used filter params for each type
        % curs2=0;
        % inorout=0;% used to detect moment cursor leaves
        radi=.02;
        phasores=256;
        dwnsmp=256;% size of downsampled images (set to zero to prevent downsampling)
        running=0;
        figsz=[100 100 100 100];% figure for int ims size
        currCol=1;% current cursor index used for colors
        colMks=[];% cursor colors
        roidad=[];% roidata where we keep position and radius of clusters being diplayed (radius at 0 means not in display)
        imcur=[];% cursor handle for reciprocal
        curhan=[];% handles for cursors sitting on phasor plot (so they can be remove without clearing the whole axes)
        IWh=[];% image window handle
        AXh=[];% axes in im window handles
        PDh=[];% plot decay handle
        blankIm=[];
        compNum=0;% component number
        compNumT=0;% theo component number
        compNumE=0;% empiric component number
        comphans=[];% handles for component markers
        complif=[1:20];% initial lifetimes allowing 20 components
        compdad=zeros(20,2,20);%  sng allowing 20 harms
        % colcomps=repmat('rgby',[1 5]);
        colcomps='bgyrmauFltZ';% use cursor
        GMfit=[];% var to hold trained model
        GMfitcolinds=[];
        curp=[];% keep track of last list of files for which phasors have been filtered and plotted
        ALLcols=[]; % available cols set in startup
        BGcol=[ ]; % Default bg col
        BGcol2=[ ];% darker bg col
        BGcol3=[ ];% lighter bg col
        FGcol=[ ];% default foreground (colors are set in setcolors function
        currcol=1;
        DM=0;% darkmode flag
        MKoutim=0;
        harmonic=1; % unused i think
        preval=1;% to keep track if maxharms is increased by user
        exportHR=1; % allow export low res
        PD=zeros(20,10);% calibration values (first 4 rows: 32Sp1, 32sp2, 32A0, 32A1)
        MF=ones(20,10);% allowing for 10 harms is overkill
        multical=cell(1,20); % allow multiple calibration files
        multilif=zeros(1,20);
        calibflag=[]; % calibration associated to each loaded file
        calibtags={'32sp','','A0','A1','31nB','','','','All'}; % user defined tags start at 10;
        currPP=0;
        currIC=0;
        % IrangePtile=.99; % percentile to set max value for intensity (at 1 the histograms are all in the lower end of the dynamic range)
        Imaxrange=1;% default value for intensity range when set to absolute
        keepPeakCounts=[];% array to renormalize to original counts
        LoadedIms=0; % count loaded images
        lastyn=0;% flag to control when pointer exits phasor plot
        freq=8e7; % laser freq
        curcolbuthan=[] ;% handles for cursor color buttons
        word=[];% password input
        currentMag='';% text of current magnitude for disp
        emphcol=-ones(100,3);%emphasis colors will error if user loads more than 100ims
        exportvec=0;% vector file flag - we can remove this likely with appropriate code changes
        taus=[];%keep track of current ticks displayed
        refpos=[];% keep track of app.Eb.Position for changing stuff around when swapping filters
        decaywinpos=[];% user params for decay win position
        tabpos=[];% keep tab position for control
    end

    methods (Access = private)
        function [] = applyUserSettings(app,ruta)
            app.running=1;
            if(nargin==1),ruta=app.folderS;end
            try
                if(nargin>1)
                    ensenya('Applying settings','b');
                end
                load(ruta,'files','paths','masks','p01','p02','p03','p04','p05','p06','p07','p08','p09','p10','p11','p12','p13','p14','p15','p16','p17','p18','p19','p20','p21','p22','p23','p24','p25','p26','p27','p28','p29','r01','r02','r03','r04','r05','r06','r07','r08','r09','r10','r11','r12','r13','r14','r15','r16','r17','r18','r19','r20','r21','r22','m01','m02','m03','m04','m05','m06','m07','m08','m09','m10','v01','v02','v03','v04','v05','v06','v07','v08','v09','v10','v11','v12','v13','v14','v15','v16','v17','v18','v19','v20','v21','v22','v23','c01','c02','c03','c04','c05','c06','c07','c08','c09','c10','c11','c12','c13','c14','c15','c16','c17','c18','c19','c20','s00','u01','u02','u03','u04','u05','u06','s01','s02','s03','s04','s06','s07','s08','s09','s10','s11');

                pop=app.GSLabUIFigure.Position;

                app.GSLabUIFigure.Position=[p01(1:2) pop(3:4)];

                app.figsz=p02;
                app.Eb.Value=p03;
                app.Filtermatch.Text=[' x ' num2str(p03)];
                app.Ti.Value=p04;
                if (p05>=p06)||((abs(p05)==Inf)||(abs(p06)==Inf)),p05=0;p06=1;end% sanity check
                try,app.Imin.Value=p05;end
                try,app.Imax.Value=p06;end
                app.Islide.Value=[app.Imin.Value app.Imax.Value];
                if (p07>=p08)||((abs(p07)==Inf)||(abs(p08)==Inf)),p07=0;p08=.6;end% sanity check
                app.Smin.Value=p07;
                app.Smax.Value=p08;
                if (p09>=p10)||((abs(p09)==Inf)||(abs(p10)==Inf)),p09=0;p10=1;end% sanity check
                app.Gmin.Value=p09;
                app.Gmax.Value=p10;
                if (p11>=p12)||((abs(p11)==Inf)||(abs(p12)==Inf)),p11=0;p12=.25;end% sanity check
                app.Cmin.Value=p11;
                app.Cmax.Value=p12;
                app.folderC=p13;
                app.folderD=p14;
                app.LT.Value=p15;
                app.DoCalib.Value=p16;
                app.Cbar.Value=p17;
                app.Harm.Value=p18;
                app.HarmLim.Value=p19;
                % app.Filterbefore.Value=p20;
                app.phaS.Value=p21;
                app.modS.Value=p22;
                try % I think this fails bc items are not set in some of the drop downs
                    app.PhasorfilterDropDown.ValueIndex=p23;
                    app.WavSupp.ValueIndex=p24;
                    app.filtinds=p25;
                    app.WaveletFamilyDropDown.ValueIndex=p28;
                end
                app.Gcenter.Value=p26;
                app.Scenter.Value=p27;
                % p28 is assigned after creation of the elements in wavelet dropdown
                try app.decaywinpos=p29;end

                app.folderR=r01;
                app.ExDecay.Value=r02;
                app.DeacayFileDropDown.Value=r03;
                app.Combine.Value=r04;
                app.ExPhasor.Value=r05;
                app.ExMagH.Value=r06;
                app.ExInt.Value=r07;
                app.PhBg.Value=r08;
                app.ExMagS.Value=r09;
                app.PhRb.Value=r10;
                app.blend.Value=r11;
                app.PhRb.Value=r12;
                app.ExCurH.Value=r13;
                app.ExCurS.Value=r14;
                app.Expptrans.Value=r15;
                app.exportvec=r16;
                app.axes.Value=r17;
                app.ExportPhasorFileDropDown.Value=r18;
                app.ExportImageFileDropDown.Value=r19;
                app.groupfiles.Value=r20;
                app.LabelCoords.Value=r21;
                app.LabelFmt.Value=r22;

                app.Srad.Value=m01;
                app.Grad.Value=m02;
                app.Phaserad.Value=m03;
                app.TauPhaserad.Value=m04;
                app.Modrad.Value=m05;
                app.TauModrad.Value=m06;
                app.NormPhaserad.Value=m07;
                app.TauNormrad.Value=m08;
                app.CustomRadial.Value=m09;
                app.CustomAngular.Value=m10;

                app.AFSef.Value=v01;
                app.UCcb.Value=v02;
                app.ticks.Value=v03;
                app.tickfontsize.Value=v04;
                switch v05
                    case 0,app.LifetimeStyle.Value=1;
                    case 1,app.SpectralStyle.Value=1;
                    case 2,app.IvMagStyle.Value=1;
                    case 3,app.UmapStyle.Value=1;
                end
                app.CCM=v06;
                app.freq=v07;
                app.freqEF.Value=v07*1e-6;
                if(nargin>1) % drop downs arent yet constructed!
                    app.PhasorcolormapDD.ValueIndex=v08;
                    app.RainbowcolormapDD.ValueIndex=v09;
                end
                app.SoftCursor.Value=v10;
                app.logDens.Value=v11;
                app.normFile.Value=v12;
                app.contours.Value=v13;
                app.margns.Value=v14;
                app.imagewindowtile.Value=v15;
                app.imagecols.Value=v16;
                app.imagerows.Value=v17;
                app.SpecMin.Value=v18;
                app.SpecMax.Value=v19;
                app.tickNumber.Value=v20;
                try
                    app.contourOnly.Value=v21;
                    app.NormDecays.Value=v22;
                    app.LogDecays.Value=v23;
                end
                if(app.contours.Value==0),app.contourOnly.Value=0;app.contourOnly.Enable=0;else,app.contourOnly.Enable=1;end


                app.complif=c01;
                app.colcomps=c02;
                app.ShowComponents.Value=c03;
                app.AddLines.Value=c04;
                app.CompImsExp.Value=c05;
                app.CompImsIntExp.Value=c06;
                app.CompImsCSVExp.Value=c07;
                app.CompImsCol.Value=c08;
                app.exportratiometric.Value=c09;
                app.comp1.Value=c10;
                app.comp2.Value=c11;
                app.ratiocsv.Value=c12;
                app.Filterbefore.Value=p20;

                app.ExportType.Value=s00;

                app.singleimage.Value=c13;
                app.currcol=c14;
                app.DM=c15;if(app.DM==1);app.DarkmodeCheckBox.Value=1;end
                app.addmargins.Value=c16;
                app.includeint.Value=c17;
                app.groupfile.Value=c18;
                app.mergecomps.Value=c19;
                app.ALLcols(17,:)=c20;

                try
                    app.NormColunits.Value=s01;
                    app.CoordPtile.Value=s02;
                    app.AddFolders.Value=s03;
                    app.ColPtile.Value=s04;
                    app.IntPtile.Value=s06;
                    app.IntDispTop.Value=s07;
                    app.PromptFLIMlabsloadoptionCheckBox.Value=s08;
                    app.LoadFLIMlabsrawCheckBox.Value=s09;
                    app.LoadFLAME31nBwithBCheckBox.Value=s10;
                    app.autotab.Value=s11;
                end

                if(u01==0),app.conttogether.Value=1;app.contseparate.Value=0;app.elipse.Value=0;end
                if(u01==1),app.conttogether.Value=0;app.contseparate.Value=1;app.elipse.Value=0;end
                if(u01==2),app.conttogether.Value=0;app.contseparate.Value=0;app.elipse.Value=1;end
                if(u02==0),app.hard.Value=1;app.prob.Value=0;end
                if(u02==1),app.hard.Value=0;app.prob.Value=1;end
                app.conf.Value=u03;
                app.blend.Value=u04;
                app.blendamount.Value=u05;
                app.gmmexport.Value=u06;
                % han= barraprogress(app,1,han,'Done');
            catch
                ensenya('Warning: Could not load user settings.','a')
            end
            if(nargin>1)% loading a session

                triggercallbacks(app);
                prev='';% save last path so that flimsplits arent loaded twice
                for ii=1:numel(paths)
                    aux=[paths{ii} filesep files{ii}];
                    switch files{ii}(end-3:end)
                        case '.tif'
                            LoadTifSeqFun(app,{aux});
                        case '.lsm'
                            LoadZeissLSMFun(app,{aux});
                        case {'.R64','.ref','.re1','.re3','.re4','.re5','.re6','.re7','.re8'}
                            LoadSimFCSrefFun(app,{aux});
                        case 'CON)'
                            LoadFalconSnGFun(app,paths{ii});
                        otherwise% flame
                            if(strcmp(prev,aux)==0)
                                app.folderD=aux;
                                LoadFlameRawFun(app);
                            end
                            prev=aux;
                    end
                end
                try
                    if(~isempty(masks))
                        app.MKL=masks;
                        mostramask(app);
                        app.Applymask.Value=1;
                        ApplymaskValueChanged(app);
                    end
                end
                plotHists(app);
                FilterAndPlotPhasor(app);
            end


            app.running=0;
        end
        function [han] = barraprogress(app,frac,han,msg)

            %% old version with external window
            % if(frac==0)
            %     han = waitbar(0,msg,'Name','Running');
            %     han.Children.Title.Interpreter = 'none';
            % else
            %     try % in case user closed window
            %         exx='';
            %         % exx=[niceNums(fra*100,0),'% '];
            %         if(nargin>3)
            %             han = waitbar(frac,han,[exx msg]);
            %         else
            %             han = waitbar(frac,han);
            %         end
            %     end
            % end
            %% new version with embed progress dialog
            if(frac==0)
                han = uiprogressdlg(app.GSLabUIFigure,'Title','Please Wait','Message',msg);
            else
                if(frac>1),frac=1;end
                han.Value = frac;
                han.Message = msg;
            end
            %% handmade version
            %             app.barra.Visible=1;
            %            if(frac==0)
            % app.barra.ImageSource=.5*ones(1,1000,3);
            %            else
            %                gg=.5*ones(1,1000,3);
            %                gg(1,1:round(frac*1000),1)=1;
            %                gg(1,1:round(frac*1000),2)=1;
            %                gg(1,1:round(frac*1000),3)=0;
            %  app.barra.ImageSource=gg;
            %            end
            %            drawnow;


        end
        function rang = checkedFiles(app)
            % see what nodes are checked
            rang=[];
            for ii=1:numel(app.Tree.CheckedNodes)
                rang=[rang app.Tree.CheckedNodes(ii).NodeData];
            end
        end


        function [] = calibrationApply(app)
            N=app.LoadedIms;
            calibrationDisplayUpdate(app);
            filterfactor=1;
            minimsz=2*floor(app.Eb.Value*filterfactor/2)*(app.Ti.Value);
            % % apply calibration
            if(N>0)
                d=barraprogress(app,0,[],'Applying calibration');
                if(app.DoCalib.Value==1)
                    for ii=1:N% ii=2
                        if(app.calibflag(ii)>0)||strcmp(app.Calibrationtype.SelectedObject.Text,'Manual')

                            % if(sum(app.PD(app.calibflag(ii),1:app.HarmLim.Value))~=0)
                            %     if(prod(app.MF(app.calibflag(ii),1:app.HarmLim.Value))~=1)
                            for hh=1:app.HarmLim.Value

                                S1=app.SSo{ii,hh};
                                G1=app.GGo{ii,hh};

                                if(strcmp(app.Calibrationtype.SelectedObject.Text,'Manual'))
                                    PDi=app.phaS.Value;
                                    MFi=app.modS.Value;
                                else
                                    PDi=app.PD(app.calibflag(ii),hh);
                                    MFi=app.MF(app.calibflag(ii),hh);
                                end

                                if(max(size(S1))>minimsz)
                                    % fill nans before resizing (otherwise only nearest algorithm works)!
                                    if(sum(isnan(S1(:)))>0)
                                        S1=phasorFillNaN2(S1);%disp('y');
                                        G1=phasorFillNaN2(G1);
                                    end
                                    % downsample images
                                    if(app.dwnsmp>0)
                                        if(app.dwnsmp<size(S1,2))
                                            nwsz=round([app.dwnsmp size(S1,2)*app.dwnsmp/size(S1,1)]);
                                            S1=imresize(S1,nwsz);%
                                            G1=imresize(G1,nwsz);%
                                            if(hh==1)
                                                app.IIa{ii}=imresize(sum(app.IIo{ii},3),nwsz);%
                                            end
                                        end
                                    end
                                end

                                pha=atan2(S1,G1)+PDi;
                                modu=sqrt((S1.*S1)+(G1.*G1))*MFi;

                                S1=modu.*sin(pha);G1=modu.*cos(pha);

                                app.SSa{ii,hh}=S1;
                                app.GGa{ii,hh}=G1;
                                d=barraprogress(app,((ii-1)*app.HarmLim.Value+hh)/N/app.HarmLim.Value,d,'Applying calibration');
                            end
                        end
                        %     end
                        % end
                    end
                end
            end
            try, close(d);end

        end

        function [] = calibrationRemove(app)
            N=size(app.SSa,1);
            filterfactor=1;
            minimsz=2*floor(app.Eb.Value*filterfactor/2)*(app.Ti.Value);
            if(N>0)
                d=barraprogress(app,0,[],'Removing calibration');
                for ii=1:N
                    if(app.calibflag(ii)>0)||strcmp(app.Calibrationtype.SelectedObject.Text,'Manual')
                        % if(sum(app.PD(app.calibflag(ii),1:app.HarmLim.Value))~=0)
                        %     if(prod(app.MF(app.calibflag(ii),1:app.HarmLim.Value))~=1)
                        for hh=1:app.HarmLim.Value

                            S1=app.SSo{ii,hh};
                            G1=app.GGo{ii,hh};

                            if(max(size(S1))>minimsz)
                                % fill nans before resizing (otherwise only nearest algorithm works)!
                                if(sum(isnan(S1(:)))>0)
                                    S1=phasorFillNaN2(S1);%disp('n');
                                    G1=phasorFillNaN2(G1);
                                end
                                % downsample images
                                if(app.dwnsmp>0)
                                    if(app.dwnsmp<size(S1,2))
                                        nwsz=round([app.dwnsmp size(S1,2)*app.dwnsmp/size(S1,1)]);
                                        S1=imresize(S1,nwsz);%
                                        G1=imresize(G1,nwsz);%
                                        if(hh==1)
                                            app.IIa{ii}=imresize(sum(app.IIo{ii},3),nwsz);%
                                        end
                                    end
                                end
                            end

                            app.SSa{ii,hh}=S1;
                            app.GGa{ii,hh}=G1;
                            d=barraprogress(app,((ii-1)*app.HarmLim.Value+hh)/N/app.HarmLim.Value,d,'Removing calibration');
                        end
                        %     end
                        % end
                    end
                end
            end
            try, close(d);end
        end

        function [] =calibrationDisplayUpdate(app)
            aux={};
            chnams=app.calibtags;
            jj=0;
            for ii=1:numel(app.multical)
                if(~isempty(app.multical{ii}))
                    jj=jj+1;

                    aux{jj,1}=chnams{ii};
                    aux{jj,2}=niceNums(app.multilif(ii),2);
                    aux{jj,3}=app.multical{ii};

                end
            end
            if(isempty(aux))
                aux='';%app.DoCalib.Value=0;app.CalibrationPanel.Enable=0;
            end

            % if(strcmp(whosPC,'Alex'))
            app.calTable.Data=aux;
            % else
            %     app.FiLab.Text=aux;
            % end
        end

        function [] = clearMasks(app)
            for ii=1:numel(app.MK)
                app.MK{ii}(:)=1;
            end
        end

        function [] = clearCalib(app)
            app.PD=zeros(20,100);% calibration values (first 4 rows: 32Sp1, 32sp2, 32A0, 32A1
            app.MF=ones(20,100);
            app.multical={'','','',''};
        end

        function [] = CursorColPush(app,~,~,q)
            h=app.curcolbuthan(q);
            pos=app.GSLabUIFigure.Position;
            tam=[200 200];
            % pos=pos(1:2)+(pos(3:4)-tam)/2;
            % pos(2)=pos(2)+sum(app.Panel.Position([2,4]));
            pos(1)=pos(1)-tam(1);
            pos(3:4)=tam;
            try
                nou=colorPicker2(1,pos);
                app.CCM(q)=nou;
                app.colcomps(q)=nou;
                nou=superjet(1,nou);
                set(h,'BackgroundColor',nou);
                hh=app.comphans(q,2);
                set(hh,'BackgroundColor',nou);
                app.colMks(q,:)=nou;
            end
            app.currCol=q;
            app.curs1.Color=app.colMks(q,:);

        end
        function [] = cursorSizeAdapt(app)
            app.radi=max([app.Smax.Value-app.Smin.Value app.Gmax.Value-app.Gmin.Value])/20;
        end
        function [] = ComponentColPush(app,~,~,q)
            h=app.comphans(q,2);
            pos=app.GSLabUIFigure.Position;
            tam=[200 200];
            % pos=pos(1:2)+(pos(3:4)-tam)/2;
            % pos(2)=pos(2)+sum(app.ComponentsPanel.Position([2,4]));
            pos(1)=pos(1)-tam(1);
            pos(3:4)=tam;
            try
                nou=colorPicker2(1,pos);
                app.colcomps(q)=nou;
                nou=superjet(1,nou);
                set(h,'BackgroundColor',nou);
                componentLifeChange(app);
                % app.colMks(q,:)=nou;
                % app.curs1.Color=nou;
                % app.currCol=q;
            end
        end

        function [] = componentAddTheo(app)
            maxComps=1+2*app.HarmLim.Value;
            app.minusB.Enable=1;
            if(app.compNumT<maxComps)
                app.compNumT=app.compNumT+1;
                ref=app.plusB.Position;
                sep=1;
                tam=12;
                % Component EditFieldLabel
                h = uilabel(app.ComponentsPanel);
                h.HorizontalAlignment = 'left';
                h.Position = [ref(1)-5 ref(2) 100 20];
                h.Text = [niceDigits(app.compNumT,2) '. Lifetime:'];
                h.FontSize=12;
                h.FontColor=app.FGcol;
                app.comphans(app.compNumT,1)=h;
                % % component color button
                % h = uibutton(app.ComponentsPanel);
                % h.Position = [ref(1)+18 ref(2)+4 tam tam];
                % h.Text = '';
                % h.BackgroundColor= superjet(1,app.colcomps(app.compNumT));
                % h.Tooltip=['Component' num2str(app.compNumT) ' color'];
                % h.ButtonPushedFcn = {@app.ComponentColPush, app.compNumT};
                % app.comphans(app.compNumT,2)=h;
                h=app.comphans(app.compNumT,2);
                set(h,'Visible',1);
                set(h,'Position',[ref(1)-16 ref(2)+7 tam tam]);
                % component color edit field
                h = uieditfield(app.ComponentsPanel, 'numeric');
                h.UserData = app.compNumT;
                h.ValueChangedFcn = {@app.componentLifeChange, app.compNumT};
                h.HorizontalAlignment = 'center';
                h.Limits=[0 Inf];
                h.Tooltip = ['Component' num2str(app.compNumT) ' lifetime(ns)'];
                h.Position = [ref(1)+81-21  ref(2) 36 20];
                h.Value = app.complif(app.compNumT);
                app.comphans(app.compNumT,3)=h;

                % shift plus minus buttons
                app.plusB.Position=ref-[0 sep+20 0 0];
                app.minusB.Position=app.minusB.Position-[0 sep+20 0 0];
                if(app.compNumT==maxComps)
                    app.plusB.Enable=0;
                    app.plusB.Tooltip='Increase max harmonics for more components';
                end
                app.compNum=app.compNumT;
                % update S n g and plot
                componentLifeChange(app);

            end
        end

        function [] = componentRemoveTheo(app)
            app.plusB.Enable=1;
            app.plusB.Tooltip='Add component';
            if(app.compNumT>0)
                h=app.comphans(app.compNumT,1);
                delete(h);
                h=app.comphans(app.compNumT,2);
                set(h,'Visible',0);
                h=app.comphans(app.compNumT,3);
                delete(h);
                app.compNumT=app.compNumT-1;
                if(app.compNumT==0)
                    app.minusB.Enable=0;
                    replotPP(app);% gotta replot bc otherwise component circle is left
                end
                % shift plus minus buttons
                ref=app.plusB.Position;
                app.plusB.Position=ref-[0 -21 0 0];
                app.minusB.Position=app.minusB.Position-[0 -21 0 0];
                app.compNum=app.compNumT;
                componentLifeChange(app);

            end
        end


        function [] = componentTreeColors(app)

            tst0=uistyle('FontColor','black');

            checkedNodes = app.ComponentTree.CheckedNodes;
            rang=[];
            for ii=1:numel(app.ComponentTree.Children)
                addStyle(app.ComponentTree,tst0,'node',app.ComponentTree.Children(ii));
            end
            for ii=1:numel(checkedNodes)
                rang=[rang app.ComponentTree.CheckedNodes(ii).NodeData];
                h=app.comphans(ii,2);
                tst=uistyle('FontColor',get(h,'BackgroundColor'));
                addStyle(app.ComponentTree,tst,'node',app.ComponentTree.CheckedNodes(ii));
            end

        end

        function [] = componentGetEmpiricCoords(app)
            HH=app.HarmLim.Value;
            rang=[];
            checkedNodes = app.ComponentTree.CheckedNodes;
            for ii=1:numel(checkedNodes)
                rang=[rang app.ComponentTree.CheckedNodes(ii).NodeData];
                % h=app.comphans(ii,2);
                % tst=uistyle('FontColor',get(h,'BackgroundColor'));
                % addStyle(app.ComponentTree,tst,'node',app.ComponentTree.CheckedNodes(ii));
            end
            if(numel(rang)>1+2*HH)
                uialert(app.GSLabUIFigure,['Increase computed harmonics to unmix more than ' num2str(1+2*HH) ' components'],'Warning','Icon','info');
            end
            app.compNumE=numel(rang);
            cc=0;
            for ii=1:8
                h=app.comphans(ii,2);
                set(h,'Visible',0);
            end
            qv=[app.Imin.Value app.Imax.Value];
            filterfactor=1;%
            for ii=rang
                cc=cc+1;
                % h=app.comphans(cc,2);
                %  set(h,'Visible',1);
                Im0=app.IIa{ii};
                if(app.relative.Value==1)
                    Im0=Im0/app.keepPeakCounts(ii);
                end
                Im0=(Im0-qv(1))/diff(qv);
                Im0(Im0<0)=0;
                if(app.Excess.Value==0)
                    Im0(Im0>1)=1;
                else
                    Im0(Im0>1)=0;
                end
                minimsz=2*floor(app.Eb.Value*filterfactor/2)*(app.Ti.Value);
                for hh=1:HH
                    % photon weighted mean
                    Im1=Im0;
                    S1=app.SSa{ii,hh};
                    G1=app.GGa{ii,hh};
                    % disp(ii)
                    if(app.Filterbefore.Value==0)  % filter being smooth before int thresh
                        S1(Im1==0)=NaN;
                        G1(Im1==0)=NaN;
                    end
                    if(min(size(Im1))>minimsz)
                        [S1,G1] = phasorSmooth(S1,G1,app.Eb.Value*filterfactor,app.Ti.Value,0,[],1);
                        [S1,G1,~,Im1] = phasorSmoothCrop(S1,G1,app.Eb.Value*filterfactor,app.Ti.Value,Im1);
                        % [Im1,Im1] = phasorSmoothCrop(Im1,Im1,app.Eb.Value*filterfactor,app.Ti.Value);
                        if(app.Filterbefore.Value==1)
                            S1(Im1==0)=NaN;
                            G1(Im1==0)=NaN;
                        end
                    end
                    if(app.Applymask.Value==1)
                        % incase we loadded a single image
                        ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                        aux=app.MKL{ind};aux(aux>1)=1;
                        out=(size(Im1,1)-size(aux,1))/2;
                        if(out>0)% image bigger than mask
                            aux=imresize(aux,size(Im1));
                            % Im1=Im1(1+out:end-out,1+out:end-out);
                            % S1=S1(1+out:end-out,1+out:end-out);
                            % G1=G1(1+out:end-out,1+out:end-out);
                        end
                        if(out<0)
                            if(size(app.IIa{ii},1)==size(aux,1))% this would be used if mask was same size as original image but due to filtering we gotta crop some
                                out=-out;
                                aux=aux(1+out:end-out,1+out:end-out);
                            else% mask is highres and image is downsampled, we gotta downsample mask
                                aux=imresize(aux,[size(im,1) size(im,2)],'nearest');
                            end
                        end
                        try
                            Im1=Im1.*aux;
                            S1(aux==0)=NaN;
                            G1(aux==0)=NaN;
                        end
                    end
                    out=find(isnan(S1));
                    S1(out)=[];G1(out)=[];Im1(out)=[];
                    S1=S1(:);G1=G1(:);Im1=Im1(:);
                    aux2=sum(Im1);
                    s=(S1.*Im1)./aux2;s=sum(s);
                    g=(G1.*Im1)./aux2;g=sum(g);
                    app.compdad(cc,2,hh)=s;
                    app.compdad(cc,1,hh)=g;
                end
            end
        end

        function [] = componentLifeChange(app,A,~,q)
            % q=get(A,'UserData')
            if(nargin<2)
                q=app.compNum;
            else
                if(~isempty(A))
                    if(nargin<4)
                        q=A.UserData;
                    end
                    app.complif(q)=A.Value;
                end
            end
            if(q>0)
                for ii=1:numel(q)
                    for hh=1:10
                        if(app.InputButton.Value==1)
                            [s,g] = tau2coords(app.complif(q(ii)),app.freq,hh);
                        else
                            inds=find(app.roidad(:,3)>0);
                            s=app.roidad(inds(ii),2);
                            g=app.roidad(inds(ii),1);
                        end
                        app.compdad(q(ii),2,hh)=s;
                        app.compdad(q(ii),1,hh)=g;
                    end
                end
                if(app.ShowComponents.Value==1)
                    replotPP(app);
                end
            else
                replotPP(app);
            end
        end

        function [Crange] = crangeinunity(app,over)% we can call it with random 2nd parameter for when we need to know the state as we switch to Normcol units
            Crange=[app.Cmin.Value app.Cmax.Value];
            if(app.NormColunits.Value==0)||(nargin>1)
                switch app.MeasuredmagnitudeButtonGroup.SelectedObject.Text
                    % case 'S',Crange=[app.Cmin.Value app.Cmax.Value];
                    % case 'G',Crange=[app.Cmin.Value app.Cmax.Value];
                    case 'Phase',Crange=[app.Cmin.Value app.Cmax.Value]/6.2832;
                        % case 'Mod',Crange=[app.Cmin.Value app.Cmax.Value];
                    case 'NormPhase',Crange=[app.Cmin.Value app.Cmax.Value]/6.2832;
                    case 'TauPhase'
                        if(strcmp(app.Style.SelectedObject.Text,'Lifetime'))
                            [s,g] = tau2coords([app.Cmin.Value app.Cmax.Value],app.freq,app.Harm.Value);
                            Crange=[atan2(s(1),g(1)),atan2(s(2),g(2))]/6.2832;
                        end
                        if(strcmp(app.Style.SelectedObject.Text,'Spectral'))
                            % if(app.Cmin.Value<app.SpecMin.Value),app.SpecMax.Value=app.SpecMin.Value+app.Cmax.Value-app.Cmin.Value;app.SpecMin.Value=app.Cmin.Value;end
                            Crange=([app.Cmin.Value app.Cmax.Value]-app.SpecMin.Value)/(app.SpecMax.Value-app.SpecMin.Value);
                        end
                    case 'TauMod'
                        if(strcmp(app.Style.SelectedObject.Text,'Lifetime'))
                            [s,g] = tau2coords([app.Cmin.Value app.Cmax.Value],app.freq,app.Harm.Value);
                            Crange=sort([sqrt(s(1)*s(1)+g(1)*g(1)) sqrt(s(2)*s(2)+g(2)*g(2))]);
                        end
                    case 'TauNorm'
                        if(strcmp(app.Style.SelectedObject.Text,'Lifetime'))
                            [s,g] = tau2coords([app.Cmin.Value app.Cmax.Value],app.freq,app.Harm.Value);
                            Crange=[atan2(s(1),g(1)-.5),atan2(s(2),g(2)-.5)]/6.2832;
                        end
                    case 'CustomAngular'
                        if(strcmp(app.Style.SelectedObject.Text,'Lifetime'))
                            % use lifetime
                            % [s,g] = tau2coords([app.Cmin.Value app.Cmax.Value],app.freq,1);
                            % Crange=[atan2(s(1)-app.Scenter.Value,g(1)-app.Gcenter.Value),atan2(s(2)-app.Scenter.Value,g(2)-app.Gcenter.Value)]/6.2832;
                            % use phase
                            Crange=[app.Cmin.Value app.Cmax.Value]/6.2832;
                        end
                end

            end

        end
        function [Crange] = crangefromunity(app,Crange)
            if(nargin==1)
                Crange=[app.Cmin.Value app.Cmax.Value];
            end
            switch app.MeasuredmagnitudeButtonGroup.SelectedObject.Text
                case {'Phase','NormPhase','CustomAngular'}
                    Crange(1)=Crange(1)*6.2832;
                    Crange(2)=Crange(2)*6.2832;
                case 'TauPhase'
                    if(strcmp(app.Style.SelectedObject.Text,'Lifetime'))
                        %if were in first quadrant
                        if((Crange(1)>=0)&&(Crange(1)<.25))&&((Crange(2)>=0)&&(Crange(2)<.25))
                            g=.5;
                            s=g*tan(Crange*6.2832);
                            Crange=magnitude(s,g,5,app.freq,app.Harm.Value);
                        end
                    end
                case 'TauMod'
                    if(strcmp(app.Style.SelectedObject.Text,'Lifetime'))
                        % if M<1
                        if(((Crange(1)>=0)&&(Crange(1)<1))&&((Crange(2)>=0)&&(Crange(2)<1)))
                            aux0=Crange(1);
                            aux=[1 1];aux=aux/norm(aux);aux=aux*Crange(2);
                            aux=magnitude(aux(2),aux(1),6,app.freq,app.Harm.Value);
                            Crange(1)=aux;
                            aux=[1 1];aux=aux/norm(aux);aux=aux*aux0;
                            aux=magnitude(aux(2),aux(1),6,app.freq,app.Harm.Value);
                            Crange(2)=aux;
                        end
                    end
                case 'TauNorm'
                    if(strcmp(app.Style.SelectedObject.Text,'Lifetime'))
                        if((Crange(1)>=0)&&(Crange(1)<.5))&&((Crange(2)>=0)&&(Crange(2)<.5))
                            g=.5+.5*cos(Crange*6.2832);
                            s=.5*sin(Crange*6.2832);
                            Crange=magnitude(s,g,7,app.freq,app.Harm.Value);
                        end
                    end
            end
        end
        function [] = createImageFigure(app)
            if(numel(app.IIa)>0)
                try,close(app.IWh);end
                sz=get(0,'screensize');
                sw=sz;% total win size
                n=app.imagerows.Value;
                m=app.imagecols.Value;
                if(app.imagewindowtile.Value==0)||(m*n<app.LoadedIms)
                    if(app.LoadedIms<17)
                        [n,m]=squareDistrib2(app.LoadedIms);
                        if((n==1)&&(m==2)),n=2;m=1;end
                    else
                        [n,m]=squareDistrib2(app.LoadedIms,sw(4)/sw(3),1);
                    end
                    % if(app.imagerows.Value*app.imagecols.Value<app.LoadedIms)
                    app.imagerows.Value=n;
                    app.imagecols.Value=m;
                    % end
                end
                sz(2)=50;sz(4)=sz(4)-68;% actial available space removing taskbar

                if(app.dwnsmp>0)
                    fits=round(sz(4)/app.dwnsmp/n);% how many images could fit in vertical respecting pix num
                else
                    fits=round(sz(4)/size(app.IIa{1},1)/n);
                end
                if(fits==0),fits=1;end

                figratio=sz(4)/sz(3);
                imratio=n/m;
                if(figratio/imratio<1)
                    sz(3)=sz(3)*figratio/imratio;
                    sz(3:4)=sz(3:4)/fits; % reduce in order to match win size to actual image size
                else
                    sz(4)=sz(4)*imratio/figratio;
                end
                % sz(3:4)=sz(3:4)/fits; % reduce in order to match win size to actual image size
                sz(2)=(sw(4)-sz(4))/2;
                sz(1)=sw(3)-sz(3);
                if(isempty(app.figsz))||(app.figsz(1)==0)||(app.figsz(2)==0)% user never saved position
                    app.figsz=sz;
                else% take position from last use
                    app.figsz=[app.figsz(1:2) sz(3:4)];
                end
                try% attempt to check if figure is within one monitor
                    aux=get(groot, 'MonitorPositions');
                    outH=true;
                    outV=true;
                    for ii=1:size(aux,1)
                        outH=outH&(app.figsz(1)+app.figsz(3)>aux(ii,1)+aux(ii,3));
                    end
                    for ii=1:size(aux,1)
                        outV=outV&(app.figsz(2)+app.figsz(4)>aux(ii,2)+aux(ii,4));
                    end
                catch
                    outH=app.figsz(1)+app.figsz(3)>sw(3);
                    outV=app.figsz(2)+app.figsz(4)>sw(4);
                end
                if(outH)% edge outside of monitor(s) will move to first
                    app.figsz(1)=sw(3)-app.figsz(3);% shift to left until fit
                    % app.figsz(1)=(sw(3)-app.figsz(3))/2;% center on screen
                end
                if(outV)
                    app.figsz(2)=sw(4)-app.figsz(4);% shift bot until fit
                    % app.figsz(2)=(sw(4)-app.figsz(4))/2;% center on screen
                end

                app.IWh=uifigure('WindowStyle','normal','Resize','off');%clf;

                if(app.margns.Value==1)
                    mg=[.01 .01 .01 .01]./[m n m n];
                else
                    mg=0;
                end


                for ii=1:n*m%app.LoadedIms
                    h=subNM(n,m,ii,mg,app.IWh);
                    hh=imagesc(h,app.blankIm);
                    axis(h,'image');
                    set(h,'xtick',[],'ytick',[],'toolbar',[]);
                    set(h,'Box','off','XColor',[0 0 0 0],'YColor',[0 0 0 0]);
                    app.AXh(ii)=hh;
                end
                % app.figsz=[1201         145         150         752];
                set(app.IWh,'position',app.figsz,'name','Image Window','NumberTitle','off','menubar','none','Icon','scripts/logo1.png','WindowButtonMotionFcn',{@app.IntWinMotion},'WindowButtonDownFcn',{@app.IntWinDown},'WindowButtonUpFcn',{@app.IntWinUp},'CloseRequestFcn',{@app.IntWinClose,app});
            end
        end

        function [] = createMasksFromRois(app)
            N=size(app.SSa,1);%
            for ii=1:N
                % dd=zeros(size(app.SSi{ii},1),size(app.SSi{ii},2),3);
                % cc=0;
                for kk=1:size(app.roidad,1)
                    if(app.roidad(kk,3)>0)
                        % disp('y');
                        S1=app.SSi{ii}-app.roidad(kk,2);
                        G1=app.GGi{ii}-app.roidad(kk,1);
                        % blank=G1;blank(:)=1;
                        blank=app.MK{ii};
                        % if(size(blank,1)~=size(G1,1)),blank=zeros(size(G1,1),size(G1,2),3);end
                        colmk=app.colMks(kk,:);
                        aux=(S1.*S1)+(G1.*G1)-app.roidad(kk,3)^2;
                        % aux2=aux;
                        % aux2(aux2<0)=0;
                        % aux2=1-aux2;aux2(aux2<0)=0;
                        % aux2=cat(3,aux2*colmk(1),aux2*colmk(2),aux2*colmk(3));
                        % dd=dd+aux2;
                        % cc=cc+1;
                        aux=aux<0;
                        for jj=1:3
                            aux0=blank(:,:,jj);
                            aux0(aux==1)=colmk(jj);
                            app.MK{ii}(:,:,jj)=aux0;
                        end
                        % gg=0;
                        % if(kk==1)&&(ii==1)
                        %                              figure(55);clf;
                        %     hh=axes();
                        %     images.roi.Circle(hh,'center',app.roidad(kk,1:2),'color',app.colMks(app.currCol,:),'radius',app.roidad(kk,3));
                        %     fov=[app.Gmin.Value app.Gmax.Value;app.Smin.Value app.Smax.Value];
                        %     xlim(fov(1,:));ylim(fov(2,:));
                        %     universalCircle(fov,'k',8e7);
                        %     axis equal;
                        % end
                    end
                end
                % gg=0;
            end

        end

        function [] = decayWindClose(varargin)
            app=varargin{1};
            app.decaywinpos=get(varargin{2},'position');
            saveParams(app);
            delete(varargin{2});
        end

        function [] = test33(app)
            clc;
            N=numel(app.TabGroup.Children);
            for ii=1:N
                NN=numel(app.TabGroup.Children(ii).Children);
                nom='  ';
                for jj=1:NN
                    gg=app.TabGroup.Children(ii).Children(jj);
                    succ=0;

                    try
                        texte=gg.Tooltip;
                        texte=texte{1};
                        if(~isempty(texte))
                            succ=1;
                        end
                    end

                    try
                        nom=gg.Text;
                    catch
                        gg=0;
                    end
                    if(succ==1)


                        classe=class(gg);classe=lower(classe(find(classe=='.',1,'last')+1:end));
                        if(strcmp(classe,'numericeditfield')),classe='edit field';end


                        frase=[nom ' ' classe ' : ' texte '.'];
                        disp(frase);

                    end
                end
                disp('*******************************')
            end
        end
        function [] = exportManual(app)
            d=barraprogress(app,0,[],'Generating');


            ruta='C:\Users\austi\Documents\0_Flame_Scripts\PhasorTools\GSLab_Manual_files';
            rutaG='C:\Users\austi\Documents\GitHub\GSLab\assets';
            rutaOut='C:\Users\austi\Documents\GitHub\GSLab';
            try
                ClearAllContextSelected(app);
                app.PhasorfilterDropDown.ValueIndex=1;
                applyUserSettings(app,[ruta filesep 'manualSettings2.mat']);

                for ii=1:app.compNumT
                    componentRemoveTheo(app);
                end
                componentAddTheo(app);componentAddTheo(app);
                componentAddTheo(app);componentAddTheo(app);
            catch
                error('Data for manual not in the path!')
            end


            N=numel(app.TabGroup.Children);
            %,app.PhasorPanel.Position);
            %
            for ii=1:N
                tabselector(app,[],[],ii);
                [snap,~]=getframe(app.GSLabUIFigure,app.TabGroup.Position);
                imwrite(snap,[rutaG filesep 'images' filesep 'tab' niceDigits(ii) '.png']);
                d=barraprogress(app,ii/(N+1),d,'Generating');
                if(ii==1)
                    [snap,~]=getframe(app.GSLabUIFigure);
                    imwrite(snap,[rutaG filesep 'images' filesep 'image01.png']);
                    [snap,~]=getframe(app.IWh);
                    imwrite(snap,[rutaG filesep 'images' filesep 'image02.png']);
                end
            end
            htmlFile=[rutaG filesep 'html' filesep 'GSLab_Manual.html'];
            pdfFile=[rutaOut filesep 'GSLab_Manual.pdf'];
            html2docx(htmlFile,pdfFile);
            d=barraprogress(app,1,d,'Generating');
            pause(.1);
            try, close(d);end

        end
        function [S1,G1,Im1,pp,I1,I2,IC] = FilterAndPlotPhasor(app,S1,G1,Im1,q)

            % ensenya('')
            app.running=1;
            Srange=[app.Smin.Value app.Smax.Value];
            Grange=[app.Gmin.Value app.Gmax.Value];
            if(diff(Grange)==0),app.Gmax.Value=app.Gmax.Value+.1;Grange=[app.Gmin.Value app.Gmax.Value];end
            phsiz=[round(app.phasores*diff(Srange)/diff(Grange)) app.phasores];
            if(app.IvMagStyle.Value==1)
                phsiz=[round(.688*app.phasores) app.phasores];
            end
            fov=[Grange;Srange];
            IC=ones(phsiz(1),phsiz(2),3);
            N=size(app.SSa,1);
            PPa=zeros(phsiz(1),phsiz(2),N);% accumulate phasors
            if(nargin>1)% called while exporting data on single exp
                N=1;
            end
            if(N>0)&&(nargin==1),d = barraprogress(app,0,[],'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end

            filterfactor=1;%
            % achtung, exported images use true filter size
            % app.EbLabel.Text='Phasor filtering';
            % if(app.exportHR==0)% if lr change filter to match user view
            %     if(nargin>1)
            %         if(app.dwnsmp>0)
            %             filterfactor=round(size(S1,1)/app.dwnsmp);
            %             app.EbLabel.Text=['Filter x' niceNums(size(S1,1)/app.dwnsmp,1)];
            %         end
            %     end
            % end

            % see what nodes are checked
            if(nargin==1)
                rang=checkedFiles(app);
            else
                rang=q;
            end
            app.curp=rang;
            qv=[app.Imin.Value app.Imax.Value];
            minimsz=2*floor(app.Eb.Value*filterfactor/2)*(app.Ti.Value);
            for ii=rang
                if(nargin==1)
                    S1=app.SSa{ii,app.Harm.Value};
                    G1=app.GGa{ii,app.Harm.Value};
                    Im1=app.IIa{ii};
                else
                    if(app.DoCalib.Value==1)
                        if(app.exportHR==1)% high res images is on originals, need to be calibrated
                            if(app.calibflag(ii)>0)
                                pha=atan2(S1,G1)+app.PD(app.calibflag(ii),app.Harm.Value);
                                modu=sqrt((S1.*S1)+(G1.*G1))*app.MF(app.calibflag(ii),app.Harm.Value);
                                S1=modu.*sin(pha);G1=modu.*cos(pha);
                            end
                        end
                    end
                end

                % Im1=round(1000*Im1)/1000;% achtung
                if(app.relative.Value==1)
                    Im1=Im1/app.keepPeakCounts(ii);
                end
                Im1=(Im1-qv(1))/diff(qv);
                Im1(Im1<0)=0;
                if(app.Excess.Value==0)
                    Im1(Im1>1)=1;
                else
                    Im1(Im1>1)=0;
                end
                if(app.logint.Value==1)
                    Im1=log(1+9999*Im1);Im1=NormArray(Im1);
                end
                if(app.hdr.Value==1)
                    if((~isempty(app.hdrkeep{ii}))&&(numel(app.hdrkeep{ii})==numel(S1)))
                        Im1=app.hdrkeep{ii};
                    else
                        Im1=pseudoHDR(Im1);
                        app.hdrkeep{ii}=Im1;
                    end
                end
                % figure,imagesc(isnan(Im1));
                if(nargin==1),d = barraprogress(app,(ii-.8)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end


                if(max(size(Im1))>minimsz)% only if images are big enough

                    if(app.Filterbefore.Value==0)  % apply int thresh now before phasor filt
                        S1(Im1==0)=NaN;
                        G1(Im1==0)=NaN;
                    end
                    if(nargin==1),d = barraprogress(app,(ii-.6)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end
                    % numel(find(isnan(S1==0)))
                    % phasor filtering boooo
                    filtru=app.PhasorfilterDropDown.ValueIndex;
                    if(filtru==1)
                        [S1,G1] = phasorSmooth(S1,G1,app.Eb.Value*filterfactor,app.Ti.Value,0,[],1);
                    end
                    if(filtru==2)
                        S1=imgaussfilt(S1,app.Eb.Value);
                        G1=imgaussfilt(G1,app.Eb.Value);
                    end
                    if(filtru==3)
                        wn=app.WavSupp.Value;
                        % disp(app.WaveletFamilyDropDown.ValueIndex);
                        % two wavelets do not use extra dropdown bc any support is valid
                        if(app.WaveletFamilyDropDown.ValueIndex==3)
                            wn=['db' num2str(app.Eb.Value)];
                        end
                        if(app.WaveletFamilyDropDown.ValueIndex==9)
                            wn=['sym' num2str(app.Eb.Value)];
                        end
                        lev=app.Ti.Value;% times field is used for wavelet level
                        if(lev>0)
                            aux=floor(log2(min([size(S1)])));
                            if(lev>aux),lev=aux;app.Ti.Value=lev;end
                            if(numel(find(isnan(S1)))>0)
                                [S1] = phasorFillNaN2(S1);
                                [G1] = phasorFillNaN2(G1);
                            end
                            if(app.AnscombeCheckBox.Value==1)
                                S1=real(ansc(S1));
                                G1=real(ansc(G1));
                            end
                            % disp(lev);disp(wn);
                            S1=wdenoise2(S1,lev,'wavelet',wn);
                            G1=wdenoise2(G1,lev,'wavelet',wn);
                            if(app.AnscombeCheckBox.Value==1)
                                S1=real(ansc(S1,1));
                                G1=real(ansc(G1,1));
                            end
                        end
                    end


                    if(nargin==1),d = barraprogress(app,(ii-.5)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end
                    if(filtru==1)
                        [S1,G1] = phasorSmoothCrop(S1,G1,app.Eb.Value*filterfactor,app.Ti.Value);

                        [Im1,Im1] = phasorSmoothCrop(Im1,Im1,app.Eb.Value*filterfactor,app.Ti.Value);
                    end
                    if(app.Filterbefore.Value==1)
                        S1(Im1==0)=NaN;
                        G1(Im1==0)=NaN;
                    end
                    if(app.GaussianFilter.Value~=0)
                        Im1=imgaussfilt(Im1,app.GaussianFilter.Value);
                    end
                end

                if(nargin==1),d = barraprogress(app,(ii-.4)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end

                if(app.Applymask.Value==1)
                    % incase we loadded a single image
                    ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                    aux=app.MKL{ind};
                    % ensenya(num2str(min(aux(:))));
                    % ensenya(num2str(max(aux(:))));   
                    % figure(34);imagesc(aux);
                    out=(size(Im1,1)-size(aux,1))/2;
                    if(out>0)% image bigger than mask, we gotta upsample mask
                        aux=imresize(aux,size(Im1),'nearest');
                    end
                    if(out<0)
                        if(size(app.IIa{ii},1)==size(aux,1))% this would be used if mask was same size as original image but due to filtering we gotta crop some
                            out=-out;
                            aux=aux(1+out:end-out,1+out:end-out);
                        else% mask is highres and image is downsampled, we gotta downsample mask
                            aux=imresize(aux,size(Im1),'nearest');
                        end
                    end
                     aux(aux<0)=0;% incase we inverted a labeled mask
                     if(app.integrateRegions.Value==0)% normal pixel based operation
                         aux(aux>1)=1;% incase it is a labeled mask
                         try
                             Im1=Im1.*aux;
                             S1(aux==0)=NaN;
                             G1(aux==0)=NaN;
                         end
                     else
                         L=unique(aux(:));
                         L(L==0)=[];
                         % compute mean S,G
                         for jj=1:numel(L)
                             gg=S1(aux==L(jj));gg(isnan(gg))=[];S1(aux==L(jj))=mean(gg);
                             gg=G1(aux==L(jj));gg(isnan(gg))=[];G1(aux==L(jj))=mean(gg);
                         end
                         S1(aux==0)=NaN;
                         G1(aux==0)=NaN;
                     end
                end


                if(nargin==1),d = barraprogress(app,(ii-.2)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end
                % map on phasor space


                if(app.IvMagStyle.Value==0) % normal

                    [pp]=phasorPlotFast(S1,G1,phsiz,fov);
                
                    
                    if((app.Applymask.Value==1)&&(app.integrateRegions.Value==1))
                        % getnhood(strel('disk',ceil(max(phsiz(:))*.005)))
                        pp=conv2(pp,getnhood(strel('disk',ceil(max(phsiz(:))*.005))),'same');
                        aux=find(pp==max(pp(:)));
                        pp(aux(1))=pp(aux(1))*1.1;% make 1 px brighter in case colormap top is same as background
                        % pp=imgaussfilt(pp,max(phsiz(:))*.005);
                    elseif(max(size(Im1))==1)% assume these are traces, make bigger for display
                        pp=imgaussfilt(pp,max(phsiz(:))*.005);

                        % if(0)% turn to scatterplot
                        %            pp=NormArray(pp);
                        %            pp(pp>.1)=1;pp(pp<=.1)=0;
                        % end
                        % pp=imgaussfilt(pp,max(phsiz(:))*.005);
                        % v=max(pp(:));pp(pp<.5*v)=0;pp(pp>=.5*v)=1;%
                    end
                else% Int vs Mag
                    [~,~,codi] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);

                    [R] = magnitude(S1,G1,codi,app.freq,app.Harm.Value);
                    R=real(R);
                    % aux=quantile(R(:),[.01 .99]);
                    % Srange=[app.Smin.Value app.Smax.Value];
                    % app.Gmin.Value=aux(1);
                    % app.Gmax.Value=aux(2);
                    % Grange=aux;
                    % fov=[Grange;Srange];
                    % gag=numel(unique(Im1(:)));
                    % if(phsiz(1)>gag),;end
                    [pp]=phasorPlotFast(Im1,R,phsiz,fov);
                    S1=Im1;G1=R;
                    app.PhBg.Value=0;

                end
                % figure;imagesc(pp);
                % pp=NormArray2(pp);
                % figure,imagesc(pp)
                PPa(:,:,ii)=pp;

                % update plotting versions
                if(nargin==1)
                    app.SSi{ii}=S1;
                    app.GGi{ii}=G1;
                    app.IIi{ii}=Im1;
                    app.MK{ii}=ones(size(S1,1),size(S1,2),3);
                end

                if(nargin==1),d = barraprogress(app,(ii-.1)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end

                Crange=crangeinunity(app);
                [type,centre] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);
                if(isempty(centre)),centre=[app.Gcenter.Value app.Scenter.Value];end
                if(type==3)% centers are shifted by 1000 to use radial as linear
                    Crange=Crange+1000;
                    type=2;
                end
                if(app.gmmexport.Value==0)
                    I1=1;I2=1;IC=1;
                    if(app.PhBg.Value==1)||((app.ExMagS.Value==1)||(app.ExMagH.Value==1))
                        aux=app.RCM;%if(app.DM==1),aux=['k' aux(2:end-1) 'k'];end
                        [~,~,I1,I2,IC] = phasorRainbowIm(S1,G1,Im1,phsiz,fov,centre,1,Crange,aux,1,type);
                    end
                else
                    I1=app.MK{ii};
                    if(app.exportHR==1)&&(nargin>1)
                        ensenya('GMM not implemented for HR','r');
                        I1=imresize(I1,size(Im1));
                    end

                    I2=I1.*Im1;
                    I2=uint8(255*I2);
                end

                if(nargin==1)
                    if(app.PhBg.Value==1)
                        app.MK{ii}=I1;
                        app.MKp{ii}=I1;
                    end
                end
                I1=uint8(255*I1);
                if(nargin==1),d = barraprogress(app,(ii)/N,d,'Thresholding|Filtering|Cropping|Masking|Projecting|Plotting');end

            end
            if(N==0)% create rainbow
                if(app.PhBg.Value==1)
                    [type,centre] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);
                    if(isempty(centre)),centre=[app.Gcenter.Value app.Scenter.Value];end
                    Crange=crangeinunity(app);
                    if(type==3)% centers are shifted by 1000 to use radial as linear
                        Crange=Crange+1000;
                        type=2;
                    end
                    [~,~,~,~,IC] = phasorRainbowIm(0,0,0,phsiz,fov,centre,1,Crange,app.RCM,1,type);
                end
            end
            app.currPP=PPa;
            app.currIC=IC;
            replotPP(app);
            if(N>0)&&(nargin==1),replotII(app);end
            try close(d);end


            if(~((isempty(app.PDh)||~ishandle(app.PDh))))
                DecaysPlotButton(app);
            end

            app.running=0;

        end
        function [] = IntWinUp(varargin)
            % ensenya('','r');
        end
        function [] = IntWinDown(varargin)
            % ensenya('','e');
        end
        function [] = IntWinClose(varargin)
            try,saveParams(varargin{1});end
            delete(varargin{2});
        end
        function [] = IntWinMotion(varargin)
            app=varargin{1};
            ff=varargin{2};
            N=numel(ff.Children);
            % disp('##');
            inone=0;
            for ii=1:N
                cp=ff.Children(ii).CurrentPoint(1,1:2);
                pp=ff.Children(ii).XLim;
                if(cp(1)>pp(1))&&(cp(1)<pp(2))
                    inone=1;
                    pp=ff.Children(ii).YLim;
                    if(cp(2)>pp(1))&&(cp(2)<pp(2))
                        cp=round(cp);
                        %                                 ff.Pointer='custom';
                        %                            ff.PointerShapeCData=app.curs2(:,:,app.radi);
                        % ff.PointerShapeHotSpot=[16 16];
                        ff.Pointer='crosshair';
                        qq=ff.Children(ii).UserData;
                        if(~isempty(qq))
                            app.Tree.SelectedNodes=app.Tree.Children(qq);
                            % disp(ff.Children(ii).UserData);
                            if(isnan(cp(1)))
                                app.imcur.XData=-1000;
                            else
                                app.imcur.XData=app.GGi{qq}(cp(2),cp(1));
                                app.imcur.YData=app.SSi{qq}(cp(2),cp(1));
                                % if we want to display pix coords
                                % app.coolab.Text=['(' niceNums(cp(1),0) ',' niceNums(cp(2),0) ')'];
                                % display phasor coords
                                lag=app.GGi{qq}(cp(2),cp(1));
                                las=app.SSi{qq}(cp(2),cp(2));

                                if(app.LifetimeStyle.Value==1)
                                    tp=magnitude(las,lag,5,app.freq,app.Harm.Value);
                                    tn=magnitude(las,lag,7,app.freq,app.Harm.Value);
                                    tm=magnitude(las,lag,6,app.freq,app.Harm.Value);
                                    app.coolab.Text=['$$\rm \textsf{G=' niceNums(lag,2) ' S=' niceNums(las,2) ' }\tau_{p}\textsf{=' niceNums(tp,2) ' } \tau_{n}\textsf{=' niceNums(tn,2) ' }\tau_{m}\textsf{=' niceNums(tm,2) '}$$'];
                                else
                                    app.coolab.Text=['$$\rm \textsf{G=' niceNums(lag,2) ' S=' niceNums(las,2) ' }$$'];

                                end
                            end
                            % figure(88);clf;
                            % imagesc(app.IIi{qq});hold on;
                            % plot(cp(1),cp(2),'or');
                        end
                    end

                end
            end
            if(inone==0)
                app.imcur.XData=-1000;
                app.coolab.Text='';
            end
        end


        function [] = LoadCalibrationFile(app)
            figure(app.GSLabUIFigure);
            d=barraprogress(app,0,[],'Loading calibration');
            N=1;
            ruta{1}=app.folderC;
            try
                [I,dad] = readFlameRaw(ruta{1},1);
            catch
                I=[];
            end
            notloaded=0;

            if(isempty(I))% attempt run as folder pack
                notloaded=1;
                N=0;
                S=dir(app.folderC);
                for ii=length(S):-1:1
                    if(S(ii).isdir==0)
                        S(ii)=[];
                    end
                end
                S(2)=[];S(1)=[];%remove . and ..
                N=length(S);
                ruta=cell(0);
                for ii=1:N
                    ruta{ii}=[S(ii).folder filesep S(ii).name];
                end
            end

            if(N==0)
                uialert(app.GSLabUIFigure,['Empty calibration path!'],'Warning','icon','warning');
            else
                for jj=1:N
                    if(notloaded==1)
                        [I,dad] = readFlameRaw(ruta{jj},0);
                    end
                    d=barraprogress(app,.1,d,'Loading calibration');
                    I=sum(sum(I,1),2);
                    nom=ruta{jj}(find(ruta{jj}=='_',1,'last')+1:end);

                    for ii=1:app.HarmLim.Value
                        Ng=0;
                        switch nom
                            case '32Sp'
                                ch1=1:16;%dad.channelsSaved(1:ceil(end/2));
                                ch2=17:32;%dad.channelsSaved(1+ceil(end/2):end);
                                [S2,G2]=fpt(I(:,:,ch2),ii);
                                Ng=1;
                            case '32A1'
                                ch1=1:32;%dad.channelsSaved;
                                Ng=4;
                            case '32A0'
                                ch1=1:32;%dad.channelsSaved;
                                Ng=3;
                            case '31nB'
                                ch1=[1:30 31 31];
                                Ng=5;
                                S2=zeros(size(I,1),size(I,2));
                                G2=S2;
                        end
                        if(Ng>0)
                            [S1,G1]=fpt(I(:,:,ch1),ii);
                            [s,g]=tau2coords(app.LT.Value,app.freq,ii);
                            aux=atan2(S1,G1);aux=aux(:);aux(isnan(aux))=[];
                            app.PD(Ng,ii)=atan2(s,g)-mean(mean(aux));
                            aux=sqrt((S1.*S1)+(G1.*G1));aux=aux(:);aux(isnan(aux))=[];
                            app.MF(Ng,ii)=sqrt((s*s)+(g*g))/mean(mean(aux));

                            if(Ng==1)% split do second half
                                aux=atan2(S2,G2);aux=aux(:);aux(isnan(aux))=[];
                                app.PD(2,ii)=atan2(s,g)-mean(mean(aux));
                                aux=sqrt((S2.*S2)+(G2.*G2));aux=aux(:);aux(isnan(aux))=[];
                                app.MF(2,ii)=sqrt((s*s)+(g*g))/mean(mean(aux));
                                app.multilif(2)=app.LT.Value;
                            end
                        end
                        d=barraprogress(app,((jj-1)+ii)/N/app.HarmLim.Value,d,'Loading calibration');
                    end
                    if(Ng>0)
                        app.multical{Ng}=ruta{jj}(find(ruta{jj}==filesep,1,'last')+1:end);
                        app.multilif(Ng)=app.LT.Value;
                    end
                end
                calibrationDisplayUpdate(app);
            end
            calibrationApply(app);
            FilterAndPlotPhasor(app);
            try(close(d));end

        end
        function [] = LoadFlameRawFun(app)
            ensenya(['Starting'],'b');
            app.Applymask.Value=0;
            % delete(app.Tree.Children);


            app.running=1;

            N=0;cc=0;S=[];

            % unlimit harmonics
            app.Harm.Limits(2)=app.HarmLim.Value;

            try
                S=dir(app.folderD);
                % keep only folders matching known names
                for ii=numel(S):-1:1
                    if(S(ii).isdir==0)
                        S(ii)=[];
                    else
                        try
                            aux=S(ii).name(find(S(ii).name=='_',1,'last')+1:end);

                            switch aux
                                case {'32Sp','32A1','32A0','31nB'}

                                otherwise
                                    S(ii)=[];
                            end
                        catch
                            S(ii)=[];
                        end
                    end
                end
                N=numel(S);
                if(N==0)% if no folders are left
                    % its possible user is selecting daughter not mother folder
                    % check to see if it has a known name
                    aux=app.folderD(find(app.folderD=='_',1,'last')+1:end);
                     switch aux
                                case {'32Sp','32A1','32A0','31nB'}
                        aux=find(app.folderD==filesep,1,'last');
                        S(1).folder=app.folderD(1:aux-1);
                        S(1).name=app.folderD(aux+1:end);
                        S(1).isdir=1;
                        N=1;                                    
                     end
                end
                if(N==0)% still no folders are left
    % its possible user is selecting grandmother not mother folder
                    % loop all subfolders
                    S=dir(app.folderD);
                    SSS=struct();contapaths=0;
                    % keep only folders matching known names
                    for ii=3:numel(S)
                        if(S(ii).isdir==1)
                            SS=dir([S(ii).folder filesep S(ii).name]);
                            for jj=3:numel(SS)
                            try
                                aux=SS(jj).name(find(SS(jj).name=='_',1,'last')+1:end);
                                switch aux
                                    case {'32Sp','32A1','32A0','31nB'}
                           contapaths=contapaths+1;
                            SSS(contapaths).folder=SS(jj).folder;
                            SSS(contapaths).name=SS(jj).name;
                            SSS(contapaths).isdir=1;
                                end
                            end
                            end
                        end
                    end
                    N=numel(SSS);                    
                    S=SSS;
                end
            end


            d=barraprogress(app,0,[],'Please wait...');


            Ng=-1;

            if(isempty(S))
                uialert(app.GSLabUIFigure,['Empty data path!'],'Warning','icon','error');
            end

            for ii=1:numel(S)

                d=barraprogress(app,(cc+.01)/N,d,[S(ii).name ]);
                %%%%%%%%%%%%%%%%

                if(S(ii).isdir)
                    nom=S(ii).name(find(S(ii).name=='_',1,'last')+1:end);

                    switch nom% these are assigned to calibflag
                        case '32Sp'
                            Ng2=1;% 1 and 2 (assigned later) for flimsplits
                        case '32A1'
                            Ng2=4;% 4 is 32A1
                        case '32A0'
                            Ng2=3;% 3 is 32A0
                        case '31nB'
                            Ng2=5;% 5 is 31nB
                        otherwise
                            Ng2=0;
                    end

                    if(Ng2~=0)
                        pav=2+app.HarmLim.Value;% progress addition value (3 points load/compute(x)harm/export)
                        ensenya(['Starting ' S(ii).name],'b');
                        % I=fishTiffs([S(ii).folder filesep S(ii).name]);
                        [I,dad] = readFlameRaw([S(ii).folder filesep S(ii).name],1);

                        %%

                        Nslices=numel(dad.tileZs);
                        if(size(I,5)~=Nslices)
                            ensenya(['Warning: data contains ' num2str(size(I,5)) ' slices, txt file states ' num2str(Nslices) '.'],'a');
                            Nslices=size(I,5);

                        end
                        Ntiles=1;
                        if(dad.geometryId==2)||(dad.geometryId==4)
                            Ntiles=dad.tileRows*dad.tileCols;
                        end
                        rep=1;% usually no need to repeat, only flimsplit
                        if(Ng2==1)
                            %% attention, we now force to 32
                            ch1=[1:16];%dad.channelsSaved(1:ceil(end/2));
                            ch2=[17:32];%dad.channelsSaved(1+ceil(end/2):end);
                            rep=2;% for split files we gotta run phasor twice
                        end
                        if(Ng2==3)||(Ng2==4)
                            ch1=[1:32];%dad.channelsSaved;
                        end
                        if(Ng2==5)
                            ch1=[1:30 31 31];% % achtung
                            ch2=32;
                            if(app.LoadFLAME31nBwithBCheckBox.Value==1)
                                rep=2;% if we wanna load blue
                            else
                                rep=1;
                            end
                        end

                        %%

                        cc2=0;

                        % size(I)
                        for kk=1:Nslices
                            for ll=1:Ntiles

                                ext2=[];
                                if(Nslices>1)
                                    ext2=[ext2 '_z'   niceDigits(kk,2)];
                                end
                                if(Ntiles>1)
                                    ext2=[ext2 '_t'   niceDigits(ll,2)];
                                end

                                for jj=1:rep
                                    ext=[];% separate A1 from A0 in flimsplits
                                    if(jj==1)
                                        cht=ch1;
                                        if(rep>1)
                                            ext='_A1';
                                        end
                                    else
                                        cht=ch2;
                                        ext='_A0';
                                    end

                                    if(sum(size(I,3)<cht)>0)
                                        ensenya('Error: Missing frames in tiff!','r');
                                        break;
                                    end

                                    app.LoadedIms=app.LoadedIms+1;

                                    app.calibflag(app.LoadedIms)=Ng2;
                                    if(jj==2),app.calibflag(app.LoadedIms)=2;end

                                    app.IIo{app.LoadedIms}=I(:,:,cht,ll,kk);
                                    app.hdrkeep{app.LoadedIms}=[];
                                    Im1=sum(I(:,:,cht,ll,kk),3);

                                    app.keepPeakCounts(app.LoadedIms)=quantile(Im1(:),app.IntDispTop.Value);
                                    app.Imaxrange=max(app.keepPeakCounts);

                                    % downsample images
                                    if(app.dwnsmp>0)
                                        nwsz=round([app.dwnsmp size(Im1,2)*app.dwnsmp/size(Im1,1)]);
                                    else
                                        nwsz=size(Im1);
                                    end
                                    app.IIa{app.LoadedIms}=imresize(Im1,nwsz);% accumulated
                                    % if no cursors in place set mask to new
                                    aux=0;
                                    for mm=1:size(app.roidad,1)
                                        if(app.roidad(mm,3)>0)
                                            aux=aux+1;break
                                        end
                                    end
                                    if(aux==0)
                                        app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                                    else% since the accumulated SSi and GGi are not going to be cropped resize MK
                                        try% cursors could be in place but no mask yet (possibly user put cursors before loading data)
                                            app.MK{app.LoadedIms}=imresize(app.MK{app.LoadedIms},nwsz);
                                        catch % assume it is such case so we do have to initialise masks
                                            app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                                        end
                                    end
                                    app.MKp{app.LoadedIms}=app.MK{app.LoadedIms};

                                    cc2=cc2+1/Nslices/Ntiles/pav/rep;
                                    d=barraprogress(app,(cc+cc2)/N,d,[S(ii).name ext2]);

                                    for hh=1:app.HarmLim.Value
                                        % fast phasor transform
                                        if(numel(cht)>1)
                                            [S1,G1]=fpt(I(:,:,cht,ll,kk),hh);
                                        else
                                            S1=zeros(size(I,1),size(I,2));
                                            G1=S1;
                                        end

                                        % keep raw calibrated for accumulated
                                        app.SSo{app.LoadedIms,hh}=S1;
                                        app.GGo{app.LoadedIms,hh}=G1;

                                        % fill nans before resizing (otherwise only nearest algorithm works)!
                                        S1=phasorFillNaN2(S1);
                                        G1=phasorFillNaN2(G1);

                                        %resize
                                        app.SSa{app.LoadedIms,hh}=imresize(S1,nwsz);% accumulated
                                        app.GGa{app.LoadedIms,hh}=imresize(G1,nwsz);% accumulated

                                        cc2=cc2+1/Nslices/Ntiles/pav/rep;
                                        d=barraprogress(app,(cc+cc2)/N,d,[S(ii).name ext2 '_h' num2str(hh)]);
                                    end


                                    % decay
                                    fluo=permute(sum(sum(I(:,:,cht,ll,kk),1),2),[3 1 2]);
                                    app.dda{app.LoadedIms}=fluo;

                                    % names
                                    app.pat{app.LoadedIms}=S(ii).folder;
                                    if(app.AddFolders.Value==0)
                                        app.nms{app.LoadedIms}=S(ii).name;
                                    else
                                        aux='';try,aux=S(ii).folder(find(S(ii).folder==filesep,1,'last')+1:end);aux=[aux filesep];end
                                        app.nms{app.LoadedIms}=[aux S(ii).name];
                                    end
                                    app.nmsx{app.LoadedIms}=[ext2 ext];

                                    % add to tree
                                    aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                                    cc2=cc2+1/Nslices/Ntiles/pav/rep;
                                    d=barraprogress(app,(cc+cc2)/N,d,[S(ii).name ext2 '_h' num2str(hh)]);

                                end
                            end
                        end
                    end
                end
                cc=cc+1;
                %%%%%%%%%%%%%%%%
            end

            try
                d=barraprogress(app,1,d,'Finishing');
                close(d)
            end
            try
                close(app.IWh);
            end
            if(app.DoCalib.Value==1)
                calibrationApply(app);
            end


            delete(app.ComponentTree.Children);

            ensenya('Finished','e');

            app.running=0;
        end

        function [] = LoadTifSeqFun(app,filepaths)
            app.running=1;
            if(ischar(filepaths)),filepaths={filepaths};end
            d=barraprogress(app,0,[],'Please wait...');
            ensenya(['Starting'],'b');
            app.Applymask.Value=0;
            % delete(app.Tree.Children);


            N=0;cc=0;S=[];

            % unlimit harmonics
            app.Harm.Limits(2)=app.HarmLim.Value;


            N=numel(filepaths);
            for ii=1:N
                nom=filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end);

                d=barraprogress(app,(ii-.9)/N,d,[nom ' __']);

                I=fishTiffs(filepaths{ii});
                [~,~,chgap]=size(I);
                if(chgap>1)
                    cht=1:chgap;
                    ensenya(['Time dimension has ' num2str(chgap) ' bins']);
                    ext=[];% maybe use this for calibration tag

                    app.LoadedIms=app.LoadedIms+1;

                    app.calibflag(app.LoadedIms)=0;% use calib flag for predefined tags

                    app.IIo{app.LoadedIms}=I(:,:,cht);
                    app.hdrkeep{app.LoadedIms}=[];
                    Im1=sum(I(:,:,cht),3);
                    app.keepPeakCounts(app.LoadedIms)=quantile(Im1(:),app.IntDispTop.Value);
                    app.Imaxrange=max(app.keepPeakCounts);

                    % downsample images
                    if(app.dwnsmp>0)
                        nwsz=round([app.dwnsmp size(Im1,2)*app.dwnsmp/size(Im1,1)]);
                    else
                        nwsz=size(Im1);
                    end
                    app.IIa{app.LoadedIms}=imresize(Im1,nwsz);% accumulated
                    % if no cursors in place set mask to new
                    aux=0;
                    for ll=1:size(app.roidad,1)
                        if(app.roidad(ll,3)>0)
                            aux=aux+1;break
                        end
                    end
                    if(aux==0)
                        app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                    else% since the accumulated SSi and GGi are not going to be cropped resize MK
                        try% cursors could be in place but no mask yet (possibly user put cursors before loading data)
                            app.MK{app.LoadedIms}=imresize(app.MK{app.LoadedIms},nwsz);
                        catch % assume it is such case so we do have to initialise masks
                            app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                        end
                    end
                    app.MKp{app.LoadedIms}=app.MK{app.LoadedIms};
                    d=barraprogress(app,(ii-.6)/N,d,[nom ' ']);

                    for hh=1:app.HarmLim.Value
                        % fast phasor transform
                        [S1,G1]=fpt(I(:,:,cht),hh);

                        % keep raw calibrated for accumulated
                        app.SSo{app.LoadedIms,hh}=S1;
                        app.GGo{app.LoadedIms,hh}=G1;

                        % fill nans before resizing (otherwise only nearest algorithm works)!
                        S1=phasorFillNaN2(S1);
                        G1=phasorFillNaN2(G1);

                        %resize
                        app.SSa{app.LoadedIms,hh}=imresize(S1,nwsz);% accumulated
                        app.GGa{app.LoadedIms,hh}=imresize(G1,nwsz);% accumulated


                        d=barraprogress(app,(ii-.6+(.5*hh/app.HarmLim.Value))/N,d,[nom ' h' num2str(hh)]);
                    end

                    % decay
                    fluo=permute(sum(sum(I,1),2),[3 1 2]);
                    app.dda{app.LoadedIms}=fluo;

                    % names
                    app.pat{app.LoadedIms}=filepaths{ii}(1:find(filepaths{ii}==filesep,1,'last')-1);
                    if(app.AddFolders.Value==0)
                        app.nms{app.LoadedIms}=nom;
                    else
                        aux='';try,aux=app.pat{app.LoadedIms}(find(app.pat{app.LoadedIms}==filesep,1,'last')+1:end);aux=[aux filesep];end
                        app.nms{app.LoadedIms}=[aux nom];
                    end
                    app.nmsx{app.LoadedIms}=[];

                    % add to tree
                    aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];


                    d=barraprogress(app,ii/N,d,[nom ' h' num2str(hh)]);
                end
            end
            try
                d=barraprogress(app,1,d,'Finishing');
                close(d);
            end
            try
                close(app.IWh);
            end
            if(app.DoCalib.Value==1)
                calibrationApply(app);
            end


            delete(app.ComponentTree.Children);

            ensenya('Finished','e');

            app.running=0;
        end

        function [] = LoadCGohlkeFun(app,filepaths)
            app.running=1;
            if(ischar(filepaths)),filepaths={filepaths};end
            d=barraprogress(app,0,[],'Please wait...');
            ensenya(['Starting'],'b');
            app.Applymask.Value=0;
            % delete(app.Tree.Children);

            % unlimit harmonics
            app.Harm.Limits(2)=app.HarmLim.Value;
            N=numel(filepaths);
            for ii=1:N
                nom=filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end);

                d=barraprogress(app,(ii-1)/N,d,[nom ' ']);
                if(strcmp(nom(end-2:end),'sdt'))
                    data=double(pyrunfile('sdtf.py',"e",file=filepaths{ii}));
                end
                if(strcmp(nom(end-2:end),'ptu'))
                    data=double(pyrunfile('ptuf.py',"e",file=filepaths{ii}));
                end
                orisiz=size(data);
                [dims,ord]=sort(orisiz,'descend');

                try
                    aux=ord;aux2=numel(ord);aux(aux==aux2)=[];aux=[aux(1:2),aux2,aux(3:end)];
                    data=permute(data,aux);
                    if(numel(aux)>4)

                        fra=[];for jj=1:numel(orisiz),fra=[fra num2str(orisiz(jj)) ' x '];end;fra=fra(1:end-3);
                        ensenya(['Warning: file was read as ' fra],'a');

                        newsz=size(data);
                        newsz=[newsz(1),newsz(2),newsz(3),prod(newsz(4:end))];
                        data=reshape(data,newsz);

                        fra=[];for jj=1:numel(newsz),fra=[fra num2str(newsz(jj)) ' x '];end;fra=fra(1:end-3);
                        ensenya(['         reshaping to ' fra],'a');

                    end
                catch
                    success=0;
                    ensenya('Error in new formatting, switching to old. Send me the file plz: avallmit@uci.edu','r');
                    if(size(data,1)==1)&&(size(data,4)>1)
                        % assume first dimension is python 0th, 2nd and 3rd are image and 4th is time
                        data=permute(data,[2 3 4 1]);success=1;
                    end
                    if(size(data,1)==1)&&((size(data,4)==1)&&(size(data,5)>1))
                        % assume first dimension is python 0th, 2nd and 3rd are image and 5th is time
                        data=permute(data,[2 3 5 4 1]);success=1;
                    end
                    if(size(data,4)==1)
                        % assume dimensions are x y t
                        data=data(:,:,:);success=1;
                    end
                    if(success==0),ensenya('Error: unexpected  dimensions');continue;end
                end
                %%
                % disp(size(data));
                cht=1:size(data,3);
                ensenya(['Time dimension has ' num2str(numel(cht)) ' bins']);
                CC=size(data,4);
                HH=app.HarmLim.Value;
                for cc=1:CC
                    ext=[];
                    if(CC>1)
                        ext=['_ch' niceDigits(cc)];
                    end

                    app.LoadedIms=app.LoadedIms+1;

                    app.calibflag(app.LoadedIms)=0;% use calib flag for predefined tags

                    app.IIo{app.LoadedIms}=data(:,:,cht,cc);
                    app.hdrkeep{app.LoadedIms}=[];
                    Im1=sum(data(:,:,cht,cc),3);
                    app.keepPeakCounts(app.LoadedIms)=quantile(Im1(:),app.IntDispTop.Value);
                    app.Imaxrange=max(app.keepPeakCounts);

                    % downsample images
                    if(app.dwnsmp>0)
                        nwsz=round([app.dwnsmp size(Im1,2)*app.dwnsmp/size(Im1,1)]);
                    else
                        nwsz=size(Im1);
                    end
                    % wtf dunno what this was for
                    % if(nwsz(1)>size(Im1,1))||(nwsz(2)<size(Im1,2))
                    %     nwsz=size(Im1);
                    % end
                    app.IIa{app.LoadedIms}=imresize(Im1,nwsz);% accumulated
                    % if no cursors in place set mask to new
                    aux=0;
                    for ll=1:size(app.roidad,1)
                        if(app.roidad(ll,3)>0)
                            aux=aux+1;break
                        end
                    end
                    if(aux==0)
                        app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                    else% since the accumulated SSi and GGi are not going to be cropped resize MK
                        try% cursors could be in place but no mask yet (possibly user put cursors before loading data)
                            app.MK{app.LoadedIms}=imresize(app.MK{app.LoadedIms},nwsz);
                        catch % assume it is such case so we do have to initialise masks
                            app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                        end
                    end
                    app.MKp{app.LoadedIms}=app.MK{app.LoadedIms};
                    d=barraprogress(app,((ii-1)*CC+(cc-.6))/CC/N,d,[nom ]);

                    for hh=1:HH
                        % fast phasor transform
                        [S1,G1]=fpt(data(:,:,cht,cc),hh);

                        % keep raw calibrated for accumulated
                        app.SSo{app.LoadedIms,hh}=S1;
                        app.GGo{app.LoadedIms,hh}=G1;

                        % fill nans before resizing (otherwise only nearest algorithm works)!
                        S1=phasorFillNaN2(S1);
                        G1=phasorFillNaN2(G1);

                        %resize
                        app.SSa{app.LoadedIms,hh}=imresize(S1,nwsz);% accumulated
                        app.GGa{app.LoadedIms,hh}=imresize(G1,nwsz);% accumulated


                        d=barraprogress(app,((ii-1)*CC+(cc-.6+(.5*hh/HH)))/CC/N,d,[nom ' h' num2str(hh)]);
                    end

                    % decay
                    fluo=permute(sum(sum(data(:,:,:,cc),1),2),[3 1 2]);
                    app.dda{app.LoadedIms}=fluo;

                    % names
                    app.pat{app.LoadedIms}=filepaths{ii}(1:find(filepaths{ii}==filesep,1,'last')-1);
                    if(app.AddFolders.Value==0)
                        app.nms{app.LoadedIms}=nom;
                    else
                        aux='';try,aux=app.pat{app.LoadedIms}(find(app.pat{app.LoadedIms}==filesep,1,'last')+1:end);aux=[aux filesep];end
                        app.nms{app.LoadedIms}=[aux nom];
                    end
                    app.nmsx{app.LoadedIms}=ext;

                    % add to tree
                    aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];


                    d=barraprogress(app,((ii-1)*CC+cc)/CC/N,d,[nom ' h' num2str(hh)]);
                end
                %%
            end
            try
                d=barraprogress(app,1,d,'Finishing');
                close(d);
            end
            try
                close(app.IWh);
            end
            if(app.DoCalib.Value==1)
                calibrationApply(app);
            end
            delete(app.ComponentTree.Children);
            ensenya('Finished','e');
            app.running=0;
        end
        function [] = LoadFLIMlabsFun(app,filepaths)
            app.running=1;
            if(ischar(filepaths)),filepaths={filepaths};end
            d=barraprogress(app,0,[],'Please wait...');
            ensenya(['Starting'],'b');
            app.Applymask.Value=0;
            % delete(app.Tree.Children);


            N=0;cc=0;S=[];

            % unlimit harmonics
            app.Harm.Limits(2)=app.HarmLim.Value;
            if(app.PromptFLIMlabsloadoptionCheckBox.Value==1)
                gg='Raw';
                gg=uiconfirm(app.GSLabUIFigure, {'Files containing precalibrated phasor data,','load raw or phasor?',' '},'FLIMlabs prompt', 'Options', {'Raw','Precomputed'},'Icon','none');
            else
                if(app.LoadFLIMlabsrawCheckBox.Value==1)
                    gg='raw';
                else
                    gg='precomputed';
                end
            end


            N=numel(filepaths);
            for ii=1:N
                nom=filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end);

                d=barraprogress(app,(ii-1)/N,d,[nom ]);

                % Read phasors .json file
                try
                    fid = fopen(filepaths{ii}, 'r');
                    raw = fread(fid, inf, '*char')';
                    fclose(fid);
                    p_data = jsondecode(raw);
                catch
                    ensenya('Error reading JSON file.','r');
                    continue;
                end

                % 'header' field check
                if ~isfield(p_data, 'header')
                    ensenya('Error: Invalid data file. Missing header field.','r');
                    continue;
                end

                % File identifier check
                % file_id must be IPG1. 'IPG1' is an identifier for phasors - imaging .json files

                magic_bytes = char(p_data.header.file_id);
                magic_bytes_string = strjoin(cellstr(magic_bytes'), '');

                switch magic_bytes_string
                    case 'IPG1'
                        if(strcmpi(gg,'raw'))
                            type=2;
                        else
                            type=1;
                        end
                        % type=1;
                    case 'IMG1'
                        type=2;% image decay
                    otherwise
                        ensenya('Error: Invalid data file. Selected file is not a Phasors - Imaging .json file','r');
                        continue;
                end

                % Read file metadata
                metadata = p_data.header;

                % Laser freq
                if isfield(metadata, 'laser_period_ns')
                    app.freq = 1e9/metadata.laser_period_ns;
                    app.freqEF.Value=app.freq*1e-6;
                end

                % Image width
                if ~isfield(metadata, 'image_width')
                    ensenya('Error: Image width not found in metadata.','r');
                    continue;
                end

                % Image height
                if ~isfield(metadata, 'image_height')
                    ensenya('Error: Image height not found in metadata.','r');
                    continue;
                end
                % Harmonics
                if(type==1)
                    if isfield(metadata, 'harmonics')
                        app.HarmLim.Value=metadata.harmonics;
                        app.Harm.Limits(2)=app.HarmLim.Value;
                    end
                end
                % Retrieve parameters for analysis
                image_width = metadata.image_width;
                image_height = metadata.image_height;


                Nchan=1;
                % Nchan=numel(imaging_data);
                if(type==2)

                    try% this try should work for type2, when we are opening a type1 as type2 it should fail
                        Nchan=numel(p_data.data);
                    catch% we are opening a phasor file 'IPG1' as raw 'IMG1'
                        Nchan=numel(p_data.intensities_data);
                    end
                end
                for ch=1:Nchan
                    if(type==2)% raw image and decay
                        % loose pixel decay
                        % image_data = zeros(image_height, image_width, 'uint32');
                        % decay_curve = zeros(1, 256, 'int64');
                        % keep all (to have N harmonics)
                        image_data=zeros(image_height, image_width, 256);


                        d=barraprogress(app,((ii-1)+((ch+.4)/(Nchan+1)))/N,d,[nom ]);
                        try% this try should work for type2, when we are opening a type1 as type2 it should fail
                            channel_data = p_data.data{ch};
                        catch% we are opening a phasor file 'IPG1' as raw 'IMG1'
                            channel_data=p_data.intensities_data{ch};
                        end

                        for pixel_index = 1:(image_width * image_height)
                            pixel_data = channel_data{pixel_index};
                            % total_photon_count = sum(pixel_data(:, 2));
                            row = floor((pixel_index - 1) / image_width) + 1;
                            col = mod((pixel_index - 1), image_width) + 1;

                            if ~isempty(pixel_data)
                                for i = 1:size(pixel_data, 1)
                                    bin_index = pixel_data(i, 1);
                                    if bin_index < 256
                                        image_data(row, col,bin_index + 1) = image_data(row, col,bin_index + 1)+pixel_data(i, 2);
                                        % decay_curve(bin_index + 1) = decay_curve(bin_index + 1) + bin_count;
                                    end
                                end
                            end

                        end
                        decay_curve=permute(sum(sum(image_data,1),2),[3 1 2]);

                        phasors_data=struct();
                        for hh=1:app.HarmLim.Value
                            % fast phasor transform
                            [S1,G1]=fpt(image_data,hh);
                            phasors_data(hh).s_data=S1;
                            phasors_data(hh).g_data=G1;
                        end

                        % remove temporal info
                        image_data=sum(image_data,3);
                    end


                    if(type==1)% precomputed phasors
                        imaging_data =  p_data.intensities_data;
                        phasors_data = p_data.phasors_data;

                        d=barraprogress(app,((ii-1)+((ch+.4)/(Nchan+1)))/N,d,[nom ]);
                        % Extract image data


                        image_data = zeros(image_height, image_width, 'uint32');
                        decay_curve = zeros(1, 256, 'int64');

                        channel_data = imaging_data{ch};
                        for pixel_index = 1:(image_width * image_height)
                            pixel_data = channel_data{pixel_index};

                            if ~isempty(pixel_data)
                                for i = 1:size(pixel_data, 1)
                                    bin_index = pixel_data(i, 1);
                                    bin_count = pixel_data(i, 2);

                                    if bin_index < 256
                                        decay_curve(bin_index + 1) = decay_curve(bin_index + 1) + bin_count;
                                    end
                                end
                            end
                            total_photon_count = sum(pixel_data(:, 2));
                            row = floor((pixel_index - 1) / image_width) + 1;
                            col = mod((pixel_index - 1), image_width) + 1;
                            image_data(row, col) = total_photon_count;
                        end


                    end


                    ext=[];% maybe use this for calibration tag

                    app.LoadedIms=app.LoadedIms+1;

                    app.calibflag(app.LoadedIms)=0;% use calib flag for predefined tags

                    Im1=double(image_data);
                    app.IIo{app.LoadedIms}=Im1;
                    app.hdrkeep{app.LoadedIms}=[];
                    app.keepPeakCounts(app.LoadedIms)=quantile(Im1(:),app.IntDispTop.Value);
                    app.Imaxrange=max(app.keepPeakCounts);

                    % downsample images
                    if(app.dwnsmp>0)
                        nwsz=round([app.dwnsmp size(Im1,2)*app.dwnsmp/size(Im1,1)]);
                    else
                        nwsz=size(Im1);
                    end
                    app.IIa{app.LoadedIms}=imresize(Im1,nwsz);% accumulated
                    % if no cursors in place set mask to new
                    aux=0;
                    for ll=1:size(app.roidad,1)
                        if(app.roidad(ll,3)>0)
                            aux=aux+1;break
                        end
                    end
                    if(aux==0)
                        app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                    else% since the accumulated SSi and GGi are not going to be cropped resize MK
                        try% cursors could be in place but no mask yet (possibly user put cursors before loading data)
                            app.MK{app.LoadedIms}=imresize(app.MK{app.LoadedIms},nwsz);
                        catch % assume it is such case so we do have to initialise masks
                            app.MK{app.LoadedIms}=ones(nwsz(1),nwsz(2),3);
                        end
                    end
                    app.MKp{app.LoadedIms}=app.MK{app.LoadedIms};

                    d=barraprogress(app,((ii-1)+((ch+.6)/(Nchan+1)))/N,d,[nom ]);
                    for hh=1:app.HarmLim.Value
                        % fast phasor transform
                        S1=phasors_data(hh).s_data;
                        G1=phasors_data(hh).g_data;

                        % keep raw calibrated for accumulated
                        app.SSo{app.LoadedIms,hh}=S1;
                        app.GGo{app.LoadedIms,hh}=G1;

                        % fill nans before resizing (otherwise only nearest algorithm works)!
                        S1=phasorFillNaN2(S1);
                        G1=phasorFillNaN2(G1);

                        %resize
                        app.SSa{app.LoadedIms,hh}=imresize(S1,nwsz);% accumulated
                        app.GGa{app.LoadedIms,hh}=imresize(G1,nwsz);% accumulated


                    end

                    % decay
                    app.dda{app.LoadedIms}=decay_curve;

                    % names
                    app.pat{app.LoadedIms}=filepaths{ii}(1:find(filepaths{ii}==filesep,1,'last')-1);
                    if(app.AddFolders.Value==0)
                        app.nms{app.LoadedIms}=nom;
                    else
                        aux='';try,aux=app.pat{app.LoadedIms}(find(app.pat{app.LoadedIms}==filesep,1,'last')+1:end);aux=[aux filesep];end
                        app.nms{app.LoadedIms}=[aux nom];
                    end
                    aux='';
                    if(Nchan>1)
                        aux=['_ch' num2str(ch)];
                    end
                    app.nmsx{app.LoadedIms}=aux;

                    % add to tree
                    aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];


                    d=barraprogress(app,((ii-1)+((ch+1)/(Nchan+1)))/N,d,[nom ]);
                end
            end

            try
                d=barraprogress(app,1,d,'Finishing');
                close(d);
            end
            try
                close(app.IWh);
            end
            if(app.DoCalib.Value==1)
                calibrationApply(app);
            end


            delete(app.ComponentTree.Children);

            ensenya('Finished','e');

            app.running=0;

        end
        function [] = LoadFalconSnGFun(app,filepaths)
            ensenya('Loading     ');
            d=barraprogress(app,0,[],'Loading...');

            if(ischar(filepaths)),filepaths={filepaths};end
            try
                [ITT,SST,GGT,names] = readFALCONsng(filepaths{1});
                success=1;
            catch
                success=0;
                ensenya('No files!','r');
            end

            if(success==1)
                aux=filepaths{1};aux=aux(find(aux==filesep,1,'last')+1:end);
                if(isempty(names))

                    names=cell(0);
                    for jj=1:size(ITT,3)
                        names{jj}=aux;
                    end
                else
                    for jj=1:size(ITT,3)
                        names{jj}=[aux '_' names{jj}];
                    end
                end


                if(isempty(ITT))% maybe user selected mother folder?
                    cont=dir(filepaths{1});
                    ITT=[];SST=[];GGT=[];names=cell(0);
                    contanou=0;
                    for jj=3:numel(cont)
                        if(cont(jj).isdir==1)
                            [IT,ST,GT] = readFALCONsng([filepaths{1} filesep cont(jj).name]);
                            if(~isempty(IT))
                                ITT=cat(3,ITT,IT);
                                SST=cat(3,SST,ST);
                                GGT=cat(3,GGT,GT);
                                for kk=1:size(IT,3)
                                    contanou=contanou+1;
                                    names{contanou}=cont(jj).name;
                                end
                            end
                        end
                    end
                end

                if(~isempty(ITT))
                    for jj=1:size(ITT,3)

                        app.LoadedIms=app.LoadedIms+1;

                        II=ITT(:,:,jj);
                        S0=SST(:,:,jj);
                        G0=GGT(:,:,jj);

                        %int
                        app.IIo{app.LoadedIms}=II;
                        app.hdrkeep{app.LoadedIms}=[];
                        app.keepPeakCounts(app.LoadedIms)=quantile(II(:),app.IntDispTop.Value);
                        app.Imaxrange=max(app.keepPeakCounts);

                        % downsample images
                        if(app.dwnsmp>0)
                            nwsz=round([app.dwnsmp size(II,2)*app.dwnsmp/size(II,1)]);
                        else
                            nwsz=size(II);
                        end
                        app.IIa{app.LoadedIms}=imresize(II,nwsz);% accumulated

                        % raw phasor coords
                        app.SSo{app.LoadedIms,1}=S0;
                        app.GGo{app.LoadedIms,1}=G0;

                        % fill nans before resizing!
                        S0=phasorFillNaN2(S0);
                        G0=phasorFillNaN2(G0);

                        % downsample images
                        app.SSa{app.LoadedIms,1}=imresize(S0,nwsz);% accumulated
                        app.GGa{app.LoadedIms,1}=imresize(G0,nwsz);% accumulated

                        % keep decay
                        app.dda{app.LoadedIms}=[];

                        % names
                        % app.nms{app.LoadedIms}=[names{jj} '_' niceDigits(jj,2) '_(FALCON)'];
                        app.nmsx{app.LoadedIms}=[ ];
                        app.pat{app.LoadedIms}=filepaths{1};
                        if(app.AddFolders.Value==0)
                            app.nms{app.LoadedIms}=[names{jj} '_' niceDigits(jj,2)];
                        else
                            aux='';try,aux=app.pat{app.LoadedIms}(find(app.pat{app.LoadedIms}==filesep,1,'last')+1:end);aux=[aux filesep];end
                            app.nms{app.LoadedIms}=[aux names{jj} '_' niceDigits(jj,2)];
                        end

                        % add to tree
                        aux=uitreenode(app.Tree,'Text',app.nms{app.LoadedIms},'NodeData',app.LoadedIms);
                        app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                        % nocalib
                        app.calibflag(app.LoadedIms)=0;

                        percentatge(jj,size(ITT,3));
                        d=barraprogress(app,jj/size(ITT,3),d,names{jj});
                    end
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end

                try, close(d);end
                % limit harmonics
                app.Harm.Value=1;
                app.Harm.Limits(2)=1;
                app.HarmLim.Value=1;


            end
        end
        function [] = LoadZeissLSMFun(app,filepaths)
            ensenya('Loading');
            if(ischar(filepaths)),filepaths={filepaths};end
            NN=numel(filepaths);
            d=barraprogress(app,0,[],'Loading');
            for ii=1:NN
                % limit harmonics

                [Data,LSMinfo] = lsmread(filepaths{ii});
                DX=LSMinfo.voxSizeX;
                Ims0=permute(Data,[5,4,2,3,1]);%Y,X,C,Z,T
                % t series and Z not implemented, only one or the other
                ZZ=size(Ims0,4);
                dm='_Z';
                if((ZZ==1)&&(size(Ims0,5)>1)),Ims0=permute(Ims0,[1,2,3,5,4]);dm='_T';end
                for zz=1:ZZ
                    if(ZZ==1),ext='';else ext=[dm niceDigits(ii)];end
                    Ims=Ims0(:,:,:,zz);  % zstacks and time series have to be implemented

                    app.LoadedIms=app.LoadedIms+1;

                    for hh=1:app.HarmLim.Value
                        [S0,G0,im] = fpt(Ims,hh);
                        if(hh==1)
                            app.IIo{app.LoadedIms}=im;
                            app.hdrkeep{app.LoadedIms}=[];
                            app.keepPeakCounts(app.LoadedIms)=quantile(im(:),app.IntDispTop.Value);
                            app.Imaxrange=max(app.keepPeakCounts);
                            % downsample images
                            if(app.dwnsmp>0)
                                nwsz=round([app.dwnsmp size(im,2)*app.dwnsmp/size(im,1)]);
                            else
                                nwsz=size(im);
                            end
                            app.IIa{app.LoadedIms}=imresize(im,nwsz);% accumulated
                        end
                        app.SSo{app.LoadedIms,hh}=S0;
                        app.GGo{app.LoadedIms,hh}=G0;

                        % fill nans before resizing!
                        S0=phasorFillNaN2(S0);
                        G0=phasorFillNaN2(G0);

                        % downsample images
                        app.SSa{app.LoadedIms,hh}=imresize(S0,nwsz);% accumulated
                        app.GGa{app.LoadedIms,hh}=imresize(G0,nwsz);% accumulated
                    end

                    % keep decay
                    app.dda{app.LoadedIms}=[];

                    % names
                    app.nmsx{app.LoadedIms}=ext;
                    app.pat{app.LoadedIms}=filepaths{ii}(1:find(filepaths{ii}==filesep,1,'last')-1);
                    if(app.AddFolders.Value==0)
                        app.nms{app.LoadedIms}=filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end);
                    else
                        aux='';try,aux=app.pat{app.LoadedIms}(find(app.pat{app.LoadedIms}==filesep,1,'last')+1:end);aux=[aux filesep];end
                        app.nms{app.LoadedIms}=[aux filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end)];
                    end
                    % add to tree
                    aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                    % nocalib
                    app.calibflag(app.LoadedIms)=0;
                    d=barraprogress(app,((ii-1)+(zz/ZZ))/NN,d,['Loading']);
                end
            end


            d=barraprogress(app,1,d,'Finishing');
            pause(.68);
            try,  close(d);end
            ensenya('Finished!','e');
        end
        function [] = LoadTraceFun(app,filepaths)
            app.running=1;
            if(ischar(filepaths)),filepaths={filepaths};end
            d=barraprogress(app,.01,[],'Loading');
            NN=numel(filepaths);
            for ii=1:NN

                dades = readmatrix(filepaths{ii});
                dades(isnan(dades))=0;
                [aux1,aux2]=size(dades);
                if(aux1<aux2),dades=dades';end
                DD=size(dades,2);
                for jj=1:DD
                    app.LoadedIms=app.LoadedIms+1;
                    if(DD==1),ext='';else,ext=['_col' num2str(jj)];end

                    for hh=1:app.HarmLim.Value
                        [S0,G0] = fpt(dades(:,jj),hh);
                        im=1;
                        if(hh==1)
                            app.IIo{app.LoadedIms}=im;
                            app.hdrkeep{app.LoadedIms}=[];
                            app.keepPeakCounts(app.LoadedIms)=quantile(im(:),app.IntDispTop.Value);
                            app.Imaxrange=max(app.keepPeakCounts);

                            app.IIa{app.LoadedIms}=im;% accumulated
                        end
                        app.SSo{app.LoadedIms,hh}=S0;
                        app.GGo{app.LoadedIms,hh}=G0;

                        app.SSa{app.LoadedIms,hh}=S0;% accumulated
                        app.GGa{app.LoadedIms,hh}=G0;% accumulated
                    end

                    % keep decay
                    app.dda{app.LoadedIms}=dades(:,jj);

                    % names
                    app.nmsx{app.LoadedIms}=ext;
                    app.pat{app.LoadedIms}=filepaths{ii}(1:find(filepaths{ii}==filesep,1,'last')-1);
                    if(app.AddFolders.Value==0)
                        app.nms{app.LoadedIms}=filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end);
                    else
                        aux='';try,aux=app.pat{app.LoadedIms}(find(app.pat{app.LoadedIms}==filesep,1,'last')+1:end);aux=[aux filesep];end
                        app.nms{app.LoadedIms}=[aux filepaths{ii}(find(filepaths{ii}==filesep,1,'last')+1:end)];
                    end

                    % add to tree
                    aux=uitreenode(app.Tree,'Text',[app.nms{app.LoadedIms} app.nmsx{app.LoadedIms}],'NodeData',app.LoadedIms);
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                    % nocalib
                    app.calibflag(app.LoadedIms)=0;


                    d=barraprogress(app,((ii-1)+(jj/DD))/NN,d,['Loading']);
                end
            end
            d=barraprogress(app,1,d,'Finishing');
            pause(.68);
            try,  close(d);end
            ensenya('Loaded traces!','e');
            app.running=0;
        end
        function [] = LoadSimFCSrefFun(app,filepaths)

            ensenya('Loading 000%');
            d=barraprogress(app,0,[],'Loading');
            if(ischar(filepaths)),filepaths={filepaths};end
            NN=numel(filepaths);
            for ii=1:NN
                % limit harmonics
                switch filepaths{ii}(end-2:end)
                    case 're1',  app.Harm.Limits(2)=1;
                    case {'ref','R64'}, app.Harm.Limits(2)=2;
                    case 're3',  app.Harm.Limits(2)=3;
                    case 're4',  app.Harm.Limits(2)=4;
                    case 're5',  app.Harm.Limits(2)=5;
                    case 're6',  app.Harm.Limits(2)=6;
                    case 're7',  app.Harm.Limits(2)=7;
                    case 're8',  app.Harm.Limits(2)=8;
                end
                [FLIMdata,filename,pathname]=refread(filepaths{ii});
                im=FLIMdata{1}(:,:,1);

                app.LoadedIms=app.LoadedIms+1;

                app.IIo{app.LoadedIms}=im;
                app.hdrkeep{app.LoadedIms}=[];
                app.keepPeakCounts(app.LoadedIms)=quantile(im(:),app.IntDispTop.Value);
                app.Imaxrange=max(app.keepPeakCounts);

                % downsample images
                if(app.dwnsmp>0)
                    nwsz=round([app.dwnsmp size(im,2)*app.dwnsmp/size(im,1)]);
                else
                    nwsz=size(im);
                end
                app.IIa{app.LoadedIms}=imresize(im,nwsz);% accumulated

                for jj=2:2:size(FLIMdata{1},3)
                    Ph=FLIMdata{1}(:,:,jj);
                    Mo=FLIMdata{1}(:,:,jj+1);

                    S0=Mo.*sin(Ph*3.1416/180);
                    G0=Mo.*cos(Ph*3.1416/180);

                    app.SSo{app.LoadedIms,jj/2}=S0;
                    app.GGo{app.LoadedIms,jj/2}=G0;

                    % fill nans before resizing!
                    S0=phasorFillNaN2(S0);
                    G0=phasorFillNaN2(G0);

                    % downsample images
                    app.SSa{app.LoadedIms,jj/2}=imresize(S0,nwsz);% accumulated
                    app.GGa{app.LoadedIms,jj/2}=imresize(G0,nwsz);% accumulated
                end

                % keep decay
                app.dda{app.LoadedIms}=[];

                % names
                app.nmsx{app.LoadedIms}=[ ];
                app.pat{app.LoadedIms}=pathname(1:end-1);
                if(app.AddFolders.Value==0)
                    app.nms{app.LoadedIms}=filename{1};
                else
                    aux='';try,aux=app.pat{app.LoadedIms}(find(app.pat{app.LoadedIms}==filesep,1,'last')+1:end);aux=[aux filesep];end
                    app.nms{app.LoadedIms}=[aux filename{1}];
                end

                % add to tree
                aux=uitreenode(app.Tree,'Text',app.nms{app.LoadedIms},'NodeData',app.LoadedIms);
                app.Tree.CheckedNodes=[app.Tree.CheckedNodes;aux];

                % nocalib
                app.calibflag(app.LoadedIms)=0;

                percentatge(ii,NN);
                d=barraprogress(app,ii/NN,d,['Loading']);
            end

            % limit harmonics
            if(app.Harm.Value>app.Harm.Limits(2)),app.Harm.Value=app.Harm.Limits(2);end


            ensenya('Finished!','e');
            d=barraprogress(app,1,d,'Finishing');
            try,  close(d);end
        end

        function [] = mostramask(app)
            cc=0;sz=ceil(256/sqrt(app.LoadedIms));
            mg=ceil(.01*max(sz*[app.imagerows.Value,app.imagecols.Value]));
            for ii=1:app.imagerows.Value
                for jj=1:app.imagecols.Value
                    cc=cc+1;mk=[];
                    if(cc>app.LoadedIms),mk=.5*ones(sz,sz);end
                    if(cc>numel(app.MKL))
                        mk=.5*ones(sz,sz);
                    else
                        if(isempty(app.MKL{cc}))
                            mk=.5*ones(sz,sz);
                        else
                            mk=[];
                        end
                    end
                    if(isempty(mk))
                        mk=app.MKL{cc};
                        if(app.labeledmk==0)
                            mk(mk>1)=1;
                        end
                        mk=imresize(mk,[sz sz],'nearest');
                    end
                    if(jj==1)
                        IR=mk;
                    else
                        IR=cat(2,IR,.5*ones(sz,mg,size(mk,3)),mk);
                    end
                end
                if(ii==1)
                    IT=IR;
                else
                    IT=cat(1,IT,.5*ones(mg,size(IR,2),size(mk,3)),IR);
                end
            end

            % colormap(app.MKax,[.24 .15 .66;.98 .98 .08]);
            if(app.labeledmk==0)
                imagesc(app.MKax,IT);
                colormap(app.MKax,app.MKcmap);
            else
                IT(IT==.5)=0;
                IT(IT<0)=0;
                cmap=superjet(22,'rgbymcauFloHe7tZsM}-hK');
                NL=max(IT(:));
                cmap=repmat(cmap,[ceil(NL/size(cmap,1)) 1]);
                cmap=[0 0 0;cmap];
                if(size(cmap,1)>NL+1),cmap(NL+2:end,:)=[];end
                if(size(cmap,1)==2),cmap(2,:)=1;end% likely if inverted, set white
                imagesc(app.MKax,IT);
                colormap(app.MKax,cmap);
            end

            xlim(app.MKax,[1,size(IT,2)]);
            ylim(app.MKax,[1,size(IT,1)]);


        end

        function [] = moveTi(app)
            % move Ti field and label to the right of Eb
            app.Filtermatch.Position=[2+app.Eb.Position(1)+app.Eb.Position(3) app.Filtermatch.Position(2:4)];
            app.Ti.Position=[2+app.Filtermatch.Position(1)+app.Filtermatch.Position(3) app.Ti.Position(2:4)];
            app.TiLab.Position=[2+app.Ti.Position(1)+app.Ti.Position(3) app.TiLab.Position(2:4)];
        end

        function [] = moveTreeElement(app,quin,newp,befaf)

            node1=app.Tree.Children(quin);
            node2=app.Tree.Children(newp);
            move(node1,node2,befaf);

            aux=app.MK;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.MK{app.Tree.Children(ii).NodeData};
            end
            app.MK=aux;
            aux=app.MKp;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.MKp{app.Tree.Children(ii).NodeData};
            end
            app.MKp=aux;
            try% its possible no masks loaded
                aux=app.MKL;
                for ii=1:numel(app.Tree.Children)
                    aux{ii}=app.MKL{app.Tree.Children(ii).NodeData};
                end
                app.MKL=aux;
            end
            %
            aux=app.hdrkeep;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.hdrkeep{app.Tree.Children(ii).NodeData};
            end
            app.hdrkeep=aux;
            %
            aux=app.IIo;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.IIo{app.Tree.Children(ii).NodeData};
            end
            app.IIo=aux;
            aux=app.SSo;
            for ii=1:numel(app.Tree.Children)
                for jj=1:app.HarmLim.Value
                    aux{ii,jj}=app.SSo{app.Tree.Children(ii).NodeData,jj};
                end
            end
            app.SSo=aux;
            aux=app.GGo;
            for ii=1:numel(app.Tree.Children)
                for jj=1:app.HarmLim.Value
                    aux{ii,jj}=app.GGo{app.Tree.Children(ii).NodeData,jj};
                end
            end
            app.GGo=aux;
            %
            aux=app.IIa;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.IIa{app.Tree.Children(ii).NodeData};
            end
            app.IIa=aux;
            aux=app.SSa;
            for ii=1:numel(app.Tree.Children)
                for jj=1:app.HarmLim.Value
                    aux{ii,jj}=app.SSa{app.Tree.Children(ii).NodeData,jj};
                end
            end
            app.SSa=aux;
            aux=app.GGa;
            for ii=1:numel(app.Tree.Children)
                for jj=1:app.HarmLim.Value
                    aux{ii,jj}=app.GGa{app.Tree.Children(ii).NodeData,jj};
                end
            end
            app.GGa=aux;
            %
            aux=app.IIi;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.IIi{app.Tree.Children(ii).NodeData};
            end
            app.IIi=aux;
            aux=app.SSi;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.SSi{app.Tree.Children(ii).NodeData};
            end
            app.SSi=aux;
            aux=app.GGi;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.GGi{app.Tree.Children(ii).NodeData};
            end
            app.GGi=aux;
            %
            aux=app.currPP;
            for ii=1:numel(app.Tree.Children)
                aux(:,:,ii)=app.currPP(:,:,app.Tree.Children(ii).NodeData);
            end
            app.currPP=aux;
            %
            aux=app.dda;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.dda{app.Tree.Children(ii).NodeData};
            end
            app.dda=aux;
            aux=app.nms;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.nms{app.Tree.Children(ii).NodeData};
            end
            app.nms=aux;
            aux=app.nmsx;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.nmsx{app.Tree.Children(ii).NodeData};
            end
            app.nmsx=aux;
            aux=app.pat;
            for ii=1:numel(app.Tree.Children)
                aux{ii}=app.pat{app.Tree.Children(ii).NodeData};
            end
            app.pat=aux;
            aux=app.calibflag;
            for ii=1:numel(app.Tree.Children)
                aux(ii)=app.calibflag(app.Tree.Children(ii).NodeData);
            end
            app.calibflag=aux;
            aux=app.keepPeakCounts;
            for ii=1:numel(app.Tree.Children)
                aux(ii)=app.keepPeakCounts(app.Tree.Children(ii).NodeData);
            end
            app.keepPeakCounts=aux;

            % %
            for ii=1:numel(app.Tree.Children)
                app.Tree.Children(ii).NodeData=ii;
            end

            aux=zeros(numel(app.Tree.CheckedNodes),1);
            if(~isempty(aux))
                for ii=1:numel(app.Tree.CheckedNodes)
                    aux(ii)=app.Tree.CheckedNodes(ii).NodeData;
                end
                aux=sort(aux);

                app.Tree.CheckedNodes=[];
                for ii=aux
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;app.Tree.Children(ii)];
                end
            end


            replotII(app);

        end

        function [] = paintRois(app)
            for ii=1:size(app.roidad,1)
                if(app.roidad(ii,3)>0)
                    roi = images.roi.Circle(app.PP,'center',app.roidad(ii,1:2),'color',app.colMks(ii,:),'radius',app.roidad(ii,3));
                    roi.InteractionsAllowed='none';
                    app.curhan=[app.curhan roi];
                end
            end
        end


        function [] = plotHists(app)
            cla(app.histax);
            rang=checkedFiles(app);
            qv=[app.Imin.Value app.Imax.Value];
            hold(app.histax,'on');
            if(app.relative.Value==1)
                top=1;
            else
                top=app.Imaxrange;
            end
            % disp(['Slider limit: ' num2str(app.Islide.Limits(2))]);
            % disp(['Current max range: ' num2str(top)]);
            % instead of changing slider limits everytime we load a new dataset we simply check here
            % kinda lazy but works
            if(top~=app.Islide.Limits(2))
                app.Islide.Limits(2)=round(top);
            end
            for ii=rang
                im=app.IIa{ii};
                if(app.relative.Value==1)
                    im=im/app.keepPeakCounts(ii);
                end

                h = histcounts(im(:),linspace(0,top,101));
                if(app.Logp.Value==1)
                    h=log(1+h);
                end
                h=.86*h/max(h);
                hh=plot(app.histax,h);
                set(hh,'color',app.FGcol);

            end
            xlim(app.histax,[0 100]);ylim(app.histax,[0 1]);
            patch(app.histax,[0 0 qv(1) qv(1)]*100/top,[0 1 1 0],'k','EdgeColor','none','FaceAlpha',.2);
            patch(app.histax,[qv(2) qv(2) top top]*100/top,[0 1 1 0],'k','EdgeColor','none','FaceAlpha',.2);
            hold(app.histax,'off');
        end

        function [] = phasorUnmixing(app)
            app.running=1;
            % see what nodes are checked
            rang=checkedFiles(app);
            N=numel(rang);
            filterfactor=1;
            top=ceil((app.compNum-1)/2);
            if(top>app.HarmLim.Value)
                ensenya('Warning: Number of computed harmonics is lower than required for system of equations!','r');
                top=app.HarmLim.Value;
            end
            if(top==0),top=1;end
            % top=app.HarmLim.Value;%% this top can be set to end in app.HarmLim.Value if we want to include more equations
            if(app.compNum>0)
                if(isempty(app.folderR))
                    BrowseResultsButtonPushed(app);
                end
                d = barraprogress(app,0,[],'Unmxing');
                coefs=[];
                for hh=1:top
                    for ii=1:app.compNum
                        coefs=[coefs; app.compdad(ii,2,hh) app.compdad(ii,1,hh)];
                    end
                end
                cc=0;
                holdims=cell(0);% keep fraction ims in memory incase group by file
                name='';
                qv=[app.Imin.Value app.Imax.Value];
                minimsz=2*floor(app.Eb.Value*filterfactor/2)*(app.Ti.Value);
                for ii=rang
                    cc=cc+1;
                    if(app.exportHR==1)%
                        im=sum(app.IIo{ii},3);
                    else
                        im=app.IIa{ii};
                    end


                    im=round(1000*im)/1000;% achtung
                    if(app.relative.Value==1)
                        im=im/app.keepPeakCounts(ii);
                    end
                    im=(im-qv(1))/diff(qv);
                    im(im<0)=0;
                    if(app.Excess.Value==0)
                        im(im>1)=1;
                    else
                        im(im>1)=0;
                    end

                    for hh=1:top
                        if(app.exportHR==1)%
                            S=app.SSo{ii,hh};
                            G=app.GGo{ii,hh};
                            if(app.DoCalib.Value==1)% high res images is on originals, need to be calibrated
                                pha=atan2(S,G)+app.PD(app.calibflag(ii),hh);
                                modu=sqrt((S.*S)+(G.*G))*app.MF(app.calibflag(ii),hh);
                                S=modu.*sin(pha);G=modu.*cos(pha);
                            end
                        else
                            S=app.SSa{ii,hh};
                            G=app.GGa{ii,hh};
                        end                %%

                        %%
                        if(min(size(im))>minimsz)

                            if(app.Filterbefore.Value==0)  % filter being smooth before int thresh
                                S(im==0)=NaN;
                                G(im==0)=NaN;
                            end


                            %% % phasor filtering boooo
                            filtru=app.PhasorfilterDropDown.ValueIndex;
                            if(filtru==1)
                                [S,G] = phasorSmooth(S,G,app.Eb.Value*filterfactor,app.Ti.Value,0,[],1);
                            else
                                wn=app.WavSupp.Value;
                                if(filtru==4)
                                    wn=['db' num2str(app.Eb.Value)];
                                end
                                if(filtru==10)
                                    wn=['sym' num2str(app.Eb.Value)];
                                end
                                lev=app.Ti.Value;
                                aux=floor(log2(min([size(S)])));
                                if(lev>aux),lev=aux;app.Ti.Value=lev;end

                                if(numel(find(isnan(S)))>0)
                                    [S] = phasorFillNaN2(S);
                                    [G] = phasorFillNaN2(G);
                                end
                                if(app.AnscombeCheckBox.Value==1)
                                    S=real(ansc(S));
                                    G=real(ansc(G));
                                end
                                S=wdenoise2(S,lev,'wavelet',wn);
                                G=wdenoise2(G,lev,'wavelet',wn);
                                if(app.AnscombeCheckBox.Value==1)
                                    S=real(ansc(S,1));
                                    G=real(ansc(G,1));
                                end


                            end
                            %%
                            if(app.Filterbefore.Value==1)  % filter being smooth before int thresh
                                S(im==0)=NaN;
                                G(im==0)=NaN;
                            end
                            % crop
                            if(filtru==1)
                                [S,G] = phasorSmoothCrop(S,G,app.Eb.Value*filterfactor,app.Ti.Value);
                            end
                        end

                        if(hh==1)
                            SS=S;GG=G;
                        else
                            SS=cat(3,SS,S);GG=cat(3,GG,G);
                        end
                    end
                    if(min(size(im))>minimsz)
                        if(filtru==1)
                            [im,im] = phasorSmoothCrop(im,im,app.Eb.Value*filterfactor,app.Ti.Value);
                        end
                    end

                    if(app.Applymask.Value==1)
                        % incase we loadded a single image
                        ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                        aux=app.MKL{ind};aux(aux>1)=1;
                        out=(size(im,1)-size(aux,1))/2;
                        if(out>0)
                            aux=imresize(aux,size(im),'nearest');
                            % im=im(1+out:end-out,1+out:end-out);
                            % SS=SS(1+out:end-out,1+out:end-out,:);
                            % GG=GG(1+out:end-out,1+out:end-out,:);
                        end
                        if(out<0)
                            if(size(app.IIa{ii},1)==size(aux,1))% this would be used if mask was same size as original image but due to filtering we gotta crop some
                                out=-out;
                                aux=aux(1+out:end-out,1+out:end-out);
                            else% mask is highres and image is downsampled, we gotta downsample mask
                                aux=imresize(aux,size(im),'nearest');
                            end
                        end
                        try
                            im=im.*aux;
                            inds=find(aux==0);
                            off=numel(im);
                            for hh=1:top
                                SS(inds+off*(hh-1))=NaN;
                                GG(inds+off*(hh-1))=NaN;
                            end
                        catch me
                            ensenya(['Error: Failed while applying mask: ' me.message],'r');
                        end

                    end

                    aux=size(SS,1);
                    SS=permute(SS,[3 1 2]);
                    GG=permute(GG,[3 1 2]);
                    WW=zeros(app.compNum,aux,size(SS,3));
                    for jj=1:aux% im y
                        for kk=1:size(SS,3)%im x

                            [weights] = solveNunknowns(SS(:,jj,kk),GG(:,jj,kk),coefs);
                            WW(:,jj,kk)=weights;
                        end

                        d = barraprogress(app,(((cc-1)*aux)+jj)/aux/N,d,'Unmxing');
                    end
                    WW=permute(WW,[2 3 1]);
                    if(app.exportratiometric.Value==1)
                        try
                            q1=app.comp1.Value;
                            q2=app.comp2.Value;
                            R=WW(:,:,q1)./(WW(:,:,q1)+WW(:,:,q2));
                            if(app.ratiocsv.Value==1)
                                aux=R(:);
                                out=isnan(aux);
                                aux(out)=[];
                                aux=round(aux*10000)/10000;
                                Head=['Ratio C' num2str(q1) 'vsC' num2str(q2) ','];
                                % for jj=1:size(aux,2)
                                %         Head=[Head ]
                                % end
                                if(app.includeint.Value==1)
                                    aux2=im(:);

                                    if(app.relative.Value==1)
                                        aux2=im(:);
                                    else
                                        if(app.exportHR==0)
                                            auxim=app.IIa{ii};
                                            fora=(size(auxim)-size(im))/2;
                                            auxim=auxim(fora(1)+1:end-fora(1),fora(2)+1:end-fora(2));
                                        else
                                            auxim=app.IIo{ii};
                                        end
                                        aux2=auxim(:);
                                    end


                                    aux2(out)=[];
                                    aux=[aux2,aux];
                                    Head=['Int,' Head];
                                end

                                muntaCSV2([app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '_C' num2str(q1) '_vs_C' num2str(q2) '.csv'],',',Head,aux);

                            end
                            R(R>1)=1;
                            R(R<0)=0;
                            R=uint8(R*255);
                            imwrite300(R,[app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '_C' num2str(q1) '_vs_C' num2str(q2) '.'  app.ExportImageFileDropDown.Value]);
                        end
                    end
                    if(app.CompImsCSVExp.Value==1)
                        aux=reshape(WW,[size(WW,1)*size(WW,2) size(WW,3)]);
                        if(app.includeint.Value==1)
                            if(app.relative.Value==1)
                                aux2=im(:);
                            else
                                if(app.exportHR==0)
                                    auxim=app.IIa{ii};
                                    fora=(size(auxim)-size(im))/2;
                                    auxim=auxim(fora(1)+1:end-fora(1),fora(2)+1:end-fora(2));
                                else
                                    auxim=app.IIo{ii};
                                end
                                aux2=auxim(:);
                            end

                            aux2(isnan(aux(:,1)))=[];
                        end
                        aux(isnan(aux(:,1)),:)=[];
                        head=cell(0);for jj=1:app.compNum;head{jj}=['C' num2str(jj)];end
                        if(app.includeint.Value==1)
                            aux=[aux2,aux];
                            head(2:app.compNum+1)=head(1:app.compNum);
                            head{1}='Int';
                        end
                        aux=round(aux*10000)/10000;

                        muntaCSV2([app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '.csv'],',',head,aux);

                    end
                    if(app.CompImsExp.Value==1)
                        % rgb=zeros(size(WW,1),size(WW,2),3);
                        if(app.CompImsIntExp.Value==1)%
                            for jj=1:size(WW,3)
                                WW(:,:,jj)=WW(:,:,jj).*im;
                            end
                        end
                        if(app.CompImsCol.Value==1)
                            WW2=zeros(size(WW,1),size(WW,2),3,size(WW,3));
                            rgb=zeros(size(WW,1),size(WW,2),3);
                            for jj=1:size(WW,3)
                                aux=gray3rgb(WW(:,:,jj),superjet(1,app.colcomps(jj)));
                                rgb=rgb+aux;
                                WW2(:,:,:,jj)=aux;
                            end
                        else
                            WW2=permute(WW,[1 2 4 3]);
                        end

                        marginsize=ceil(size(WW2,1)*.01);
                        WWm=WW2;
                        if(app.addmargins.Value==1)
                            WWm=cat(1,ones(marginsize,size(WWm,2),size(WWm,3),size(WWm,4)),WWm,ones(marginsize,size(WWm,2),size(WWm,3),size(WWm,4)));
                            WWm=cat(2,ones(size(WWm,1),marginsize,size(WWm,3),size(WWm,4)),WWm,ones(size(WWm,1),marginsize,size(WWm,3),size(WWm,4)));
                        end
                        if(app.singleimage.Value==0)
                            for jj=1:app.compNum
                                imwrite300(WWm(:,:,:,jj),[app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '_C' num2str(jj) '.'  app.ExportImageFileDropDown.Value]);
                            end
                        else
                            if(app.groupfile.Value==0)
                                holdims{cc}=WWm;% keep comp ims for tiling
                            else
                                if(app.imagecols.Value*app.imagerows.Value<app.compNum)
                                    app.imagecols.Value=app.compNum;
                                    app.imagerows.Value=1;
                                end
                                cc2=0;
                                for jj=1:app.imagerows.Value
                                    for kk=1:app.imagecols.Value
                                        cc2=cc2+1;
                                        if(cc2>app.compNum)
                                            if(app.addmargins.Value==1)
                                                thim=ones(size(thim));
                                            else
                                                thim=zeros(size(thim));
                                            end
                                        else
                                            thim=WWm(:,:,:,cc2);
                                        end
                                        if(kk==1)
                                            Row=thim;
                                        else
                                            Row=cat(2,Row,thim);
                                        end
                                    end
                                    % if(cc2>numel(holdims)),if(jj==1),WWm2=Row;end;break;end
                                    if(jj==1)
                                        WWm2=Row;
                                    else
                                        if(size(WWm2,2)~=size(Row,2))
                                            Row=cat(2,Row,ones(size(Row,1),size(WWm2,2)-size(Row,2),size(Row,3)));
                                        end
                                        WWm2=cat(1,WWm2,Row);
                                    end
                                end
                                WWm2(WWm2>1)=1;
                                WWm2(WWm2<0)=0;
                                imwrite300(WWm2,[app.folderR filesep app.nms{ii} '_Unmix' app.nmsx{ii} '.'  app.ExportImageFileDropDown.Value]);

                            end
                        end
                    end
                end
                d = barraprogress(app,1,d,'Finishing');
                if(app.CompImsExp.Value==1)&&(app.groupfile.Value==0)
                    if(app.imagecols.Value*app.imagerows.Value~=numel(holdims))
                        app.imagecols.Value=numel(holdims);
                        app.imagerows.Value=1;
                    end
                    accumula=[];contacc=0;
                    % check if some images are smaller than dwnsmp val (traces likely)
                    sz=zeros(numel(holdims),2);
                    for ii=1:numel(holdims)
                        sz(ii,:)=[size(holdims{ii},1),size(holdims{ii},2)];
                    end
                    if((numel(unique(sz(:,1)))>1)||(numel(unique(sz(:,2)))>1))
                        sz=max(sz);
                        for ii=1:numel(holdims)

                            holdims{ii}=imresize(holdims{ii},sz);
                        end

                    end
                    for ii=1:app.compNum

                        for jj=1:numel(holdims)
                            aux=holdims{jj}(:,:,:,ii);
                            if(jj==1)
                                WWm=aux;
                            else
                                WWm=cat(4,WWm,aux);
                            end
                        end
                        cc2=0;Row=[];

                        for jj=1:app.imagerows.Value
                            for kk=1:app.imagecols.Value
                                cc2=cc2+1;
                                if(cc2>numel(holdims))
                                    aqu=zeros(size(WWm(:,:,:,numel(holdims))));
                                else
                                    aqu=WWm(:,:,:,cc2);
                                end
                                if(kk==1)
                                    Row=aqu;
                                else
                                    Row=cat(2,Row,aqu);
                                end
                            end
                            % if(cc2>numel(holdims)),if(jj==1),WWm2=Row;end;break;end
                            if(jj==1)
                                WWm2=Row;
                            else
                                if(size(WWm2,2)~=size(Row,2))
                                    Row=cat(2,Row,ones(size(Row,1),size(WWm2,2)-size(Row,2),size(Row,3)));
                                end
                                WWm2=cat(1,WWm2,Row);
                            end
                        end
                        if(cc2>0)
                            if(~isempty(WWm2))
                                WWm2(WWm2>1)=1;
                                WWm2(WWm2<0)=0;
                                imwrite300(WWm2,[app.folderR filesep 'Component' num2str(ii) '.'  app.ExportImageFileDropDown.Value]);
                                if(app.mergecomps.Value==1)
                                    contacc=contacc+1;
                                    if(contacc==1)
                                        accumula=WWm2;
                                    else
                                        accumula=accumula+WWm2;
                                    end
                                    if(ii==app.compNum)
                                        if(app.addmargins.Value==1)
                                            qqq=accumula;
                                            out=find(sum(qqq,3)==9);
                                            outt=[out;out+size(qqq,1)*size(qqq,2);out+2*size(qqq,1)*size(qqq,2)];
                                            qqq(outt)=[];
                                            qqq=quantile(qqq(:),.99);
                                        else
                                            qqq=quantile(accumula(:),.99);
                                        end
                                        accumula=accumula/qqq;
                                        accumula(accumula>1)=1;
                                        imwrite300(accumula,[app.folderR filesep 'CompMerge.'  app.ExportImageFileDropDown.Value]);
                                    end
                                end
                            end
                        end
                    end
                end
                clear holdims;
                try, close(d);end
            end
            app.running=0;
        end


        function [] = replotPP(app)
            app.running=1;
            PPa=app.currPP;
            IC=app.currIC;
            cla(app.PP);
            % under some wierd circumstance axes dont update ticks
            % set(app.PP,'XTickMode', 'auto', 'XTickLabelMode', 'auto','YTickMode', 'auto', 'YTickLabelMode', 'auto');
            app.PP.Toolbar.Visible='off';
            Sr = [app.Smin.Value app.Smax.Value];
            Gr = [app.Gmin.Value app.Gmax.Value];


            % see what nodes are checked
            rang=checkedFiles(app);

            if(app.logDens.Value==1)
                if~isempty(rang)
                    PPa=log(1+NormArray2(PPa));
                end
            end
            if(app.normFile.Value==1)
                for ii=rang
                    PPa(:,:,ii)=NormArray2(PPa(:,:,ii));
                end
            end

            % rangsep=rang;
            %% %% testing plotting phasors in different colormaps
            %% doesent really work bc each file contributes to a white background that turns other clouds to pastel colors
            % separate=[1];
            % for ii=1:numel(separate)
            % rangsep(rangsep==separate(ii))=[];
            % end

            im0=sum(PPa(:,:,rang),3);

            separate=find(app.emphcol(:,1)~=-1);
            % separate=[2];
            %% emphasis
            if(~isempty(separate))
                if(app.DM==0)
                    imBG=ones(size(im0,1),size(im0,2),3);
                else
                    imBG=zeros(size(im0,1),size(im0,2),3);
                end
                % imBG(:,:,1)=app.BGcol(1);
                % imBG(:,:,2)=app.BGcol(2);
                % imBG(:,:,3)=app.BGcol(3);
                im=zeros(size(im0,1),size(im0,2),3);
                ic=im;cf=1;
                for ii=rang
                    col=app.FGcol;
                    if(~isempty(intersect(separate,ii)))
                        col=app.emphcol(ii,:);
                    end
                    % aux=NormArray2(PPa(:,:,ii));
                    % aux1=cat(3,aux*col(1),aux*col(2),aux*col(3));
                    aux1=ic;aux1(:,:,1)=col(1);aux1(:,:,2)=col(2);aux1(:,:,3)=col(3);
                    % fact=PPa(:,:,ii)./im0;
                    % fact(isnan(fact))=0;%figure,imagesc(fact);
                    fact=NormArray2(PPa(:,:,ii));
                    if(cf==1)%count first
                        im=aux1.*fact;cf=0;
                    else
                        im=im+aux1.*fact;
                    end
                end
                % figure,imagesc(im);
                im0=NormArray2(im0);
                im=imBG.*(1-im0)+im.*(im0);
                % figure,imagesc(im);
            else
                im=gray2rgb(im0,superjet(255,app.PCM));
            end
            % im2=zeros(size(im));
            % for ii=1:numel(separate)
            % im2=im2+gray2rgb(NormArray2(PPa(:,:,separate(ii))),superjet(255,'wr'));
            % end
            % im2=NormArray2(im2);
            % im=(im*numel(rangsep)+im2*numel(separate))/numel(rang);
            % im=NormArray2(im);
            %%
            hold(app.PP,'on');
            fov=[Gr;Sr];
            % sz=[round(256*diff(Sr)/diff(Gr)) 256];


            xI=[fov(1,1:2);fov(1,1:2)];
            yI=[fov(2,2) fov(2,2);fov(2,1) fov(2,1)];
            zI=[0 0; 0 0];
            % facc=.3;
            % fons=facc+(1-facc)*IC;
            % fons(1,:,:)=0;
            % fons(end,:,:)=0;
            % fons(:,1,:)=0;
            % fons(:,end,:)=0;
            % if(nargin>1)
            if(app.PhBg.Value==1)
                % figure(6),imagesc(im);axis image;
                fons=IC;
                if(app.DM==0)
                    facc=.4;% attenuation factor
                    [~,alp]=white2alpha(im,.95,0);
                    fons=(1-facc)+facc*fons;
                else
                    facc=.6;% attenuation factor
                    fons=facc*fons;
                    [~,alp]=white2alpha(1-im,.95,0);
                end


                alp=repmat(alp,[1 1 3]);
                fons(alp==1)=im(alp==1);
                % figure(6),imagesc(fons);axis image;
            else
                fons=im;
            end
            if(app.contourOnly.Value==1)
                if(app.DM==0),fons(:)=1;else,fons(:)=0;end
            end
            h=surf(app.PP,xI,yI,zI,'CData',fons,'FaceColor','texturemap','EdgeColor','none');

            set(h,'hittest','off');
            levs=[0 0.001 0.003 0.008 0.019 0.042 0.093 0.206 0.454 .9];
            if(app.contourOnly.Value==0),levs=levs(1:4);end
            if(app.contours.Value==1)
                [x,y]=meshgrid(linspace(fov(1,1),fov(1,2),size(im,2)),linspace(fov(2,1),fov(2,2),size(im,1)));
                I=im0;
                I=imgaussfilt(I,size(I,1)*.01);
                I=imgaussfilt(I,size(I,1)*.01);
                I=I(end:-1:1,:);
                I=NormArray2(I);
                if(numel(unique(I(:)))>1)
                    [~,h]=contour(app.PP,x,y,I,levs,'k');
                    set(h,'hittest','off');
                end
            end
            %% %plot gm model
            if(~isempty(app.GMfit))
                if(app.elipse.Value==1)

                    fac=find(round(2*cumsum(normpdf([0:.01:5])))==app.conf.Value)*.01;
                    fac=fac(1);
                    ang=linspace(0,2*3.1416,100);
                    for ii=1:size(app.GMfit.mu,1)% for each cluster

                        co=fac*app.GMfit.Sigma(:,:,ii);
                        a=co(2,2);b=co(1,2);c=co(1,1);
                        l1=(a+c)/2+sqrt((b^2)+((a-c)/2)^2);
                        l2=(a+c)/2-sqrt((b^2)+((a-c)/2)^2);
                        if(b==0)
                            if(a>=c)
                                th=0;
                            else
                                th=3.1416;
                            end
                        else
                            th=atan2(l1-a,b);
                        end

                        x=sqrt(l1)*cos(th)*cos(ang)-sqrt(l2)*sin(th)*sin(ang)+app.GMfit.mu(ii,2);
                        y=sqrt(l1)*sin(th)*cos(ang)+sqrt(l2)*cos(th)*sin(ang)+app.GMfit.mu(ii,1);


                        h=plot(app.PP,x,y);
                        set(h,'color',app.colMks(app.GMfitcolinds(ii),:),'linewidth',3,'hittest','off');

                    end
                else
                    grid0=zeros(size(PPa,1),size(PPa,2));
                    % dospidos=(2*pi)^2;
                    [x,y]=meshgrid(linspace(fov(1,1),fov(1,2),size(PPa,2)),linspace(fov(2,1),fov(2,2),size(PPa,1)));
                    coo=[y(:),x(:)];
                    mu=app.GMfit.mu;
                    sigmas=app.GMfit.Sigma;
                    for ii=1:size(mu,1)% for each cluster
                        %                     preexp=1/sqrt(dospidos*det(sigmas(:,:,jj)));% gaussian preexponential factor
                        preexp=1;
                        if(ii==1),gridd=grid0;end
                        questo=reshape(preexp*exp(-0.5*sum(((coo-mu(ii,1:2))').*(sigmas(:,:,ii)\(coo-mu(ii,1:2))'),1))',size(PPa,1),size(PPa,2));
                        if(app.contseparate.Value==1)
                            [~,h]=contour(app.PP,x,y,questo,4,'b');
                            set(h,'linewidth',2,'EdgeColor',app.colMks(app.GMfitcolinds(ii),:),'hittest','off');
                        end
                        gridd=gridd+questo;
                    end
                    if(app.conttogether.Value==1)
                        [~,h]=contour(app.PP,x,y,gridd,levs,'k');
                        set(h,'hittest','off','linewidth',1);
                    end
                end
            end
            %% color for text and stuff
            fons=superjet(1,app.PCM(1));
            coloret=inverseColor(fons);
            %% universal circle
            if(app.UCcb.Value==1)
                angles=[];% init empty for when style has no lines
                steps=31;
                if(min(diff(fov,[],2))<.3)
                    steps=steps*2;
                end
                if(app.LifetimeStyle.Value==1)
                    angles=linspace(0,3.1416,steps);
                    off=1;
                    rad=.5;
                end
                if(app.SpectralStyle.Value==1)
                    angles=linspace(0,6.28326,steps*2);
                    off=0;
                    rad=1;
                end
                rgx=rad*(off+cos(angles'));
                inx=find((rgx>Gr(1))&(rgx<Gr(2)));
                if(~isempty(inx))
                    inx=[inx(1)-1;inx;inx(end)+1];
                end
                rgx=[rgx(1:end-1) rgx(2:end)];
                rgy=rad*sin(angles');
                iny=find((rgy>Sr(1))&(rgy<Sr(2)));
                if(~isempty(iny))
                    iny=[iny(1)-1;iny;iny(end)+1];
                end
                rgy=[rgy(1:end-1) rgy(2:end)];
                in=intersect(inx,iny);
                in(in==0)=[];
                in(in>numel(angles)-1)=[];
                rgx=rgx(in,:);
                rgy=rgy(in,:);
                for ii=1:size(rgx,1)
                    h=plot(app.PP,rgx(ii,:),rgy(ii,:));
                    set(h,'color',coloret,'hittest','off');
                end

            end
            %% ticks
            if(app.ticks.Value==1)
                app.taus=[];% init empty for when style has no lines
                if(app.LifetimeStyle.Value==1)
                    omega=2*3.1416*app.Harm.Value*app.freq;
                    if(app.tickNumber.Value==10)
                        app.taus=[.1 .5 1 2 3 5 8 13 21 34]*1e-9;
                    else
                        ph=linspace(0,3.1416,app.tickNumber.Value+2);
                        gg=sort(.5+.5*cos(ph));
                        gg(gg<1e-4)=[];
                        seq=1e9*sqrt((1./gg)-1)/omega;
                        seq=round(10*seq)/10;
                        seq=unique(seq);
                        seq(seq==Inf)=[];
                        seq(seq==0)=[];
                        app.taus=seq*1e-9;
                    end
                    gg=1./(1+(omega*app.taus).^2);
                    ss=(omega*app.taus)./(1+(omega*app.taus).^2);
                    in=find(((gg>Gr(1))&(gg<Gr(2)))&((ss>Sr(1))&(ss<Sr(2))));
                    gg=gg(in);ss=ss(in);app.taus=app.taus(in)*1e9;
                    pseudo=atan2(ss,(gg-.5));
                    desf=.5;ra=.01;ra=[.5-ra .5+ra];ra0=.5;
                    unitats='ns  ';rightleft=.5;upper=.3;lower=0;top=.461;bot=0;
                end
                if(app.SpectralStyle.Value==1)
                    desf=0;ra=.02;ra=[1-ra 1+ra];ra0=1;%ra=[0 1];
                    pseudo=linspace(0,6.2832,app.tickNumber.Value+1);
                    app.taus=round(app.SpecMin.Value+pseudo*(app.SpecMax.Value-app.SpecMin.Value)/6.2832);
                    gg=cos(pseudo);ss=sin(pseudo);
                    in=find(((gg>Gr(1))&(gg<Gr(2)))&((ss>Sr(1))&(ss<Sr(2))));
                    gg=gg(in);ss=ss(in);app.taus=app.taus(in);
                    pseudo=atan2(ss,gg);
                    unitats='nm  ';rightleft=0;upper=.6;lower=-.6;top=.96;bot=-top;
                end


                hp={'left','center','right'};vp={'top','mid','bot'};
                for ii=1:length(app.taus)
                    h=plot(app.PP,[desf+ra(1)*cos(pseudo(ii)) desf+ra(2)*cos(pseudo(ii))],[ra(1)*sin(pseudo(ii)) ra(2)*sin(pseudo(ii))]);
                    set(h,'color',coloret,'hittest','off');
                    coords=[desf+ra0*cos(pseudo(ii)) ra0*sin(pseudo(ii))];
                    hpp=1;vpp=2;exv=0;
                    % fancy positioning of text %
                    if(coords(1,1)>rightleft),hpp=3;end
                    if(coords(1,2)>upper),hpp=4-hpp;end
                    if(coords(1,2)<lower),hpp=4-hpp;end
                    if(coords(1,2)>top),hpp=2;vpp=1;end
                    if(coords(1,2)<bot),hpp=2;vpp=3;end
                    if(app.taus(ii)==app.SpecMin.Value),vpp=3;end
                    if(app.taus(ii)==app.SpecMax.Value),vpp=1;end
                    %     if(coords(1,2)>.46),vpp=1;end
                    h=text(app.PP,coords(1,1),coords(1,2),{['  ' num2str(app.taus(ii)) unitats ]});
                    %        h=text(coords(1,1),coords(1,2),{[' \tau=' num2str(taus(ii)*1e9) 'ns ' ]});
                    set(h,'color',coloret,'fontname','calibri','fontsize',app.tickfontsize.Value,'horizontalalign',hp{hpp},'verticalalign',vp{vpp},'margin',.1,'hittest','off');
                    %      set(h,'BackgroundColor',[colordefons .5]);
                end

            end
            %%
            paintRois(app);
            %%
            app.imcur=plot(app.PP,-1000,-1000,'o');
            app.imcur.MarkerSize=6;
            app.imcur.LineWidth=2;
            app.imcur.MarkerEdgeColor=coloret;
            app.imcur.MarkerFaceColor=[fons];
            % app.imcur.MarkerFaceColor=[1 1 1];
            %%
            set(app.PP,'fontsize',app.AFSef.Value);

            % if(app.IvMagStyle.Value==0)
            axis(app.PP,'equal');
            axis(app.PP,'tight');
            % set(app.PP,'BoxStyle','full','Box','on');
            % end
            app.PP.YLim=Sr;
            app.PP.XLim=Gr;
            app.curs1=images.roi.Circle(app.PP,'center',[.5 .5]-1000,'color',app.colMks(app.currCol,:),'radius',app.radi,'InteractionsAllowed','none','FaceAlpha',0);

            %%
            if(app.Harm.Value>1)
                app.coolab.Text=['harmonic #' num2str(app.Harm.Value)];
                app.coolab.FontColor='r';
            end
            %%
            if(app.ShowComponents.Value==1)
                if(app.AddLines.Value==1)
                    % sort components so lines dont intersect
                    aux=app.compdad(1:app.compNum,1:2)-mean(app.compdad(1:app.compNum,1:2));
                    phases=atan2(aux(:,2),aux(:,1));
                    [~,ord]=sort(phases);
                    ord=[ord ord];
                    for ii=1:app.compNum
                        if(ii==1),anterior=ord(app.compNum);else anterior=ord(ii-1);end
                        co1=app.compdad(ord(ii),1:2,app.Harm.Value);
                        co2=app.compdad(anterior,1:2,app.Harm.Value);
                        h=plot(app.PP,[co1(1) co2(1)],[co1(2) co2(2)],'k--','linewidth',.5);
                    end
                end
                for ii=1:app.compNum
                    roi = images.roi.Circle(app.PP,'center',app.compdad(ii,1:2,app.Harm.Value),'edgecolor','k','color',superjet(1,app.colcomps(ii)),'radius',.01,'FaceAlpha',1,'linewidth',0.1);
                    roi.InteractionsAllowed='none';
                end
            end

            %%
            hold(app.PP,'off');
            app.running=0;
        end


        function [] = replotII(app)
            app.running=1;
            if(app.LoadedIms>0)
                app.createMasksFromRois();%app.IWh
                if(isempty(app.IWh))
                    createImageFigure(app);%disp('y');
                else
                    if(ishandle(app.IWh)==0)
                        createImageFigure(app);%disp('y2');
                    end
                end
                % clf(app.IWh);
                figure(app.IWh);% bring to front
                n=app.imagerows.Value;
                m=app.imagecols.Value;
                % if(app.imagewindowtile.Value==0)%)||(m*n<app.LoadedIms)
                %     if(app.LoadedIms<17)
                %         [n,m]=squareDistrib2(app.LoadedIms);
                %     else
                %         sw=get(0,'screensize');
                %         [n,m]=squareDistrib2(app.LoadedIms,sw(4)/sw(3),1);
                %     end
                %     % end
                %     % if(app.imagerows.Value*app.imagecols.Value<app.LoadedIms)
                %     app.imagerows.Value=n;
                %     app.imagecols.Value=m;
                % end
                % % app.tilerows2.Value=n;
                %
                %
                % if(app.margns.Value==1)
                %     mg=[.01 .01 .01 .01]./[m n m n];
                % else
                %     mg=0;
                % end

                % see what nodes are checked
                rang=checkedFiles(app);
                ord=rang;
                if(app.fliprows.Value==1)
                    ord2=rang;
                    for ii=rang
                        if(mod(ceil(ii/m),2)==0)% if even row
                            ord2(ord==ii)=((ceil(ii/m)-1)*m)+(ceil(ii/m)*m)-ii+1;
                        end
                    end
                    ord=ord2;
                end
                cc=0;
                for ii=1:n*m%app.LoadedIms
                    set(app.AXh(ii),'CData',app.blankIm);
                end
                for ii=rang
                    cc=cc+1;
                    % h=subNM(n,m,ord(cc),mg,app.IWh);


                    im=app.IIi{ii};
                    im=repmat(im,[1 1 3]);

                    mk=app.MK{ii};

                    if(app.SoftCursor.Value==0)% simFCS style only masks
                        out=find(((mk(:,:,1)==1)&(mk(:,:,2)==1))&(mk(:,:,3)==1));
                        mk(out)=im(out);
                        df=numel(mk)/3;
                        mk(out+df)=im(out+df);
                        mk(out+df+df)=im(out+df+df);
                        im=mk;
                    else% intensity in bw with mask overlay
                        try
                            for jj=1:3
                                im(:,:,jj)=im(:,:,jj).*mk(:,:,jj);
                            end
                        catch
                            gg=0;
                        end
                    end
                    if(app.Applymask.Value==1)
                        % incase we loaded a single image
                        ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                        aux=app.MKL{ind};aux(aux>1)=1;aux(aux<0)=0;
                        out=(size(im,1)-size(aux,1))/2;
                        if(out>0)
                            aux=imresize(aux,[size(im,1) size(im,2)],'nearest');
                        end
                        if(out<0)
                            if(size(app.IIa{ii},1)==size(aux,1))% this would be used if mask was same size as original image but due to filtering we gotta crop some
                                out=-out;
                                aux=aux(1+out:end-out,1+out:end-out);
                            else% mask is highres and image is downsampled, we gotta downsample mask
                                aux=imresize(aux,[size(im,1) size(im,2)],'nearest');
                            end
                        end

                        try
                            % stripes=imresize(app.MKoutim,size(aux));
                            % stripes=.5+.5*repmat(stripes,[1 1 3]);
                            aux=repmat(aux,[1 1 3]);
                            % im(aux==0)=stripes(aux==0);
                            im(aux==0)=0;
                        end

                    end

                    % tic
                    % imshow(im,'parent',app.AXh(ii));
                    % axes(app.AXh(ord(cc)));
                    set(app.AXh(ord(cc)),'CData',im);
                    % imagesc(im);
                    %
                    %   axis(h,'image');%colormap(parula);
                    % toc
                    set(get(app.AXh(ord(cc)),'parent'),'UserData',ii);
                    % set(gca,'xtick',[],'ytick',[],'UserData',ii);
                    % h.Toolbar.Visible='off';%
                    % if(app.margns.Value==0)
                    %     h.Box='off';
                    %     h.XColor=[0 0 0 0];
                    %     h.YColor=[0 0 0 0];
                    % end
                end
                % gg=0;
                %             % Create invbut
                % app.Inv = uicontrol(6868,'style','push');
                % % app.Inv.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
                % app.Inv.Tooltip = {'Invert mask'};
                % app.Inv.Position = [2 2 24 24];
                % app.Inv.String = ' ';
                % app.Inv.CData = imread('inverse.png');

            end
            app.running=0;
        end


        function [] = setcolors(app,coloret)
            fac=.9-2*app.DM/10;
            % default colors
            fg=[0.06,0.06,0.06];
            bg=[0.94,0.94,0.94];
            % set current
            if(nargin==2)
                app.currcol=17;
                app.ALLcols(app.currcol,:)=coloret;
            end
            app.FGcol=fg.*(fac+(1-fac)*app.ALLcols(app.currcol,:));
            app.BGcol=bg.*(fac+(1-fac)*app.ALLcols(app.currcol,:));


            if(app.DM==1)
                aux=app.BGcol;
                app.BGcol=app.FGcol;
                app.FGcol=aux;
                app.BGcol=3*app.BGcol;
                app.BGcol2=.8*app.BGcol;
                app.BGcol3=app.BGcol+(app.FGcol-app.BGcol)*.2;
                try
                    % disp('y')
                    app.GSLabUIFigure.Theme = 'dark';
                    % disp('yy')
                end
            else
                app.BGcol2=.8*app.BGcol;
                app.BGcol3=[1 1 1];
                try
                    app.GSLabUIFigure.Theme = 'light';
                end
            end


            app.FGcol(app.FGcol>1)=1;app.FGcol(app.FGcol<0)=0;
            app.BGcol(app.BGcol>1)=1;app.BGcol(app.BGcol<0)=0;
            app.BGcol2(app.BGcol2>1)=1;app.BGcol2(app.BGcol2<0)=0;
            app.BGcol3(app.BGcol3>1)=1;app.BGcol3(app.BGcol3<0)=0;

            aux=fieldnames(app.findobj);
            for ii=1:numel(aux)
                if((~strcmp(aux{ii},'Line1'))&&(~strcmp(aux{ii},'Line2')))
                    eval(['h=app.' aux{ii} ';']);
                    try
                        set(h,'Color',app.BGcol);
                    end
                    if(strcmp(class(h),'matlab.ui.control.NumericEditField'))
                        set(h,'BackgroundColor',app.BGcol3);
                    else
                        try
                            set(h,'BackgroundColor',app.BGcol);
                        end
                    end
                    try
                        if(strcmp(class(h),'matlab.ui.container.Menu'))% workaround while contextmenu background is not a propery
                            if(app.DM==1)
                                set(h,'ForegroundColor',app.BGcol);
                            end
                        else
                            set(h,'ForegroundColor',app.FGcol);
                        end
                    end
                    try
                        set(h,'FontColor',app.FGcol);
                    end
                end
            end
            tst=uistyle('FontColor',app.FGcol);
            for ii=1:numel(app.Tree.Children)
                addStyle(app.Tree,tst,'node',app.Tree.Children(ii));
            end
            set(app.PP,'XColor',app.FGcol,'YColor',app.FGcol);
            app.Tree.BackgroundColor=app.BGcol3;
            app.histax.Color=app.BGcol3;
            app.MKax.Color=app.BGcol3;
            % app.ContextMenu.Children(1).Label='<html><b><i>test</b></i></html>';

            app.coolab.BackgroundColor='none';
            if(1)%keep cols
                app.X.FontColor=[1.00,0.00,0.00];
                app.PlotTrueSize.FontColor=[0.00,0.00,1.00];
                app.ComputeLR.FontColor=[0.00,0.00,1.00];
                app.ComputeHR.FontColor=[0.00,0.00,1.00];
                app.BrowseResults.FontColor=[0.47,0.67,0.19];
                app.ExportHRButton.FontColor=[0.00,0.00,1.00];
                app.ExportLRButton.FontColor=[0.00,0.00,1.00];
                app.ExpPhWinButton.FontColor=[0.31,0.57,0.97];
                app.ExpImWinButton.FontColor=[0.31,0.57,0.97];
                app.ImWinClipButton.FontColor=[0.17,0.76,1.00];
                app.PhWinClipButton.FontColor=[0.17,0.76,1.00];
            end
            for ii=1:numel(app.TabEixos.Children)
                if(strcmp(class(app.TabEixos.Children(ii)),'matlab.graphics.primitive.Rectangle'))
                    if(app.TabEixos.Children(ii).UserData==1)
                        app.TabEixos.Children(ii).FaceColor=app.BGcol;
                    else
                        app.TabEixos.Children(ii).FaceColor=app.BGcol2;
                    end
                end
                if(strcmp(class(app.TabEixos.Children(ii)),'matlab.graphics.primitive.Text'))
                    app.TabEixos.Children(ii).Color=app.FGcol;
                end
                if(strcmp(class(app.TabEixos.Children(ii)),'matlab.graphics.chart.primitive.Line'))
                    app.TabEixos.Children(ii).Color=[.5 .5 .5];
                end
            end
            if(app.DM==1),app.PCM(1)='0';else ,app.PCM(1)='w';end
            app.CLabel.BackgroundColor='none';
            app.vlabel.BackgroundColor='none';


            app.blankIm=permute(app.BGcol,[1 3 2]);
            if(ishandle(app.IWh))
                app.IWh.Color=app.BGcol;
                rang=checkedFiles(app);
                rang=setdiff([1:app.LoadedIms],rang);
                for ii=rang
                    set(app.AXh(ii),'CData', app.blankIm);
                end

            end
            if(ishandle(app.PDh))
                app.PDh.Color=app.BGcol;
                % assume 1 is legend, 2 is axes
                try
                    app.PDh.Children(1).Color=app.BGcol3;
                    app.PDh.Children(1).TextColor=app.FGcol;
                    app.PDh.Children(1).EdgeColor=app.FGcol;
                    app.PDh.Children(2).Color=app.BGcol3;
                    app.PDh.Children(2).BackgroundColor=app.BGcol;
                    app.PDh.Children(2).YColor=app.FGcol;
                    app.PDh.Children(2).XColor=app.FGcol;
                    app.PDh.Children(2).Children.EdgeColor=app.FGcol;
                end
                % for ii=1:numel(app.PDh)
                %     try,app.PDh.Children(ii).Color=app.BGcol3;end
                %     try,app.PDh.Children(ii).TextColor=app.FGcol;end
                %     try,app.PDh.Children(ii).BackgroundColor=app.BGcol;end
                % end
            end
            plotHists(app);
            replotPP(app);

        end


        function [] = singleColPush(app,~,~,q)
            h=app.singhans(q);
            pos=app.GSLabUIFigure.Position;
            tam=[200 200];
            % pos=pos(1:2)+(pos(3:4)-tam)/2;
            % pos(2)=pos(2)+sum(app.ComponentsPanel.Position([2,4]));
            pos(1)=pos(1)-tam(1);
            pos(3:4)=tam;
            try
                nou=colorPicker2(1,pos);
                app.single(q)=nou;
                nou=superjet(1,nou);
                set(h,'BackgroundColor',nou);
            end
            if(q==2)
                CmapMagDDValueChanged(app);
            else
                CmapPhdensDDValueChanged(app);
            end

        end

        function [] = triggercallbacks(app)
            AxesValueChanged(app);
            CalibrationtypeSelectionChanged(app);
            CombineValueChanged(app);
            CompImsExpValueChanged(app);
            CompImsCSVExpValueChanged(app);
            CmapPhdensDDValueChanged(app);
            CmapMagDDValueChanged(app);
            DoCalibValueChanged(app);
            FilterDropDownValueChanged(app);
            FilterWavSuppValueChanged(app);
            GMMexportValueChanged(app);
            GroupfileValueChanged(app);
            ExportPhasorFileDropDownValueChanged(app);
            ExportratiometricValueChanged(app);
            imagewindowtileValueChanged(app);
            MagnitudeButtonGroupSelectionChanged(app);
            setcolors(app);
            SourceButtonGroupSelectionChanged(app);
            singleimageValueChanged(app);
            StyleSelectionChanged(app);
            PromptFLIMlabsloadoptionCheckBoxValueChanged(app);
            plotHists(app);


        end

        function [] = tabselector(app,~,~,qq)

            if(app.CalibHolder.Visible==1)
                hidecalibButtonPushed(app);
            end
            app.TabGroup.SelectedTab=app.TabGroup.Children(qq);
            cc=0;

            for ii=numel(app.TabEixos.Children):-1:1
                if(strcmp(class(app.TabEixos.Children(ii)),'matlab.graphics.primitive.Rectangle'))
                    app.TabEixos.Children(ii).UserData=0;
                    cc=cc+1;
                    if(cc==qq)
                        app.TabEixos.Children(ii).FaceColor=app.BGcol;
                        app.TabEixos.Children(ii).UserData=1;
                    else
                        app.TabEixos.Children(ii).FaceColor=app.BGcol2;
                    end
                end
            end
            if(qq==1)
                app.hidecalib.Visible=1;
            else
                app.hidecalib.Visible=0;
            end
        end
        function updateGSLab(app)
            if(~strcmp(getenv('COMPUTERNAME'),'ALEX_PCw'))% never update in home machine
                try
                    ruta='https://alexvallmitjana.github.io/GSLab/';
                    local=which('GSLab.mlapp');
                    websave([local(1:end-11) 'scripts/updateGSLab2.m'],[ruta 'scripts/updateGSLab2.m']);
                    websave([local(1:end-11) 'scripts/requiredFiles.m'],[ruta 'scripts/requiredFiles.m']);
                    pause(1);% leave time to download files
                    aux=app.GSLabUIFigure.Position;
                    delete(app);
                    updateGSLab2(aux);
                catch
                    ensenya(['Update failed.'],'r');
                    disp(['          <a href="https://github.com/AlexVallmitjana/GSLab/archive/refs/heads/main.zip">Please download package</a>']);
                    ensenya('Unzip and overwrite contents in local folder:');
                    ensenya([local(1:end-11)]);
                end
            end
        end
        function updateTh(app)
            for ii=1:app.LoadedIms
                app.hdrkeep{ii}=[];
            end
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
            plotHists(app);
        end
        function [vers] = versioncurrent(app)
            try
                G=fopen('GSLab_Logs.md','rt');
                cont=(fread(G,inf,'uchar'))';% l
                fclose(G);
                vers=char(cont(find(cont==91,1)+1:find(cont==93,1)-1));
            catch
                vers='fail';
            end
        end
        function [vers] = versionweb(app)
            vers=webread("https://alexvallmitjana.github.io/GSLab/scripts/GSLab_Logs.md");
            vers=vers(2:7);
        end

        function [] = saveParams(app,ruta)
            if(nargin<2),ruta=app.folderS;end
            po=app.GSLabUIFigure.Position;
            p01=po;
            if(ishandle(app.IWh))
                app.figsz=get(app.IWh,'Position');
                p02=app.figsz(1:2);
            else
                p02=[0 0];
            end
            p03=app.Eb.Value;
            p04=app.Ti.Value;
            p05=app.Imin.Value;
            p06=app.Imax.Value;
            p07=app.Smin.Value;
            p08=app.Smax.Value;
            p09=app.Gmin.Value;
            p10=app.Gmax.Value;
            p11=app.Cmin.Value;
            p12=app.Cmax.Value;
            p13=app.folderC;
            p14=app.folderD;
            p15=app.LT.Value;
            p16=app.DoCalib.Value;
            p17=app.Cbar.Value;
            p18=app.Harm.Value;
            p19=app.HarmLim.Value;
            p20=app.Filterbefore.Value;
            p21=app.phaS.Value;
            p22=app.modS.Value;
            p23=app.PhasorfilterDropDown.ValueIndex;
            p24=app.WavSupp.ValueIndex;
            p25=app.filtinds;
            p26=app.Gcenter.Value;
            p27=app.Scenter.Value;
            p28=app.WaveletFamilyDropDown.ValueIndex;
            p29=app.decaywinpos;

            r01=app.folderR;
            r02=app.ExDecay.Value;
            r03=app.DeacayFileDropDown.Value;
            r04=app.Combine.Value;
            r05=app.ExPhasor.Value;
            r07=app.ExInt.Value;
            r06=app.ExMagH.Value;
            r08=app.PhBg.Value;
            r09=app.ExMagS.Value;
            r10=app.PhRb.Value;
            r11=app.blend.Value;
            r12=app.PhRb.Value;
            r13=app.ExCurH.Value;
            r14=app.ExCurS.Value;
            r15=app.Expptrans.Value;
            r16=app.exportvec;
            r17=app.axes.Value;
            r18=app.ExportPhasorFileDropDown.Value;
            r19=app.ExportImageFileDropDown.Value;
            r20=app.groupfiles.Value;
            r21=app.LabelCoords.Value;
            r22=app.LabelFmt.Value;

            m01=app.Srad.Value;
            m02=app.Grad.Value;
            m03=app.Phaserad.Value;
            m04=app.TauPhaserad.Value;
            m05=app.Modrad.Value;
            m06=app.TauModrad.Value;
            m07=app.NormPhaserad.Value;
            m08=app.TauNormrad.Value;
            m09=app.CustomRadial.Value;
            m10=app.CustomAngular.Value;

            v01=app.AFSef.Value;
            v02=app.UCcb.Value;
            v03=app.ticks.Value;
            v04=app.tickfontsize.Value;
            if(app.LifetimeStyle.Value==1),v05=0;end
            if(app.SpectralStyle.Value==1),v05=1;end
            if(app.IvMagStyle.Value==1),v05=2;end
            if(app.UmapStyle.Value==1),v05=3;end
            v06=app.CCM;
            v07=app.freqEF.Value*1e6;
            v08=app.PhasorcolormapDD.ValueIndex;
            v09=app.RainbowcolormapDD.ValueIndex;
            v10=app.SoftCursor.Value;
            v11=app.logDens.Value;
            v12=app.normFile.Value;
            v13=app.contours.Value;
            v14=app.margns.Value;
            v15=app.imagewindowtile.Value;
            v16=app.imagecols.Value;
            v17=app.imagerows.Value;
            v18=app.SpecMin.Value;
            v19=app.SpecMax.Value;
            v20=app.tickNumber.Value;
            v21=app.contourOnly.Value;
            v22=app.NormDecays.Value;
            v23=app.LogDecays.Value;

            c01=app.complif;
            c02=app.colcomps;
            c03=app.ShowComponents.Value;
            c04=app.AddLines.Value;
            c05=app.CompImsExp.Value;
            c06=app.CompImsIntExp.Value;
            c07=app.CompImsCSVExp.Value;
            c08=app.CompImsCol.Value;
            c09=app.exportratiometric.Value;
            c10=app.comp1.Value;
            c11=app.comp2.Value;
            c12=app.ratiocsv.Value;
            c13=app.singleimage.Value;
            c14=app.currcol;
            c15=app.DM;
            c16=app.addmargins.Value;
            c17=app.includeint.Value;
            c18=app.groupfile.Value;
            c19=app.mergecomps.Value;
            c20=app.ALLcols(17,:);

            s01=app.NormColunits.Value;
            s02=app.CoordPtile.Value;
            s03=app.AddFolders.Value;
            s04=app.ColPtile.Value;
            s06=app.IntPtile.Value;
            s07=app.IntDispTop.Value;
            s08=app.PromptFLIMlabsloadoptionCheckBox.Value;
            s09=app.LoadFLIMlabsrawCheckBox.Value;
            s10=app.LoadFLAME31nBwithBCheckBox.Value;
            s11=app.autotab.Value;

            if(app.conttogether.Value==1),u01=0;end
            if(app.contseparate.Value==1),u01=1;end
            if(app.elipse.Value==1),u01=2;end
            if(app.hard.Value==1),u02=0;end
            if(app.prob.Value==1),u02=1;end
            u03=app.conf.Value;
            u04=app.blend.Value;
            u05=app.blendamount.Value;
            u06=app.gmmexport.Value;

            s00=app.ExportType.Value;

            if(app.AddFolders.Value==1)
                gg=cell(0);
                for ji=1:numel(app.nms)
                    gg{ji}=app.nms{ji}(find(app.nms{ji}==filesep)+1:end);
                end
                files=gg;
            else
                files=app.nms;
            end
            paths=app.pat;
            masks=[];
            if(nargin==2)% only if we are saving session keep masks
                masks=app.MKL;
            end

            save(ruta,'files','paths','masks','p01','p02','p03','p04','p05','p06','p07','p08','p09','p10','p11','p12','p13','p14','p15','p16','p17','p18','p19','p20','p21','p22','p23','p24','p25','p26','p27','p28','p29','r01','r02','r03','r04','r05','r06','r07','r08','r09','r10','r11','r12','r13','r14','r15','r16','r17','r18','r19','r20','r21','r22','m01','m02','m03','m04','m05','m06','m07','m08','m09','m10','v01','v02','v03','v04','v05','v06','v07','v08','v09','v10','v11','v12','v13','v14','v15','v16','v17','v18','v19','v20','v21','v22','v23','c01','c02','c03','c04','c05','c06','c07','c08','c09','c10','c11','c12','c13','c14','c15','c16','c17','c18','c19','c20','s00','u01','u02','u03','u04','u05','u06','s01','s02','s03','s04','s06','s07','s08','s09','s10','s11');

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % addpath('scripts');

            app.vlabel.FontSize=7;
            ruta='params3.mat';
            try% attempt to save user settings file in matlab folder
                if(~isempty(getenv('USERPROFILE')))% windows machine
                    rut=[getenv('USERPROFILE') filesep 'Documents' filesep 'MATLAB'];
                    if(exist(rut,'dir')~=7)
                        mkdir(rut);
                    end
                    ruta=[rut filesep 'params3.mat'];
                end
                if(~isempty(getenv('HOME')))% mac machine
                    rut=[getenv('HOME') filesep 'Documents' filesep 'MATLAB'];
                    if(exist(rut,'dir')~=7)
                        mkdir(rut);
                    end
                    ruta=[rut filesep 'params3.mat'];
                end
            end

            app.folderS=ruta;
            app.refpos=app.Eb.Position;% keep original pos
            app.ALLcols=superjet(32,'wvAbZceglyoarFmpwvAbZceglyoarFmp');
            app.CalibHolder.Position=[app.TabGroup.Position(1:2)+app.Tree.Position(1:2) app.Tree.Position(3) app.CalibHolder.Position(4)];% achtung fixed pos, not changing with design mode!
            app.CalibrationPanel.Position(3:4)=app.CalibHolder.Position(3:4)-[6 29];
            app.CalibrationPanel.Position(1:2)=[3 3];
            % app.FiLab.Position=[1 1 236 app.CalibrationPanel.Position(4)-2];
            app.calTable.Position=[1 1 app.CalibrationPanel.Position(3)-2 app.CalibrationPanel.Position(4)-2];
            app.manualcalibpanel.Position=app.calTable.Position;
            app.manualcalibpanel.Title='';
            app.coolab.Interpreter='latex';


            %% load user settings file
            warning('off','MATLAB:load:variableNotFound');
            applyUserSettings(app);


            %% only in my machine
            if(~strcmp(getenv('COMPUTERNAME'),'ALEX_PC'))% else anscombe transform stay forced
                app.AnscombeCheckBox.Visible=0;% wavelet anscombe filter checkbox only for me
            else
                requiredFilesGenerate();% generate file list for updating
            end
            try
                app.ReLab.Text=app.folderR(find(app.folderR==filesep,1,'last')+1:end);
            end
            %% make component Tree window
            aux=app.ComponentsPanel.Position;
            app.ComponentTree.Position=[aux(1:2) aux(3) aux(4)-19];
            app.ComponentTree.Visible=0;

            %% prepare tab overlay to have better control
            ref=app.TabGroup.Position;
            th=25;% tab height
            app.AxisContainer.Position=[ref(1) ref(2)+ref(4)-th-1 ref(3) th+1];
            app.TabEixos.Position=[0 0 app.AxisContainer.Position(3) app.AxisContainer.Position(4)];
            app.TabEixos.OuterPosition=app.TabEixos.Position;
            app.TabEixos.InnerPosition=app.TabEixos.Position;
            app.TabEixos.XAxisLocation='top';
            nn=numel(app.TabGroup.Children);
            chars=zeros(nn,1);
            for ii=1:nn
                chars(ii)=numel(app.TabGroup.Children(ii).Title);
            end
            % chars(chars<3)=3;
            chars=chars+2;
            for ii=1:nn
                pp=[sum(chars(1:ii-1))*ref(3)/sum(chars) 0 chars(ii)*ref(3)/sum(chars) th];
                if(ii==1)
                    col= [0.94,0.94,0.94];
                else
                    col=.8*[0.94,0.94,0.94];
                end
                ud=0;if(ii==1),ud=1;end
                app.tabpos(ii)=pp(1);
                h = rectangle(app.TabEixos,'Position',pp,'EdgeColorMode','manual','UserData',ud, ...
                    'FaceColor',col, 'EdgeColor', 'none','LineWidth',.001,'ButtonDownFcn',{@app.tabselector,ii});
                aux=app.TabGroup.Children(ii).Title;
                app.TabGroup.Children(ii).UserData=ii;
                if(strcmp(aux,'S')),aux='⚙';fw='bold';else fw='normal';end
                t = text(app.TabEixos,pp(1)+pp(3)/2,pp(2)+pp(4)/2,aux);
                set(t,'verticalalignment','middle','HorizontalAlignment','center','FontSize',12,'fontweight',fw,'ButtonDownFcn',{@app.tabselector,ii});
            end
            hold(app.TabEixos,'on');
            h=plot(app.TabEixos,[1 ref(3)],[th th]);set(h,'color','k','linewidth',1,'UserData',0);
            h=plot(app.TabEixos,[1 1],[0 th]);set(h,'color','k','linewidth',1,'UserData',0);
            h=plot(app.TabEixos,[ref(3) ref(3)],[0 th]);set(h,'color','k','linewidth',1,'UserData',1);
            app.TabEixos.XLim=[0 ref(3)];
            app.TabEixos.YLim=[0 th];


            %% set axes sizes (histogram, masks)
            app.histax.InnerPosition([1,3])=app.Islide.Position([1,3]);
            app.MKax.InnerPosition(3)=app.MKax.InnerPosition(4);
            axis(app.MKax,'image');
            set(app.MKax,'Xcolor',[.94 .94 .94],'Ycolor',[.94 .94 .94]);
            app.CalibHolder.Visible=0;

            app.Harm.Limits(2)=app.HarmLim.Value;
            app.preval=app.HarmLim.Value;
            % prepare mask skin for display
            % [x,y]=meshgrid([1:app.dwnsmp],[1:app.dwnsmp]);
            % app.MKoutim=sin(6.28*20*(x+y*.7)/app.dwnsmp)>0;
            % figure(44);imagesc(app.MKoutim>0);

            app.colMks=superjet(numel(app.CCM),app.CCM);% cursor colors
            app.colMks=[app.colMks;0 0 0];% add black to assign gmm nans
            app.roidad=zeros(numel(app.CCM),3);% cursor data
            %% generate cursor color buttons
            ref=app.Panel.Position;
            sep=3;
            tam=round([(ref(3)-sep*(1+numel(app.CCM)))/numel(app.CCM) (ref(4)-2*sep)/2]);
            tam=tam(1);
            tam=12;
            % if(tam(2)>tam(1)),tam(2)=tam(1);end
            for ii=1:numel(app.CCM)
                h = uibutton(app.Panel);
                h.Position = [(sep+tam)*(ii-1)+sep 1 tam tam];
                h.Text = '';
                h.BackgroundColor= app.colMks(ii,:);
                h.Tooltip=['Cursor ' num2str(ii)];
                h.ButtonPushedFcn = {@app.CursorColPush, ii};
                app.curcolbuthan(ii)=h;
            end
            %% generate component color buttons
            ref=app.plusB.Position;

            for ii=1:8% % component color button
                h = uibutton(app.UnmixTab);
                h.Position = [ref(1) round(ref(2)+6.9-(ii-1)*(20.5714)) tam tam];
                h.Text = '';
                h.BackgroundColor= superjet(1,app.colcomps(ii));
                h.Tooltip=['Component' num2str(ii) ' color'];
                h.ButtonPushedFcn = {@app.ComponentColPush, ii};
                h.Visible=0;
                app.comphans(ii,2)=h;
            end
            %% prepare colormap drop down density
            [llista] = cmaps();
            llista{end+1,1}='Single Color';
            llista{end,2}='dummy';
            for ii=1:size(llista,1)% phasor density
                nom=llista{ii,1};
                if(numel(unique(llista{ii,2}))==1),nom=[nom ' (single color)'];end
                app.PhasorcolormapDD.Items{ii}=nom;
                aux=llista{ii,2};if(aux(1)~='w'),aux=['w' aux];end% make first color white to add transparency in alpha
                app.PhasorcolormapDD.ItemsData{ii}=aux;
            end
            ref=app.PhasorcolormapDD.Position;
            h = uibutton(app.STab);
            h.Position = [ref(1)+ref(3)+3 ref(2)+3 tam tam];
            h.Text = '';
            h.BackgroundColor= superjet(1,app.single(1));
            h.Tooltip=['Single Color'];
            h.ButtonPushedFcn = {@app.singleColPush, 1};
            app.singhans(1)=h;
            %% prepare colormap drop down magnitude
            for ii=1:size(llista,1)% background color
                nom=llista{ii,1};
                if(numel(unique(llista{ii,2}))==1),nom=[nom ' (single color)'];end
                app.RainbowcolormapDD.Items{ii}=nom;
                aux=llista{ii,2};
                aux(aux=='k')=[];% remove black
                if(aux(1)~='w'),aux=['w' aux];end% make smooth transitions to white
                if(aux(end)~='w'),aux=[aux 'w'];end% make smooth transitions to white
                app.RainbowcolormapDD.ItemsData{ii}=aux;
            end
            ref=app.RainbowcolormapDD.Position;
            h = uibutton(app.STab);
            h.Position = [ref(1)+ref(3)+3 ref(2)+3 tam tam];
            h.Text = '';
            h.BackgroundColor= superjet(1,app.single(2));
            h.Tooltip=['Single Color'];
            h.ButtonPushedFcn = {@app.singleColPush, 2};
            app.singhans(2)=h;
            try
                load(app.folderS,'v08','v09');
                app.PhasorcolormapDD.ValueIndex=v08;
                app.RainbowcolormapDD.ValueIndex=v09;

            end
            %% prepare filter dropdown
            [aux1,app.filtnams]=FiltNames();
            app.filtinds=ones(numel(aux1)+2,2);% we keep track of parameters associated to each filter type
            % (filtind 1 is median, 2 gaussian 3 onwards each wavelet family)
            app.WaveletFamilyDropDown.Items=aux1;
            try
                gg=cwt(sin(1:.01:10));% dummy computation using wavelet toolbox, should fail if not installed


                app.filtinds(1,1)=3;app.filtinds(12,1)=2;
                app.filtinds(3:end,2)=4;
                try
                    load(app.folderS,'p23','p25','p28');
                    app.WaveletFamilyDropDown.ValueIndex=p28;
                    FiltWaveletFamilyDropDownValueChanged(app);
                    app.filtinds=p25;
                    app.Ti.Value=app.filtinds(p28+2,2);
                    app.PhasorfilterDropDown.ValueIndex=p23;
                    if(p28==3)||(p28==9)
                        app.Eb.Value=app.filtinds(p28+2,1);
                    else
                        app.WavSupp.ValueIndex=app.filtinds(p28+2,1);
                    end
                catch
                    % ensenya('messup','r');
                end
            catch
                ensenya('Install wavelet toolbox to allow more filters!');
                app.PhasorfilterDropDown.Items(3)=[];
                app.WaveletFamilyDropDown.Visible=0;
                app.WavSupp.Visible=0;
                if(app.PhasorfilterDropDown.ValueIndex==3)
                    app.PhasorfilterDropDown.ValueIndex=1;
                end
            end
            %% table for calibration
            % app.calTable.Visible=0;
            app.calTable.Visible=1;
            app.calTable.ColumnName=[];
            aux=cell(0);aux{3,3}=' ';aux{1,3}='No Calibration selected';%aux{1,4}='';
            app.calTable.Data=aux;
            app.calTable.ColumnWidth={35,33,'auto'};%,22};
            s1 = uistyle;
            s1.HorizontalAlignment='center';
            addStyle(app.calTable,s1,'column',2);
            s1.HorizontalAlignment='right';
            addStyle(app.calTable,s1,'column',1);
            s1.HorizontalAlignment='left';
            addStyle(app.calTable,s1,'column',3);

            %% handmade progress bar

            app.barra.Position=[app.AxisContainer.Position(1) app.AxisContainer.Position(2)+app.AxisContainer.Position(4)-1 app.AxisContainer.Position(3) 8];
            app.barra.ImageSource=.5*ones(1,1,3);
            app.barra.BackgroundColor='none';
            app.barra.ScaleMethod='stretch';
            app.barra.Visible=0;
            %% call functions to set stuff
            triggercallbacks(app);
            app.ExportType.Value=1;

            % % overlay spectral band on laser freq
            % aux1=app.SpecMin.Position(1)-app.sBandLab.Position(1);
            % aux2=app.SpecMax.Position(1)-app.sBandLab.Position(1);
            % app.sBandLab.Position(1:2)=app.freqLab.Position(1:2);
            % app.SpecMin.Position(1)=app.sBandLab.Position(1)+aux1;
            % app.SpecMax.Position(1)=app.sBandLab.Position(1)+aux2;
            % app.SpecMin.Position(2)=app.freqEF.Position(2);
            % app.SpecMax.Position(2)=app.freqEF.Position(2);

            %% ready


            %% user may have accessibility settings to make text big
            pause(2);
            aux=app.GSLabUIFigure.Position;
            if(aux(3)<app.PhasorPanel.Position(1)+app.PhasorPanel.Position(3)+2)||(aux(4)<app.PhasorPanel.Position(2)+app.PhasorPanel.Position(4))
                % disp('###')
                % disp('current:')
                % disp(aux);
                aux(3)=app.PhasorPanel.Position(1)+app.PhasorPanel.Position(3)+2;
                aux(4)=app.PhasorPanel.Position(2)+app.PhasorPanel.Position(4);
                app.GSLabUIFigure.Position=aux;
                % disp('target:')
                % disp(app.GSLabUIFigure.Position);
                pause(1)
                % disp('actual:')
                % disp(app.GSLabUIFigure.Position);
                auxf=aux(3:4)./app.GSLabUIFigure.Position(3:4);
                aux(3:4)=ceil(aux(3:4).*auxf);
                % disp('new target:')
                % disp(aux);
                app.GSLabUIFigure.Position=aux;
                % pause(1)
                % disp('final:')
                % disp(app.GSLabUIFigure.Position);
            end
            aux=app.GSLabUIFigure.Position;
            if(aux(3)<app.PhasorPanel.Position(1)+app.PhasorPanel.Position(3)+2)||(aux(4)<app.PhasorPanel.Position(2)+app.PhasorPanel.Position(4))
                % disp('bad');
                uialert(app.GSLabUIFigure,{'Operating system changes text and window dimensions.','Please manually adjust the window size.',' ', 'This is a known issue, either use MATLAB version prior to 2025 or go to Windows-Settings-Accessibility','Set TextSizeScrollbar at 100% (leftmost), Apply'},'Warning','icon','Warning');
                % else
                %     disp('good');
            end
            % get version
            vers1=versioncurrent(app);
            try
                vers2=versionweb(app);
                if(~strcmp(vers1,vers2))
                    app.UpdateButton.Enable=1;
                    app.UpdateButton.Text=vers2;
                    app.UpdateButton.FontColor=[.6 0 0];
                    app.UpdateButton.Tooltip='Download new version';
                else
                    app.UpdateButton.Enable=0;
                    app.UpdateButton.Tooltip='Version is latest';
                end
            end
            app.vlabel.Text=vers1;
            if isdeployed
                app.UpdateButton.Enable=0;
                app.UpdateButton.Tooltip='Auto updates not available for compiled version';
            end

            ensenya(['GSLab ' vers1 '.'],'b');
            % app.GSLabUIFigure.Name=['GSLab ' vers];% display version in title bar
        end

        % Value changed function: AnscombeCheckBox
        function AnscombeCheckBoxValueChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Value changed function: Applymask
        function ApplymaskValueChanged(app, event)
            if(numel(app.MKL)>0)
                FilterAndPlotPhasor(app);
            end
        end

        % Button pushed function: AutoButton_2
        function AutoSrange(app, event)
            rang=checkedFiles(app);
            qv=[(1-app.CoordPtile.Value)/2 (1+app.CoordPtile.Value)/2];
            limits=[Inf,-Inf];
            for ii=rang
                vec=app.SSi{ii};
                vec(isnan(vec))=[];
                
                qq=quantile(vec(:),qv);
                
                limits(1)=min(limits(1),qq(1));
                limits(2)=max(limits(2),qq(2));
            end
            % limits(1)=min(limits(1),qq(1));
            % limits(2)=max(limits(2),qq(2));
            if(numel(rang)>0)
                app.Smin.Value=.01*round(100*limits(1));
                app.Smax.Value=.01*round(100*limits(2));
                if(limits(1)==limits(2)),app.Smin.Value=app.Smin.Value-.01;app.Smax.Value=app.Smax.Value+.01;end
                cursorSizeAdapt(app);
                FilterAndPlotPhasor(app);
            end
        end

        % Button pushed function: AutoButton_3
        function AutoGrange(app, event)
            rang=checkedFiles(app);
            qv=[(1-app.CoordPtile.Value)/2 (1+app.CoordPtile.Value)/2];
            limits=[Inf,-Inf];
            for ii=rang
                vec=app.GGi{ii};
                qq=quantile(vec(:),qv);
                limits(1)=min(limits(1),qq(1));
                limits(2)=max(limits(2),qq(2));
            end
            if(numel(rang)>0)
                app.Gmin.Value=.01*round(100*limits(1));
                app.Gmax.Value=.01*round(100*limits(2));
                if(limits(1)==limits(2)),app.Gmin.Value=app.Gmin.Value-.01;app.Gmax.Value=app.Gmax.Value+.01;end
                cursorSizeAdapt(app);
                FilterAndPlotPhasor(app);
            end
        end

        % Button pushed function: AutoIrangeButton
        function AutoIrange(app, event)
            rang=checkedFiles(app);
            qv=[(1-app.IntPtile.Value)/2 (1+app.IntPtile.Value)/2];
            % qv(2)=.5;
            limits=[Inf,-Inf];
            for ii=rang
                vec=app.IIa{ii};
                qq=quantile(vec(:),qv);
                if(app.relative.Value==0)
                    limits(1)=min(limits(1),qq(1));
                    limits(2)=max(limits(2),qq(2));
                else
                    limits(1)=min(limits(1),qq(1)/app.keepPeakCounts(ii));
                    limits(2)=max(limits(2),qq(2)/app.keepPeakCounts(ii));
                end
            end
            limits(limits<0)=0;
            if(app.relative.Value==1)
                limits(limits>1)=1;
            else
                limits=round(limits);
                limits(limits>app.Imaxrange)=app.Imaxrange;
                if(limits(2)>app.Islide.Limits(2))
                    app.Imaxrange=limits(2);
                    app.Islide.Limits(2)=limits(2);
                end
                if(limits(2)>app.Imax.Limits(2))
                    app.Imaxrange=limits(2);
                    app.Imax.Limits(2)=limits(2);
                end                
            end
            app.Imax.Value=limits(2);
            app.Imin.Value=limits(1);
            ImaxValueChanged(app);


        end

        % Button pushed function: AutoButton
        function AutoColor(app, event)
            rang=checkedFiles(app);

            [type,centre] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);
            if(isempty(centre)),centre=[app.Gcenter.Value app.Scenter.Value];end
            qv=[(1-app.ColPtile.Value)/2 (1+app.ColPtile.Value)/2];
            limits=[Inf,-Inf];
            if(isempty(rang)),limits=[0 1];end
            for ii=rang
                s=app.SSi{ii};
                g=app.GGi{ii};
                if(type==1)
                    ph=atan2(s-centre(2),g-centre(1));
                    qq=quantile(ph(:),qv);
                    qq=qq/6.28;
                    limits(1)=min(limits(1),qq(1));
                    limits(2)=max(limits(2),qq(2));
                end
                if(type==2)||(type==3)
                    vec=[s(:)-centre(2),g(:)-centre(1)];
                    vec=sqrt(sum(vec.*vec,2));
                    qq=quantile(vec(:),qv);
                    if((type==3)),qq=qq-1e3;end
                    limits(1)=min(limits(1),qq(1));
                    limits(2)=max(limits(2),qq(2));
                end
            end
            if(numel(rang)>0)
                if(app.NormColunits.Value==0)
                    limits=crangefromunity(app,limits);
                end
                app.Cmin.Value=.01*round(100*limits(1));
                app.Cmax.Value=.01*round(100*limits(2));

                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: axes
        function AxesValueChanged(app, event)
            if(app.axes.Value==0)
                app.PP.XTick=[];
                app.PP.YTick=[];
            else
                xticks(app.PP,'auto');
                yticks(app.PP,'auto');
            end
        end

        % Button pushed function: BrowseResults
        function BrowseResultsButtonPushed(app, event)
            aux = uigetDirs('Select folder to save results',1);
            figure(app.GSLabUIFigure);
            if(~isempty(aux))
                app.folderR=aux;
                app.ReLab.Text=app.folderR(find(app.folderR==filesep,1,'last')+1:end);
            end
        end

        % Value changed function: Smin
        function CoordSminChanged(app, event)
            if(app.Smin.Value>app.Smax.Value),app.Smax.Value=app.Smin.Value+.1;end
            cursorSizeAdapt(app);
            FilterAndPlotPhasor(app);
            % replotPP(app);
        end

        % Value changed function: Smax
        function CoordSmaxChanged(app, event)
            cursorSizeAdapt(app);
            FilterAndPlotPhasor(app);
            % replotPP(app);
        end

        % Value changed function: Gmin
        function CoordGminChanged(app, event)
            if(app.Gmin.Value>app.Gmax.Value),app.Gmax.Value=app.Gmin.Value+.1;end
            cursorSizeAdapt(app);
            FilterAndPlotPhasor(app);
            % replotPP(app);
        end

        % Value changed function: Gmax
        function CoordGmaxChanged(app, event)
            cursorSizeAdapt(app);
            FilterAndPlotPhasor(app);
            % replotPP(app);
        end

        % Value changed function: Cmin
        function CoordCminChanged(app, event)
            if(app.Cmin.Value>app.Cmax.Value),app.Cmax.Value=app.Cmin.Value+.1;end
            FilterAndPlotPhasor(app);
        end

        % Value changed function: Cmax
        function CoordCmaxChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Value changed function: Gcenter
        function CoordGcenterChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Value changed function: Scenter
        function CoordScenterChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Button pushed function: ComputeHR
        function ComputeHRButtonPushed(app, event)

            phasorUnmixing(app);
        end

        % Value changed function: CmapFlipDens
        function CmapFlipDensValueChanged(app, event)
            % value = app.CmapFlipDens.Value;
            ind=app.PhasorcolormapDD.ValueIndex;
            app.PhasorcolormapDD.ItemsData{ind}=['w' app.PhasorcolormapDD.ItemsData{ind}(end:-1:2)];
            app.PhasorcolormapDD.ValueIndex=ind;
            CmapPhdensDDValueChanged(app);
        end

        % Value changed function: CmapFlipMag
        function CmapFlipMagValueChanged(app, event)
            %value = app.CmapFlipMag.Value;
            ind=app.RainbowcolormapDD.ValueIndex;
            app.RainbowcolormapDD.ItemsData{ind}=app.RainbowcolormapDD.ItemsData{ind}(end:-1:1);
            app.RainbowcolormapDD.ValueIndex=ind;
            CmapMagDDValueChanged(app);
        end

        % Value changed function: PhasorcolormapDD
        function CmapPhdensDDValueChanged(app, event)
            value = app.PhasorcolormapDD.Value;%disp(value);
            if(app.PhasorcolormapDD.ValueIndex==numel(app.PhasorcolormapDD.Items))
                set(app.singhans(1),'Enable',1);
                value=['w' repmat(app.single(1),[1 4])];% create single color map
            else
                set(app.singhans(1),'Enable',0);
            end
            if(app.DM==1),value(1)='k';end% theyre all saved with a 'w' at the beginning for lightmode
            app.PCM=value;% phasor colormap
            EmphasisNoneMenuSelected(app);
            replotPP(app);
        end

        % Value changed function: RainbowcolormapDD
        function CmapMagDDValueChanged(app, event)
            value = app.RainbowcolormapDD.Value;
            if(app.RainbowcolormapDD.ValueIndex==numel(app.RainbowcolormapDD.Items))
                set(app.singhans(2),'Enable',1);
                value=['w' repmat(app.single(2),[1 4]) 'w'];% create single color map
            else
                set(app.singhans(2),'Enable',0);
            end
            app.RCM=value;% rainbow colormap
            FilterAndPlotPhasor(app);
        end

        % Cell edit callback: calTable
        function CalibTableCellEdit(app, event)
            indices = event.Indices;
            newTag = event.NewData;
            aux=0;
            for ii=1:numel(app.multical)
                if(~isempty(app.multical{ii}))
                    aux=aux+1;
                    if(aux==indices(1))
                        break;
                    end
                end
            end
            edita=ii;
            if(indices(2)==1)% changed tag

                N=numel(app.calibtags);
                N=N+1;
                app.PD(N,:)=app.PD(edita,:);app.PD(edita,:)=0;
                app.MF(N,:)=app.MF(edita,:);app.MF(edita,:)=1;
                app.multical{N}=app.multical{edita};app.multical{edita}=[];
                app.multilif(N)=app.multilif(edita);app.multilif(edita)=0;
                app.calibtags{N}=newTag;

                for ii=1:app.LoadedIms
                    if(contains([app.nms{ii} app.nmsx{ii}],newTag))
                        app.calibflag(ii)=N;
                    end
                end
            end
            if(indices(2)==2)% changed lifetime
                newval=str2num(newTag);
                % disp([num2str(app.multilif(edita)) ' to ' num2str(newval)]);
                oldval=app.multilif(edita);
                app.multilif(edita)=newval;
                %%
                SP=1;
                if(edita==1),SP=2;end% when splits, we gotta change both!
                for sp=1:SP
                    quin=edita;
                    if(sp==2),quin=2;end% 2 is the index for second half of 32SP
                    for hh=1:app.HarmLim.Value
                        [so,go]=tau2coords(oldval,app.freq,hh);
                        [sn,gn]=tau2coords(newval,app.freq,hh);
                        phad=atan2(sn,gn)-atan2(so,go);
                        modf=sqrt((sn.*sn)+(gn.*gn))/sqrt((so.*so)+(go.*go));
                        app.PD(quin,hh)=app.PD(quin,hh)+phad;
                        app.MF(quin,hh)=app.MF(quin,hh)*modf;
                    end
                end
                %%
            end
            DoCalibValueChanged(app);
        end

        % Selection changed function: Calibrationtype
        function CalibrationtypeSelectionChanged(app, event)
            if(strcmp(app.Calibrationtype.SelectedObject.Text,'Files'))
                % app.modS.Enable=0;
                % app.phaS.Enable=0;
                % app.modL.Enable=0;
                % app.phaL.Enable=0;
                % app.calTable.Enable='on';
                app.manualcalibpanel.Visible=0;
                app.LT.Enable=1;
                app.CalibrationLifetimensLabel.Enable=1;
            else
                app.manualcalibpanel.Visible=1;
                % app.modS.Enable=1;
                % app.phaS.Enable=1;
                % app.modL.Enable=1;
                % app.phaL.Enable=1;
                % app.calTable.Enable='off';
                app.LT.Enable=0;
                app.CalibrationLifetimensLabel.Enable=0;
            end
            if(app.DoCalib.Value==1)
                DoCalibValueChanged(app);
            end
        end

        % Menu selected function: FLAMEcalibration
        function CalibrationLoadMenuSelected(app, event)
            aux = uigetDirs('Select folder containing image folders',1);
            if(~isempty(aux))
                app.folderC=aux;
                LoadCalibrationFile(app);
                if(app.CalibHolder.Visible==0)
                    if(app.TabGroup.SelectedTab==app.TabGroup.Children(1))
                        hidecalibButtonPushed(app);
                    end
                end
            end
        end

        % Value changed function: LT
        function CalibLifetimeValueChanged(app, event)
            Ng=9;quin=0;
            for ii=1:app.LoadedIms
                if(strcmp([app.nms{ii} app.nmsx{ii}],app.multical{Ng}))
                    quin=ii;
                    app.Tree.SelectedNodes=app.Tree.Children(quin);
                    break
                end
            end
            if(quin>0)

                CalibrationFileMenuSelected(app)

            end
        end

        % Button pushed function: ClearButton
        function CalibClearButtonPushed(app, event)
            calibrationRemove(app);
            app.DoCalib.Value=0;
            app.multilif(:)=0;
            % app.calibflag(:)=0;
            for ii=1:numel(app.multical)
                app.multical{ii}=[];
            end
            app.PD(:)=0;
            app.MF(:)=1;
            calibrationDisplayUpdate(app);
            DoCalibValueChanged(app);
        end

        % Menu selected function: CalibrationFileMenu
        function CalibrationFileMenuSelected(app, event)
            if(isempty(app.Tree.SelectedNodes))
                uialert(app.GSLabUIFigure,['Select a file to use for calibration!'],'Error','icon','error');
            else
                if(app.CalibHolder.Visible==0)
                    hidecalibButtonPushed(app);
                end

                quin=app.Tree.SelectedNodes.NodeData ;
                d=barraprogress(app,0,[],'Calibration');
                Ng=9;
                for hh=1:app.HarmLim.Value
                    S1=app.SSo{quin,hh};
                    G1=app.GGo{quin,hh};
                    II=sum(app.IIo{quin},3);
                    [s,g]=tau2coords(app.LT.Value,app.freq,hh);
                    S1=S1(:);G1=G1(:);II=II(:);
                    out=find(II==0);
                    S1(out)=[];G1(out)=[];II(out)=[];
                    S1=S1'*II/sum(II);G1=G1'*II/sum(II);% photon wieghted phasor coords
                    aux=atan2(S1,G1);
                    app.PD(Ng,hh)=atan2(s,g)-aux;
                    aux=sqrt((S1*S1)+(G1*G1));
                    app.MF(Ng,hh)=sqrt((s*s)+(g*g))/aux;

                    d=barraprogress(app,hh/app.HarmLim.Value,d,'Loading calibration');
                end

                app.multical{Ng}=[app.nms{quin} app.nmsx{quin}];
                app.multilif(Ng)=app.LT.Value;

                for ii=1:app.LoadedIms
                    if(app.calibflag(ii)==0)
                        app.calibflag(ii)=Ng;
                    end
                end


                % calibrationDisplayUpdate(app);% already inside applyCalibration!
                calibrationApply(app);
                FilterAndPlotPhasor(app);
            end

            try(close(d));end


        end

        % Menu selected function: AllNoneMenu
        function CheckedAllNoneMenuSelected(app, event)
            rang=checkedFiles(app);
            if(numel(rang)>numel(app.Tree.Children)/2)% more checked
                app.Tree.CheckedNodes=[];% uncheck all
                rang=[];
            else
                rang=1:numel(app.Tree.Children);
                for ii=rang
                    app.Tree.CheckedNodes=[app.Tree.CheckedNodes;app.Tree.Children(ii)];
                end
                bk=0;
                for ii=rang
                    aux=app.currPP(:,:,ii);
                    if(numel(unique(aux(:)))==1)
                        bk=1;break
                    end
                end
                if(bk==1)
                    FilterAndPlotPhasor(app);
                end
            end

            replotPP(app);
            replotII(app);
            plotHists(app);
        end

        % Menu selected function: InvertMenu
        function CheckedInvertMenuSelected(app, event)
            rang=checkedFiles(app);
            norang=setdiff([1:numel(app.Tree.Children)],rang);
            app.Tree.CheckedNodes=[];
            for ii=norang
                app.Tree.CheckedNodes=[app.Tree.CheckedNodes;app.Tree.Children(ii)];
            end
            rang=norang;
            bk=0;
            for ii=rang
                aux=app.currPP(:,:,ii);
                if(numel(unique(aux(:)))==1)
                    bk=1;break
                end
            end
            if(bk==1)
                FilterAndPlotPhasor(app);
            end
            replotPP(app);
            replotII(app);
            plotHists(app);
        end

        % Menu selected function: AlternateMenu
        function CheckAlternateMenuSelected(app, event)
            if(~isempty(app.Tree.CheckedNodes))
                if(mod(app.Tree.CheckedNodes(1).NodeData,2)==0)
                    start=1;
                else
                    start=2;
                end
            end
            app.Tree.CheckedNodes=[];% uncheck all

            rang=start:2:numel(app.Tree.Children);
            for ii=rang
                app.Tree.CheckedNodes=[app.Tree.CheckedNodes;app.Tree.Children(ii)];
            end
            bk=0;
            for ii=rang
                aux=app.currPP(:,:,ii);
                if(numel(unique(aux(:)))==1)
                    bk=1;break
                end
            end
            if(bk==1)
                FilterAndPlotPhasor(app);
            end
            replotPP(app);
            replotII(app);
            plotHists(app);
        end

        % Menu selected function: AllMenu
        function ClearAllContextSelected(app, event)
            app.Applymask.Value=0;
            app.IIo=cell(0);
            app.hdrkeep=cell(0);
            app.SSo=cell(0);
            app.GGo=cell(0);
            app.IIa=cell(0);
            app.SSa=cell(0);
            app.GGa=cell(0);
            app.IIi=cell(0);
            app.SSi=cell(0);
            app.GGi=cell(0);
            app.MK=cell(0);
            app.MKp=cell(0);
            app.MKL=cell(0);
            app.dda=cell(0);
            app.nms=cell(0);
            app.nmsx=cell(0);
            app.pat=cell(0);
            app.calibflag=[];
            app.LoadedIms=0;
            app.Imaxrange=1;
            app.Islide.Limits(2)=1;
            app.keepPeakCounts=[];
            app.AXh=[];
            delete(app.Tree.Children);
            replotII(app);
            cla(app.histax);
            cla(app.MKax);
            delete(app.ComponentTree.Children);
            CalibClearButtonPushed(app);
            try
                close(app.IWh);
            end
            app.DoCalib.Value=0;
            EmphasisNoneMenuSelected(app);
            XButtonPushed(app);
            GMMclearButtonPushed(app);
        end

        % Menu selected function: UncheckedMenu
        function ClearUncheckedMenuSelected(app, event)
            rang=checkedFiles(app);

            for ii=1:numel(rang)
                app.IIo{ii}=app.IIo{rang(ii)};
                app.hdrkeep{ii}=app.hdrkeep{rang(ii)};
                app.IIa{ii}=app.IIa{rang(ii)};
                for hh=1:app.HarmLim.Value
                    app.SSo{ii,hh}=app.SSo{rang(ii),hh};
                    app.GGo{ii,hh}=app.GGo{rang(ii),hh};
                    app.SSa{ii,hh}=app.SSa{rang(ii),hh};
                    app.GGa{ii,hh}=app.GGa{rang(ii),hh};
                end
                app.IIi{ii}=app.IIi{rang(ii)};
                app.SSi{ii}=app.SSi{rang(ii)};
                app.GGi{ii}=app.GGi{rang(ii)};
                app.MK{ii}=app.MK{rang(ii)};
                app.dda{ii}=app.dda{rang(ii)};
                app.nms{ii}=app.nms{rang(ii)};
                app.nmsx{ii}=app.nmsx{rang(ii)};
                app.pat{ii}=app.pat{rang(ii)};
                app.calibflag(ii)=app.calibflag(rang(ii));
                app.currPP(:,:,ii)=app.currPP(:,:,rang(ii));
            end
            app.LoadedIms=numel(rang);
            app.IIo(app.LoadedIms+1:end)=[];
            app.hdrkeep(app.LoadedIms+1:end)=[];
            app.SSo(app.LoadedIms+1:end,:)=[];
            app.GGo(app.LoadedIms+1:end,:)=[];
            app.IIa(app.LoadedIms+1:end)=[];
            app.SSa(app.LoadedIms+1:end,:)=[];
            app.GGa(app.LoadedIms+1:end,:)=[];
            app.IIi(app.LoadedIms+1:end)=[];
            app.SSi(app.LoadedIms+1:end)=[];
            app.GGi(app.LoadedIms+1:end)=[];
            app.MK(app.LoadedIms+1:end)=[];
            app.dda(app.LoadedIms+1:end)=[];
            app.nms(app.LoadedIms+1:end)=[];
            app.nmsx(app.LoadedIms+1:end)=[];
            app.pat(app.LoadedIms+1:end)=[];
            app.calibflag(app.LoadedIms+1:end)=[];
            norang=setdiff(1:numel(app.Tree.Children),rang);
            norang=sort(norang,'descend');
            for ii=norang
                delete(app.Tree.Children(ii));
            end
            for ii=1:numel(app.Tree.Children)
                app.Tree.Children(ii).NodeData=ii;
            end
            app.Tree.CheckedNodes=[];% uncheck all
            CheckedInvertMenuSelected(app);%check all

            replotPP(app);
            try
                close(app.IWh);
            end
            if(app.LoadedIms>0)
                replotII(app);
                plotHists(app);
            end
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            cla(app.MKax);
            app.Applymask.Value=0;
            app.Invertmask.Value=0;
            app.integrateRegions.Value=0;
             app.integrateRegions.Enable=0;
            app.MKL=cell(0);
            FilterAndPlotPhasor(app);
        end

        % Button pushed function: ComputeLR
        function ComputeLRButtonPushed(app, event)
            app.exportHR=0;
            phasorUnmixing(app);
            app.exportHR=1;
        end

        % Value changed function: Combine
        function CombineValueChanged(app, event)
            if(app.Combine.Value==0)
                % app.Expptrans.Enable=1;
                app.groupfiles.Enable=0;
                app.groupfiles.Value=0;
            else
                app.exportvec=0;
                app.groupfiles.Enable=1;
                app.Expptrans.Value=0;
                if(app.ExPhasor.Value==1)
                    app.Expptrans.Value=0;
                    app.ExportPhasorFileDropDown.Value=app.ExportImageFileDropDown.Value;
                end
            end
        end

        % Value changed function: AddLines
        function ComponentLinesValueChanged(app, event)
            replotPP(app);
        end

        % Button pushed function: minusB
        function ComponentMinusButtonPressed(app, event)
            componentRemoveTheo(app);
        end

        % Callback function: ComponentTree
        function ComponentTreeCheckedNodesChanged(app, event)
            componentTreeColors(app);
            componentGetEmpiricCoords(app);
            if(app.FileButton.Value==1)
                app.compNum=app.compNumE;
            else
                app.compNum=numel(find(app.roidad(:,3)>0));
                if(app.compNum>3),app.compNum=3;end
                componentLifeChange(app,[],[],[1:app.compNum]);
            end
            replotPP(app);
        end

        % Button pushed function: plusB
        function ComponentPlusButtonPressed(app, event)
            componentAddTheo(app);
        end

        % Value changed function: CompImsExp
        function CompImsExpValueChanged(app, event)
            if(app.CompImsExp.Value==1)
                app.CompImsIntExp.Enable=1;
                app.CompImsCol.Enable=1;
                app.singleimage.Enable=1;
                app.addmargins.Enable=1;
                app.groupfile.Enable=1;
                app.mergecomps.Enable=1;
            else
                app.CompImsIntExp.Enable=0;
                app.CompImsCol.Enable=0;
                app.singleimage.Enable=0;

                app.addmargins.Enable=0;
                app.groupfile.Enable=0;
                app.mergecomps.Enable=0;
            end
            singleimageValueChanged(app);
        end

        % Value changed function: ratiocsv
        function CompratiocsvValueChanged(app, event)

            if(app.ratiocsv.Value==1)
                app.includeint.Enable=1;
            else
                app.includeint.Enable=0;
            end
        end

        % Value changed function: CompImsCSVExp
        function CompImsCSVExpValueChanged(app, event)

            if(app.CompImsCSVExp.Value==1)
                app.includeint.Enable=1;
            else
                app.includeint.Enable=0;
            end
        end

        % Value changed function: contours
        function ContoursValueChanged(app, event)
            if(app.contours.Value==0),app.contourOnly.Value=0;app.contourOnly.Enable=0;else app.contourOnly.Enable=1;end
            replotPP(app);
        end

        % Value changed function: contourOnly
        function ContourOnlyValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: DarkmodeCheckBox
        function DarkmodeCheckBoxValueChanged(app, event)
            app.DM=1-app.DM;
            setcolors(app);
            % FilterAndPlotPhasor(app);
        end

        % Value changed function: NormDecays
        function DecayNormValueChanged(app, event)
            DecaysPlotButton(app);
        end

        % Value changed function: LogDecays
        function DecaysLogChanged(app, event)
            DecaysPlotButton(app);
        end

        % Button pushed function: PlotDecays
        function DecaysPlotButton(app, event)
            rang=checkedFiles(app);
            NR=numel(rang);
            if(NR>0)            
            if(isempty(app.PDh)||~ishandle(app.PDh))
                app.PDh=uifigure;
                set(app.PDh,'Menubar','none','Name','Decay Window','NumberTitle','off','Icon','scripts/logo1.png');
                set(app.PDh,'CloseRequestFcn',{@app.decayWindClose});
                if(~isempty(app.decaywinpos))
                    set(app.PDh,'position',app.decaywinpos);
                else
                    aux=get(app.PDh,'position');
                    if(aux(3)<568)
                        aux(3)=1120;
                        set(app.PDh,'position',aux)
                    end
                end
                ax=uiaxes(app.PDh,'units','normalized','position',[.03 .04 .94 .92]);
                hold(ax,'on');
            else
                ax=app.PDh.Children(end);
                cla(ax, 'reset');
                hold(ax,'on');
            end
            focus(app.PDh);
            set(ax,'fontsize',16,'box','on');
            grid(ax,'on');


            cols=superjet(NR,app.PCM(2:end-1));
            if(app.Applymask.Value==1)&&(app.integrateRegions.Value==1)
                NR=NR*max(max(app.MKL{1}));
            end
            lab=cell(0);cc=0;cc0=0;% one for files one for files and regions
            d=barraprogress(app,0,0,'Plotting decay');
            
            for ii=rang
                if(isempty(app.dda{ii}))
                    ensenya('Error: File format does not contain decay','r');
                else  
                    dec=cell(1,1);
                    dec{1}=app.dda{ii};
                if(app.Applymask.Value==1)% we gotta extract again
                        Im1=app.IIo{ii};

                    % incase we loadded a single image
                    ind=mod(ii,numel(app.MKL));if(ind==0),ind=numel(app.MKL);end
                    aux=app.MKL{ind};
                    % ensenya(num2str(min(aux(:))));
                    % ensenya(num2str(max(aux(:))));                    
                    out=(size(Im1,1)-size(aux,1))/2;
                    % caution: regardless we resize mask
                    if(out~=0)
                        aux=imresize(aux,[size(Im1,1) size(Im1,2)],'nearest');                    
                    end
                     aux(aux<0)=0;% incase we inverted a labeled mask
                     if(app.integrateRegions.Value==0)% normal pixel based operation
                         aux(aux>1)=1;% incase it is a labeled mask
                         try
                             Im1=Im1.*aux;
                             dec{1}=permute(sum(sum(Im1,2),1),[3 1 2]);
                         end
                     else
                         L=unique(aux(:));
                         L(L==0)=[];
                         dec=cell(numel(L),1);
                         for jj=1:numel(L)
                             gg=Im1.*(aux==L(jj));
                             dec{jj}=permute(sum(sum(gg,2),1),[3 1 2]);
                         end
                     end
                end
                cc0=cc0+1;
                for dd=1:numel(dec)
                    ext='';
                    if(numel(dec)>1)
                        ext=['_Id' niceDigits(dd,2)];
                    end

                    cc=cc+1;
                    if(NR<5)
                        if(app.NormDecays.Value==0)
                            h=bar(ax,[0:numel(dec{dd})-1]+cc/NR,dec{dd},1/NR);% raw
                        else
                            h=bar(ax,[0:numel(dec{dd})-1]+cc/NR,dec{dd}/max(dec{dd}),1/NR);% norm
                        end
                        % set(h,'facealpha',max(.5,1-.5*((cc-1)*NR)));
                        set(h,'facecolor',cols(cc0,:)*.5*(1+dd/numel(dec)));
                    else
                        if(app.NormDecays.Value==0)
                            h=plot(ax,dec{dd});
                        else
                            h=plot(ax,dec{dd}/max(dec{dd}));
                        end
                        set(h,'color',cols(cc0,:)*.5*(1+dd/numel(dec)));
                    end
                    lab{cc}=[app.nms{ii} app.nmsx{ii} ext];
                end
                
                d=barraprogress(app,(ii-1+(dd/numel(dec)))/numel(rang),d,'Plotting');
                end
            end
            if(cc>0)
                % if(NR<10)
                % h=legend(lab);
                % else
                if(app.LogDecays.Value==1)
                    set(ax,'yscale','log');
                end
                if(numel(lab)<=50)
                h=legend(ax,lab,'location','eastoutside');                
                set(h,'interpreter','none','linewidth',1);
                end
                enableDefaultInteractivity(ax);
            else
                close(app.PDh);
            end

            try
                close(d);
            end
            end
        end

        % Value changed function: fliprows
        function DisplayfliprowsValueChanged(app, event)
            try,close(app.IWh);end
            replotII(app);
        end

        % Value changed function: margns
        function DisplayMargnsValueChanged(app, event)
            try,close(app.IWh);end
            replotII(app);
        end

        % Value changed function: DoCalib
        function DoCalibValueChanged(app, event)

            if(app.DoCalib.Value==0)
                app.CalibrationPanel.Enable=0;
                app.Calibrationtype.Enable=0;
                calibrationRemove(app);
                FilterAndPlotPhasor(app);
            else
                app.CalibrationPanel.Enable=1;
                app.Calibrationtype.Enable=1;
                calibrationApply(app);
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: DownsamplingEditField
        function DownsamplingEditFieldValueChanged(app, event)
            app.dwnsmp = app.DownsamplingEditField.Value;
            if(app.dwnsmp>0)&&(app.dwnsmp<64)
                uialert(app.GSLabUIFigure,'The downsampling field sets the image size after resizing to speed up processing. If the value is below 64, it defaults to 64. If set to 0, no downsampling occurs.','Warning','icon','info');
                app.DownsamplingEditField.Value=64;
                app.dwnsmp=64;
            end
            % if(app.LoadedIms>0)
            %     gg=questdlg({'Data must be reloaded to change sizes. Clear everything?',' '},'','Ok','Cancel','Ok');
            %     if(strcmp(gg,'Ok'))
            %         clearCalib(app);
            %         ClearAllContextSelected(app);
            %     else
            %         app.dwnsmp =256;
            %         app.DownsamplingEditField.Value=256;
            %     end
            % end
            N=app.LoadedIms;
            if(N>0)
                app.running=1;
                d=barraprogress(app,0,[],'Resampling');

                for ii=1:N% jostia
                    nom=app.nms{ii};
                    app.hdrkeep{ii}=[];
                    Im1=sum(app.IIo{ii},3);


                    % downsample images
                    if(app.dwnsmp>0)
                        nwsz=round([app.dwnsmp size(Im1,2)*app.dwnsmp/size(Im1,1)]);
                    else
                        nwsz=size(Im1);
                    end
                    app.IIa{ii}=imresize(Im1,nwsz);% accumulated
                    % if no cursors in place set mask to new
                    aux=0;
                    for ll=1:size(app.roidad,1)
                        if(app.roidad(ll,3)>0)
                            aux=aux+1;break
                        end
                    end
                    if(aux==0)
                        app.MK{ii}=ones(nwsz(1),nwsz(2),3);
                    else% since the accumulated SSi and GGi are not going to be cropped resize MK
                        try% cursors could be in place but no mask yet (possibly user put cursors before loading data)
                            app.MK{ii}=imresize(app.MK{ii},nwsz);
                        catch % assume it is such case so we do have to initialise masks
                            app.MK{ii}=ones(nwsz(1),nwsz(2),3);
                        end
                    end
                    app.MKp{ii}=app.MK{ii};
                    d=barraprogress(app,(ii-.66)/N,d,[nom ]);

                    for hh=1:app.HarmLim.Value


                        % keep raw calibrated for accumulated
                        S1=app.SSo{ii,hh};
                        G1=app.GGo{ii,hh};

                        % fill nans before resizing (otherwise only nearest algorithm works)!
                        S1=phasorFillNaN2(S1);
                        G1=phasorFillNaN2(G1);

                        %resize
                        app.SSa{ii,hh}=imresize(S1,nwsz);% accumulated
                        app.GGa{ii,hh}=imresize(G1,nwsz);% accumulated


                        d=barraprogress(app,(ii-.66+(.66*hh/app.HarmLim.Value))/N,d,[nom ' h' num2str(hh)]);
                    end
                end

                try
                    close(app.IWh);
                end
                if(app.DoCalib.Value==1)
                    calibrationApply(app);
                end
                createImageFigure(app);
                FilterAndPlotPhasor(app);
                % plotHists(app);
                SourceButtonGroupSelectionChanged(app);
                app.running=0;
            end
        end

        % Menu selected function: NoneMenu
        function EmphasisNoneMenuSelected(app, event)
            app.emphcol(:,:)=-1;
            tst=uistyle('FontColor',app.FGcol);
            for ii=1:numel(app.Tree.Children)
                addStyle(app.Tree,tst,'node',app.Tree.Children(ii));
            end
            replotPP(app);
        end

        % Menu selected function: CheckedMenu
        function EmphasisCheckedMenuSelected(app, event)
            gg=[];
            try
                pos=app.GSLabUIFigure.Position;
                tam=[200 200];
                % pos=pos(1:2)+(pos(3:4)-tam)/2;
                % pos(2)=pos(2)+sum(app.Panel.Position([2,4]));
                % pos(1:2)=pos(1:2)+(app.Tree.Position(3:4)-tam)/2;
                % pos(1:2)=pos(1:2)+app.GSLabUIFigure.CurrentPoint-tam/2;
                pos(1)=pos(1)-tam(1);
                pos(3:4)=tam;
                gg=colorPicker2(0,pos);
            end
            if(~isempty(gg))
                rang = checkedFiles(app);
                app.emphcol(rang,:)=repmat(gg,[numel(rang),1]);
                tst=uistyle('FontColor',gg);
                for ii=1:numel(app.Tree.CheckedNodes)
                    addStyle(app.Tree,tst,'node',app.Tree.CheckedNodes(ii));
                end
            end
            % disp(app.emphcol(1:app.LoadedIms,:));
            replotPP(app);
            figure(app.GSLabUIFigure);
        end

        % Menu selected function: SelectedMenu
        function EmphasisSelectedMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                gg=[];
                try
                    pos=app.GSLabUIFigure.Position;
                    tam=[200 200];
                    % pos=pos(1:2)+(pos(3:4)-tam)/2;
                    % pos(2)=pos(2)+sum(app.Panel.Position([2,4]));
                    % pos(1:2)=pos(1:2)+(app.Tree.Position(3:4)-tam)/2;
                    % pos(1:2)=pos(1:2)+app.GSLabUIFigure.CurrentPoint-tam/2;
                    pos(1)=pos(1)-tam(1);
                    pos(3:4)=tam;
                    gg=colorPicker2(0,pos);
                end
                if(~isempty(gg))
                    app.emphcol(quin,:)=gg;
                    tst=uistyle('FontColor',gg);
                    addStyle(app.Tree,tst,'node',app.Tree.SelectedNodes);

                end
            else
                uialert(app.GSLabUIFigure,'Select a file for emphasis!','Error','Icon','Error') ;
            end
            % disp(app.emphcol(1:app.LoadedIms,:));
            replotPP(app);
            figure(app.GSLabUIFigure);
        end

        % Value changed function: exportratiometric
        function ExportratiometricValueChanged(app, event)
            if(app.exportratiometric.Value==1)
                app.comp1.Enable=1;
                app.comp2.Enable=1;
                app.ratiocsv.Enable=1;
            else
                app.comp1.Enable=0;
                app.comp2.Enable=0;
                app.ratiocsv.Enable=0;
            end

        end

        % Button pushed function: ExportLRButton
        function ExportLRButtonPushed(app, event)
            app.exportHR=0;
            ExportHRButtonPushed(app);
            app.exportHR=1;
        end

        % Button pushed function: ExportHRButton
        function ExportHRButtonPushed(app, event)
            app.running=1;
            if(isempty(app.folderR))
                BrowseResultsButtonPushed(app);
            end
            if(~isempty(app.folderR))
                ensenya('Exporting    ');
                d=barraprogress(app,0,[],'Please wait...');
                % aux=get(d,'Position');
                % set(d,'Position',aux+(aux(4)+40).*[0 1 0 0]);
                % progress bar bits for each export step:
                % plot,preprint,csvs,images,etc.
                trossos=ones(12,1);trossos=trossos/sum(trossos);
                saveParams(app);

                if(app.gmmexport.Value)
                    GMMApplyButtonPushed(app);
                end

                if(app.LabelCoords.Value==1)||(app.GScoords.Value==1)% prepare labels if necessary
                    magnames={'Int','G','S','Phase','Modulation','Pseudophase','TauPhase','TauModulation','TauNormalized'};
                    morphnames={'Area','Circularity','Eccentricity','EquivDiameter','EulerNumber','MajorAxisLength','MinorAxisLength','Orientation','Perimeter','Solidity','Centroid','Centroid'};
                end

                % check number of exports in case it is only 1 and
                % combo is selected
                npans=app.ExInt.Value+app.ExMagH.Value+app.ExMagS.Value+app.ExCurH.Value+app.ExCurS.Value+app.ExPhasor.Value;


                % see what nodes are checked
                rang=checkedFiles(app);
                if(app.groupfiles.Value==1)
                    guarda=cell(0);
                end
                RR=numel(rang);


                for ii=rang
                    % tic;
                    extra=app.nmsx{ii};
                    file=app.nms{ii};
                    if(app.AddFolders.Value==1)
                        file=file(find(file==filesep)+1:end);
                    end
                    cct=0;
                    if(app.exportHR==1)
                        [S1,G1,Im1,pp,I1,I2,IC]=FilterAndPlotPhasor(app,app.SSo{ii,app.Harm.Value},app.GGo{ii,app.Harm.Value},sum(app.IIo{ii},3),ii);
                    else
                        [S1,G1,Im1,pp,I1,I2,IC]=FilterAndPlotPhasor(app,app.SSa{ii,app.Harm.Value},app.GGa{ii,app.Harm.Value},app.IIa{ii},ii);
                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);

                    if(app.ExPhasor.Value==1)
                        auxfora=0;
                        if(app.Botonera.Visible==1),auxfora=1;end
                        if(auxfora==1),app.Botonera.Visible=0;end
                        app.coolab.Text='';% just in case
                        if(app.exportvec==1)% vector
                            exportgraphics(app.PP,[app.folderR filesep file '_PP' extra '.' app.ExportPhasorFileDropDown.Value],'Resolution',300);
                            ppprint=[];
                        else% bitmap
                            if(app.ExportType.Value==0)
                                [ppprint]=screencapture(app.GSLabUIFigure);
                                fpos=app.GSLabUIFigure.Position;
                                ppos=app.PhasorPanel.Position;
                                ppprint=ppprint(fpos(4)-ppos(2)-ppos(4)+4:fpos(4)-ppos(2),ppos(1)+1:ppos(1)+ppos(3)-2,:);

                            else
                                [ppprint,~]=getframe(app.GSLabUIFigure,app.PhasorPanel.Position);
                                ppprint=ppprint(3:end,1:end-2,:);
                            end
                        end
                        if(app.axes.Value==0)
                            aux=sum(ppprint,3);
                            val=sum(ppprint(1,1,:));
                            auxx=aux(round(size(aux,1)/2),:);
                            auxy=aux(:,round(size(aux,2)/2));
                            rgx=find(auxx~=val,1,'first'):find(auxx~=val,1,'last');
                            rgy=find(auxy~=val,1,'first'):find(auxy~=val,1,'last');
                            ppprint=ppprint(rgy,rgx,:);
                        end
                        %
                        if(auxfora==1),app.Botonera.Visible=1;end
                        % achtung, takes bg color into account to turn it white
                        % hopefully the color is not present in the plot
                        aux=find(sum(ppprint,3)==720);
                        ppprint(aux)=255;
                        aux1=numel(ppprint)/3;
                        ppprint(aux+aux1)=255;
                        ppprint(aux+aux1*2)=255;
                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);

                    % decay
                    if(app.ExDecay.Value==1)
                        % disp('y');
                        if(~isempty(app.dda{ii}))
                            muntaCSV([app.folderR filesep file extra '.' app.DeacayFileDropDown.Value],',','Decay',app.dda{ii});
                        else
                            ensenya('Error: File format does not contain decays.','r');
                        end
                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);

                    % % old coords csv of just I,S,G
                    % if(app.GScoords.Value==1)
                    %     % aux=[Im1(:),S1(:),G1(:)];
                    %  muntaCSV([app.folderR filesep file extra '_GS.' app.CoordsFmtDD.Value],',','I,S,G',aux);
                    % end
                    % coords csv
                    if(app.GScoords.Value==1)
                        % aux=[Im1(:),S1(:),G1(:)];
                        Mags=[10:18];
                        colsI=zeros(numel(Im1),numel(Mags));
                        H=cell(1,numel(Mags));
                        cc=0;
                        for jj=1:numel(Mags)
                            cc=cc+1;
                            % figure,imagesc(S1);
                            if(Mags(jj)~=10)
                                R = magnitude(S1,G1,Mags(jj)-11,app.freq,1);
                            else
                                if(app.relative.Value==1)
                                    R=Im1;
                                else
                                    if(app.exportHR==0)
                                        auxim=app.IIa{ii};
                                    else
                                        auxim=app.IIo{ii};
                                    end
                                    fora=(size(auxim)-size(Im1))/2;
                                    auxim=auxim(fora(1)+1:end-fora(1),fora(2)+1:end-fora(2));
                                    R=auxim;
                                end
                            end
                            H{cc}=[magnames{Mags(jj)-9}];
                            colsI(:,cc)=R(:);
                        end
                        % add X n Y
                        H{cc+1}='Ycoord';
                        H{cc+2}='Xcoord';
                        aux=repmat([1:size(R,1)]',[1,size(R,2)]);
                        colsI=[colsI,aux(:)];% add Y inds
                        aux=aux';
                        colsI=[colsI,aux(:)];% add Y inds
                        colsI(colsI(:,1)==0,:)=[];
                        colsI=real(colsI);
                        [out,out2]=find(isnan(colsI));
                        colsI(out,:)=[];
                        colsI=round(10000*colsI)/10000;
                        % figure,imagesc(Im1)
                        muntaCSV2([app.folderR filesep file extra '_pxData.' app.CoordsFmtDD.Value],',',H,colsI);

                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);

                    %% connected component csv
                    if(app.LabelCoords.Value==1)
                        if(isempty(app.MKL))
                            ensenya('No loaded mask!','r');
                        else
                        try
                            L=app.MKL{ii};
                            if(size(S1,1)~=size(L,1))
                                L=imresize(L,size(S1,1)/size(L,1),'nearest');
                            end
                            if(max(L(:))==1)
                                L=bwlabel(L,8);
                            end
                            NL=max(L(:));
                            Mags=[10:18,20:31];
                            H=cell(1,numel(Mags)+1);
                            H{1}='Id';
                            colsI=zeros(NL,numel(Mags)+1);
                            colsI(:,1)=[1:NL]';
                            cc=0;
                            out=unique([find(isnan(S1));find(isnan(G1))]);
                            LL=L;
                            LL(out)=0;
                            for jj=1:numel(Mags)
                                cc=cc+1;
                                if(Mags(jj)<20)
                                    if(Mags(jj)~=10)
                                        R = magnitude(S1,G1,Mags(jj)-11,app.freq,1);
                                    else
                                        if(app.relative.Value==1)
                                            R=Im1;
                                        else
                                            if(app.exportHR==0)
                                                auxim=app.IIa{ii};
                                            else
                                                auxim=app.IIo{ii};
                                            end
                                            fora=(size(auxim)-size(Im1))/2;
                                            auxim=auxim(fora(1)+1:end-fora(1),fora(2)+1:end-fora(2));
                                            R=auxim;
                                        end
                                    end
                                    LLi=LL;
                                    %exclude values from label here if theyre going to mess averages
                                    if(Mags(jj)==16)% tau phase makes no sense out of 1st quadrant
                                        LLi(S1<0)=0;LLi(G1<0)=0;
                                    end
                                    if(Mags(jj)==17)% tau modulation has complex values when mod>1
                                        LLi(imag(R)~=0)=0;
                                    end
                                    if(Mags(jj)==18)% tau norm only makes sens in 1st and 2nd Q
                                        LLi(S1<0)=0;
                                    end
                                    PR=regionprops(LLi,R,'MeanIntensity');
                                    % maybe obtain pixelvalues to compute std too
                                    H{cc+1}=['Mean' magnames{Mags(jj)-9}];
                                else
                                    PR=regionprops(L,morphnames{Mags(jj)-19});
                                    H{cc+1}=[morphnames{Mags(jj)-19}];
                                end
                                if(Mags(jj)<30)
                                    PR=cell2mat(struct2cell(PR))';
                                else
                                    PR2=cell2mat(struct2cell(PR))';
                                    if(Mags(jj)==30)
                                        H{cc+1}=[H{cc+1} 'X'];
                                        PR=PR2(1:2:end-1);
                                    end
                                    if(Mags(jj)==31)
                                        H{cc+1}=[H{cc+1} 'Y'];
                                        PR=PR2(2:2:end);
                                    end
                                end

                                colsI(:,cc+1)=PR;

                            end
                            colsI=real(colsI);
                            colsI=round(10000*colsI)/10000;
                            %%
                            muntaCSV2([app.folderR filesep file extra '_Labels.' app.LabelFmt.Value],',',H,colsI);
                            %% build reference label image
                            %                  only using a few chars, no need to find them every time
                            load('charset.mat','chars','index');
                            prev1=cat(2,chars{index=='I'},chars{index=='d'});
                            % prev2=chars{index=='X'};
                            chars{1}=chars{11};%put zero infront so we can call by index
                            L2=imresize(L,[size(chars{1},1)*100/size(L,1)],'nearest');
                            nouschars=cell(NL,1);
                            %
                            % generate new number set
                            deus=ceil(log10(NL));
                            Len=0;
                            for jj=1:NL
                                num=jj;
                                dig=floor(num/10^(deus-1));
                                aux=chars{dig+1};
                                for jj2=deus-1:-1:1
                                    num=num-dig*10^(jj2);
                                    dig=floor(num/10^(jj2-1));
                                    aux=[aux,chars{dig+1}];
                                end
                                Len=max(Len,size(aux,2));
                                nouschars{jj}=aux;
                            end
                            for jj=1:NL
                                df=Len-size(nouschars{jj},2);
                                if(df>0)
                                    nouschars{jj}=cat(2,nouschars{jj},zeros(15,df));
                                end
                            end
                            centres=regionprops(L2,'Centroid');
                            % L2=L2-imerode(L2,ones(3,3));
                            if(1)% random colors
                                cmap=superjet(22,'rgbymcauFloHe7tZsM}-hK');
                                cmap=repmat(cmap,[ceil(NL/size(cmap,1)) 1]);
                                cmap=[0 0 0;cmap];
                                if(size(cmap,1)>NL+1),cmap(NL+2:end,:)=[];end
                                L3=ind2rgb(L2+1,cmap);
                            else% red cells
                                L3=cat(3,L2,L2,L2);
                                L3(L3>1)=1;
                                L3(:,:,3)=0;
                                L3(:,:,2)=0;
                            end
                            cent=round(size(L2)/2);
                            tamtxt=size(nouschars{1});
                            ext1=size(prev1,2);
                            for jj=1:NL
                                etiqueta=[prev1,nouschars{jj}];
                                x=round(centres(jj).Centroid(1));
                                y=round(centres(jj).Centroid(2));
                                try
                                    if(x<cent(2))
                                        rgx=x:x+tamtxt(2)+ext1-1;
                                    else
                                        rgx=x-tamtxt(2)-ext1+1:x;
                                    end
                                    if(y<cent(1))
                                        rgy=y:y+tamtxt(1)-1;
                                    else
                                        rgy=y-tamtxt(1)+1:y;
                                    end
                                    %
                                    L3(rgy,rgx,:)=L3(rgy,rgx,:)+repmat(etiqueta,1,1,3);
                                end
                                %                     Lexp4=textIm(cluscoo(jj,2),cluscoo(jj,1),['X' niceDigits(jj,digN)],Lexp4,'fontsize',15,'horizontal',ha,'vertical',va);
                                % percentatge(jj,NL);
                            end
                            imwrite300(L3,[app.folderR filesep file extra '_LabRef.png']);
                            %%
                        catch
                            ensenya(['Warning: Skipping connected component data for ' file extra],'a');
                        end
                        end
                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);


                    % combo accumulated
                    sep=255*ones(round(size(Im1,1)*.02),size(Im1,2),3);
                    ImPrint=255*ones(1,size(Im1,2),3);% single line to keep concatenating
                    contaconcat=0;


                    % 1 intensity image
                    if(app.ExInt.Value==1)
                        if(contaconcat==0)
                            ImPrint=repmat(uint8(255*Im1),[1 1 3]);
                        else
                            ImPrint=cat(1,ImPrint,repmat(uint8(255*Im1),[1 1 3]));
                        end
                        contaconcat=contaconcat+1;
                        if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                            imwrite300(Im1,[app.folderR filesep file '_Int' extra '.' app.ExportImageFileDropDown.Value]);
                        end
                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);
                    % 2 magnitude
                    if(app.ExMagH.Value==1)
                        if(contaconcat==0)
                            ImPrint=I1;
                        else
                            ImPrint=cat(1,ImPrint,sep,I1);
                        end
                        contaconcat=contaconcat+1;
                        if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                            imwrite300(I1,[app.folderR filesep file '_PPI1' extra '.' app.ExportImageFileDropDown.Value]);
                        end
                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);
                    % 3 magnitude times im
                    if(app.ExMagS.Value==1)

                        if(contaconcat==0)
                            ImPrint=I2;
                        else
                            ImPrint=cat(1,ImPrint,sep,I2);
                        end
                        contaconcat=contaconcat+1;
                        if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                            imwrite300(I2,[app.folderR filesep file '_PPI2' extra '.' app.ExportImageFileDropDown.Value]);
                        end
                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);
                    % 4 cursor im
                    if(app.ExCurH.Value==1)
                        try
                            % reset mk and recreate (incase
                            % there is rainbow underneath)
                            app.MK{ii}=app.MK{ii}*0+1;
                            createMasksFromRois(app);
                            mk=app.MK{ii};
                            bk=find(sum(mk,3)==3);
                            mk(bk)=0;
                            mk(bk+numel(mk)/3)=0;
                            mk(bk+numel(mk)*2/3)=0;
                            mk=imresize(mk,size(ImPrint,2)/size(mk,2));
                            mk(mk>1)=1;

                            if(contaconcat==0)
                                ImPrint=uint8(255*mk);
                            else
                                ImPrint=cat(1,ImPrint,sep,uint8(255*mk));
                            end
                            contaconcat=contaconcat+1;
                            if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                                imwrite300(uint8(255*mk),[app.folderR filesep file '_PCI1' extra '.' app.ExportImageFileDropDown.Value]);
                            end
                        end
                    end
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);
                    % 5 cursor times im
                    if(app.ExCurS.Value==1)
                        try
                            % reset mk and recreate (incase
                            % there is rainbow underneath)
                            app.MK{ii}=app.MK{ii}*0+1;
                            createMasksFromRois(app);
                            mk=imresize(app.MK{ii},size(ImPrint,2)/size(app.MK{ii},2));
                            mk(mk>1)=1;

                            if(contaconcat==0)
                                ImPrint=uint8(255*Im1.*mk);
                            else
                                ImPrint=cat(1,ImPrint,sep,uint8(255*Im1.*mk));
                            end
                            contaconcat=contaconcat+1;
                            if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                                imwrite300(uint8(255*Im1.*mk),[app.folderR filesep file '_PCI2' extra '.' app.ExportImageFileDropDown.Value]);
                            end
                        end
                    end

                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);

                    % 6 phasor
                    if(app.ExPhasor.Value==1)&&(app.exportvec==0)
                        if(contaconcat==0)
                            ImPrint=ppprint;
                        else
                            ImPrint=cat(1,ImPrint,sep,imresize(ppprint,[round(size(ppprint,1))*size(ImPrint,2)/size(ppprint,2) size(ImPrint,2)]));
                        end
                        contaconcat=contaconcat+1;
                        if(app.Combine.Value==0)||((npans==1)&&(app.groupfiles.Value==0))
                            if(app.Expptrans.Value==1)% transparent png
                                [~,alp]=white2alpha(ppprint,.95,0);
                                imwrite(ppprint,[app.folderR filesep file '_PP' extra '.' app.ExportPhasorFileDropDown.Value],'alpha',alp);
                            else
                                imwrite300(ppprint,[app.folderR filesep file '_PP' extra '.' app.ExportPhasorFileDropDown.Value]);
                            end
                        end
                    end

                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);

                    % 7 combo
                    if(app.Combine.Value==1)&&(npans>0)
                        if(app.groupfiles.Value==1)
                            guarda{ii}=ImPrint;
                        else
                            imwrite300(ImPrint,[app.folderR filesep file '_combo' extra '.' app.ExportImageFileDropDown.Value]);
                        end
                    end
                    % percentatge(ii,numel(file));
                    cct=cct+1;d=barraprogress(app,((ii-1)+sum(trossos(1:cct)))/RR,d,[file extra]);
                    gg=1;
                end

                try
                    d=barraprogress(app,1,d,'Finishing');
                end
                % colorbar
                if(app.Cbar.Value==1)
                    try
                        % try
                        %     auxx=size(I2,1);
                        % catch
                        auxx=1000;
                        % end
                        % add edges set by user
                        currtaus=sort(unique([app.taus app.Cmin.Value app.Cmax.Value]));
                        cb=composeCbar(crangeinunity(app),app.MeasuredmagnitudeButtonGroup.SelectedObject.Text,auxx,app.RCM,app.freq,currtaus);
                        if(~isempty(cb))
                            imwrite300(cb,[app.folderR filesep 'colorbar.' app.ExportImageFileDropDown.Value]);
                        end
                    catch
                        ensenya('Magnitude not implemented for non-lifetime','a');
                    end
                end
                if(app.PhRb.Value==1)% plain bg rainbow
                    if(exist('IC','var')==0)% if no data is loaded
                        Srange=[app.Smin.Value app.Smax.Value];
                        Grange=[app.Gmin.Value app.Gmax.Value];
                        phsiz=[round(app.phasores*diff(Srange)/diff(Grange)) app.phasores];
                        fov=[Grange;Srange];

                        [type,centre] = definetype(app.MeasuredmagnitudeButtonGroup.SelectedObject.Text);
                        if(isempty(centre)),centre=[app.Gcenter.Value app.Scenter.Value];end
                        Crange=crangeinunity(app);
                        if(type==3)% centers are shifted by 1000 to use radial as linear
                            Crange=Crange+1000;
                            type=2;
                        end
                        [~,~,~,~,IC] = phasorRainbowIm(0,0,0,phsiz,fov,centre,1,Crange,app.RCM,1,type);
                    end
                    imwrite300(IC,[app.folderR filesep 'colorgradient.' app.ExportImageFileDropDown.Value]);
                end
                if(app.groupfiles.Value==1)
                    if(npans>0)
                        sz=size(guarda{rang(1)});
                        ord=rang;
                        if(app.fliprows.Value==1)
                            ord2=rang;
                            m=app.imagecols.Value;
                            for ii=rang
                                if(mod(ceil(ii/m),2)==0)
                                    ord2(ord==ii)=((ceil(ii/m)-1)*m)+(ceil(ii/m)*m)-ii+1;
                                end
                            end
                            ord=ord2;
                        end
                        cc=0;
                        for ii=1:app.imagerows.Value
                            for jj=1:app.imagecols.Value
                                cc=cc+1;
                                pp=[];
                                try
                                    pp=guarda{ord(cc)};
                                end
                                if(isempty(pp)),pp=255*ones(sz(1),sz(2),3);end
                                if(jj==1),PPr=pp;else,PPr=cat(2,PPr,pp);end
                            end
                            if(ii==1),PPT=PPr;else,PPT=cat(1,PPT,PPr);end
                        end

                        aux=numel(dir([app.folderR filesep 'ALL_combo*']));
                        for ii=1:1+aux
                            ex=niceDigits(ii);
                            nom=[app.folderR filesep 'ALL_combo' ex '.' app.ExportImageFileDropDown.Value];
                            if(exist(nom,'file')==0)
                                break;
                            end
                        end

                        imwrite300(PPT,nom);
                    end
                end
                if(numel(rang)>1)
                    FilterAndPlotPhasor(app);
                else
                    replotPP(app);
                end
                try
                    close(d);
                end
                ensenya('Finished','e');

            end
            app.running=0;
        end

        % Button pushed function: ExpPhWinButton
        function ExpPhWinButtonPushed(app, event)
            if(isempty(app.folderR))
                BrowseResultsButtonPushed(app);
            end
            auxfora=0;
            if(app.Botonera.Visible==1),auxfora=1;end
            if(auxfora==1),app.Botonera.Visible=0;end
            app.vlabel.Visible=0;
            app.coolab.Text='';% just in case
            if(app.exportvec==1)% vector
                exportgraphics(app.PP,[app.folderR filesep 'PhasorPlot.' app.ExportPhasorFileDropDown.Value],'Resolution',300);
            else% bitmap

                if(app.ExportType.Value==0)
                    [ppprint]=screencapture(app.GSLabUIFigure);
                    fpos=app.GSLabUIFigure.Position;
                    ppos=app.PhasorPanel.Position;
                    ppprint=ppprint(fpos(4)-ppos(2)-ppos(4)+4:fpos(4)-ppos(2),ppos(1)+1:ppos(1)+ppos(3)-2,:);
                else
                    [ppprint,~]=getframe(app.GSLabUIFigure,app.PhasorPanel.Position);
                    ppprint=ppprint(3:end,1:end-2,:);
                end

                %
                if(auxfora==1),app.Botonera.Visible=1;end

                if(app.axes.Value==0)
                    aux=sum(ppprint,3);
                    val=sum(ppprint(1,1,:));
                    auxx=aux(round(size(aux,1)/2),:);
                    auxy=aux(:,round(size(aux,2)/2));
                    rgx=find(auxx~=val,1,'first'):find(auxx~=val,1,'last');
                    rgy=find(auxy~=val,1,'first'):find(auxy~=val,1,'last');
                    ppprint=ppprint(rgy,rgx,:);
                end

                % achtung, takes bg color into account to turn it white
                % hopefully the color is not present in the plot
                aux=find(sum(ppprint,3)==720);
                ppprint(aux)=255;
                aux1=numel(ppprint)/3;
                ppprint(aux+aux1)=255;
                ppprint(aux+aux1*2)=255;


                aux=numel(dir([app.folderR filesep 'PhasorPlot*']));
                for ii=1:1+aux
                    ex=niceDigits(ii);
                    nom=[app.folderR filesep 'PhasorPlot' ex '.' app.ExportPhasorFileDropDown.Value];
                    if(exist(nom,'file')==0)
                        break;
                    end
                end


                if(app.Expptrans.Value==1)% transparent png
                    [~,alp]=white2alpha(ppprint,.95,0);
                    imwrite(ppprint,nom,'alpha',alp);
                else
                    imwrite300(ppprint,nom);
                end
            end
            app.vlabel.Visible=1;
            ensenya('Exported Phasor Plot','b');
        end

        % Button pushed function: ExpImWinButton
        function ExpImWinButtonPushed(app, event)

            if(isempty(app.folderR))
                BrowseResultsButtonPushed(app);
            end
            aux=numel(dir([app.folderR filesep 'ImageWindow*']));
            for ii=1:1+aux
                ex=niceDigits(ii);
                nom=[app.folderR filesep 'ImageWindow' ex '.' app.ExportImageFileDropDown.Value];
                if(exist(nom,'file')==0)
                    break;
                end
            end

            [im,~]=getframe(app.IWh);
            imwrite300(im,nom);
            ensenya('Exported Image Window','b');

        end

        % Value changed function: Expptrans
        function ExpptransValueChanged(app, event)
            if(app.Expptrans.Value==1)
                app.exportvec=0;
            end
            if(app.ExportPhasorFileDropDown.ValueIndex>1)
                app.ExportPhasorFileDropDown.ValueIndex=1;
            end
        end

        % Value changed function: ExportPhasorFileDropDown
        function ExportPhasorFileDropDownValueChanged(app, event)
            if(app.ExportPhasorFileDropDown.ValueIndex>1)
                app.Expptrans.Value=0;
            end
            if(app.ExportPhasorFileDropDown.ValueIndex<4)
                app.exportvec=0;
            else
                app.exportvec=1;
            end

        end

        % Value changed function: Excess
        function ExcessValueChanged(app, event)
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: freqEF
        function FreqEFValueChanged(app, event)
            % prev=app.freq;
            if(numel(unique(app.PD))>1)
                gg='Ok';
                % gg=questdlg({'Calibration must be reloaded. Clear?',' '},'','Ok','Cancel','Ok');
                gg=uiconfirm(app.GSLabUIFigure,{'Calibration must be reloaded.','Clear?'},'Warning', 'Options', {'Ok','Cancel'},'icon','warning');

                if(strcmp(gg,'Ok'))
                    clearCalib(app);
                    LoadCalibrationFile(app);
                    FilterAndPlotPhasor(app);
                    app.freq=app.freqEF.Value*1e6;
                else
                    app.freqEF.Value=app.freq*1e-6;
                end
            else
                app.freq=app.freqEF.Value*1e6;
                replotPP(app);
            end


        end

        % Value changed function: WavSupp
        function FilterWavSuppValueChanged(app, event)
            % dropdown is enabled, it cannot be sym or db
            app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,1)=app.WavSupp.ValueIndex;

            FilterAndPlotPhasor(app);
        end

        % Value changed function: PhasorfilterDropDown
        function FilterDropDownValueChanged(app, event)


            app.Eb.Limits=[1 Inf];
            [~,sups,~]=FiltNames();
            aux=sups{app.WaveletFamilyDropDown.ValueIndex};


            app.AnscombeCheckBox.Enable=0;

            % app.filtinds
            if(app.PhasorfilterDropDown.ValueIndex==3)


                app.Ti.Enable=1;app.Ti.Visible=1;
                app.TiLab.Text='Level';
                app.Ti.Tooltip='Wavelet levels to keep';

                app.WaveletFamilyDropDown.Enable=1;app.WaveletFamilyDropDown.Visible=1;
                app.AnscombeCheckBox.Enable=1;

                app.Ti.Value=app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,2);
                if(iscell(aux))
                    app.Filtermatch.Text='';
                    app.Eb.Enable=0;app.Eb.Visible=0;
                    app.Eb.Position=[20+app.WaveletFamilyDropDown.Position(1)+app.WaveletFamilyDropDown.Position(3) app.refpos(2:4)];
                    moveTi(app);
                    app.WavSupp.Enable=1;app.WavSupp.Visible=1;
                    app.WavSupp.Items=aux;
                    app.WavSupp.ValueIndex=app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,1);
                    FilterWavSuppValueChanged(app);
                else
                    app.Filtermatch.Text='Supp';app.Eb.Tooltip='Wavelet support';
                    app.WavSupp.Enable=0;app.WavSupp.Visible=0;
                    app.Eb.Enable=1;app.Eb.Visible=1;
                    app.Eb.Position=[2+app.WaveletFamilyDropDown.Position(1)+app.WaveletFamilyDropDown.Position(3) app.refpos(2:4)];
                    moveTi(app);
                    app.Eb.Value=app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,1);
                    FilterAndPlotPhasor(app);
                end

            end

            if(app.PhasorfilterDropDown.ValueIndex==1)% median
                app.TiLab.Text='Times';
                app.Filtermatch.Text=[' x ' num2str(app.filtinds(1,1))];
                app.Eb.Enable=1;app.Eb.Visible=1;app.Eb.Tooltip='Kernel size for median filter';
                app.Eb.Value=app.filtinds(1,1);
                app.Eb.Position=app.refpos;
                moveTi(app);
                app.Ti.Enable=1;app.Ti.Visible=1;
                app.Ti.Value=app.filtinds(1,2);
                app.Ti.Tooltip='Number of iterations';
                app.WaveletFamilyDropDown.Enable=0;app.WaveletFamilyDropDown.Visible=0;
                app.WavSupp.Enable=0;app.WavSupp.Visible=0;
                FilterAndPlotPhasor(app);
            end
            if(app.PhasorfilterDropDown.ValueIndex==2)% gaussian
                app.Eb.Limits=[0 Inf];
                app.Filtermatch.Text=[' px'];
                app.TiLab.Text='';
                app.Eb.Value=app.filtinds(2,1);
                app.Eb.Enable=1;app.Eb.Visible=1;app.Eb.Tooltip='Sigma for 2D gaussian filter';
                app.Eb.Position=app.refpos;
                moveTi(app);
                app.Ti.Enable=0;app.Ti.Visible=0;
                app.WaveletFamilyDropDown.Enable=0;app.WaveletFamilyDropDown.Visible=0;
                app.WavSupp.Enable=0;app.WavSupp.Visible=0;
                FilterAndPlotPhasor(app);
            end


        end

        % Value changed function: WaveletFamilyDropDown
        function FiltWaveletFamilyDropDownValueChanged(app, event)
            app.Eb.Limits=[1 Inf];

            if(strcmp(app.WaveletFamilyDropDown.Value,'Symlets')),app.Eb.Limits=[2 Inf];end
            [~,sups,~]=FiltNames();
            aux=sups{app.WaveletFamilyDropDown.ValueIndex};
            app.Ti.Value=app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,2);
            if(iscell(aux))
                app.Filtermatch.Text='';
                app.WavSupp.Enable=1;app.WavSupp.Visible=1;
                app.Eb.Enable=0;app.Eb.Visible=0;
                app.WavSupp.Items=aux;
                try
                    app.WavSupp.ValueIndex=app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,1);
                catch
                    % disp('y');
                    app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,1)=app.WavSupp.ValueIndex;
                end

                app.Eb.Position=[20+app.WaveletFamilyDropDown.Position(1)+app.WaveletFamilyDropDown.Position(3) app.refpos(2:4)];
                moveTi(app);
                FilterWavSuppValueChanged(app);
            else
                app.Filtermatch.Text='Supp';
                app.WavSupp.Enable=0;app.WavSupp.Visible=0;
                app.Eb.Enable=1;app.Eb.Visible=1;
                try
                    app.Eb.Value=app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,1);
                catch
                    % disp('y');
                    app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,1)=app.Eb.Value;
                end
                app.Eb.Position=[2+app.WaveletFamilyDropDown.Position(1)+app.WaveletFamilyDropDown.Position(3) app.refpos(2:4)];
                moveTi(app);
                FilterAndPlotPhasor(app);
            end

        end

        % Value changed function: Eb
        function FilterSizeValueChanged(app, event)
            if(app.PhasorfilterDropDown.ValueIndex==2)
                app.Eb.RoundFractionalValues=0;% if gaussian do not round
            else
                app.Eb.RoundFractionalValues=1;
            end

            if(app.PhasorfilterDropDown.ValueIndex<3)% not wavelet
                if(app.PhasorfilterDropDown.ValueIndex==1)% median
                    app.Filtermatch.Text=[' x ' num2str(app.Eb.Value)];
                end
                app.filtinds(app.PhasorfilterDropDown.ValueIndex,1)=app.Eb.Value;
                app.filtinds(app.PhasorfilterDropDown.ValueIndex,2)=app.Ti.Value;
            else
                app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,1)=app.Eb.Value;% since we are here it is enabled so this is one of sym or db
                app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,2)=app.Ti.Value;
            end
            FilterAndPlotPhasor(app);

        end

        % Value changed function: Ti
        function FilterTimesValueChanged(app, event)
            if(app.PhasorfilterDropDown.ValueIndex<3)% not wavelet
                app.filtinds(app.PhasorfilterDropDown.ValueIndex,2)=app.Ti.Value;
            else
                app.filtinds(app.WaveletFamilyDropDown.ValueIndex+2,2)=app.Ti.Value;
            end
            FilterAndPlotPhasor(app);
        end

        % Value changed function: Filterbefore
        function FilterbeforeValueChanged(app, event)
            if(app.PhasorfilterDropDown.ValueIndex>1)
                app.Filterbefore.Value=1;
            end
            FilterAndPlotPhasor(app);
        end

        % Value changed function: AFSef
        function FontSizeAxesValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: tickfontsize
        function FontSizeLineValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: GaussianFilter
        function GaussianFilterValueChanged(app, event)
            FilterAndPlotPhasor(app);
        end

        % Value changed function: groupfile
        function GroupfileValueChanged(app, event)
            if(app.groupfile.Value==1)
                app.mergecomps.Enable=0;
            else
                app.mergecomps.Enable=1;
            end
        end

        % Button pushed function: ApplyModelButton
        function GMMApplyButtonPushed(app, event)

            if(~isempty(app.GMfit))

                rang=checkedFiles(app);
                % alldata=[];
                %     for ii=rang
                %         aux=[app.SSi{ii}(:) app.GGi{ii}(:)];
                %         aux(isnan(aux(:,1)),:)=[];% remove nans
                %         alldata=[alldata;aux];
                %     end

                for ii=rang
                    IDX0=zeros(size(app.SSi{ii},1),size(app.SSi{ii},2),3);
                    df=numel(IDX0)/3;
                    dad=[app.SSi{ii}(:) app.GGi{ii}(:)];
                    inind=find(~isnan(dad(:,1)));
                    dad=dad(inind,:);% remove nans
                    if(app.hard.Value==1)
                        K=size(app.GMfit.mu,1);
                        IDX = cluster(app.GMfit,dad);
                        out=find(isnan(IDX));
                        IDX(out)=K+1;
                        aux=app.GMfitcolinds;
                        aux(K+1)=numel(app.CCM);
                        aux=app.colMks(aux(IDX),:);
                        IDX=IDX0;
                        IDX(inind)=aux(:,1);
                        IDX(inind+df)=aux(:,2);
                        IDX(inind+2*df)=aux(:,3);
                    else
                        probs=posterior(app.GMfit,dad);
                        cols=repmat(permute(app.colMks(app.GMfitcolinds,:),[3 1 2]),[size(probs,1) 1 1]);
                        probs=probs.*cols;
                        probs=sum(probs,2);
                        IDX=IDX0;
                        IDX(inind)=probs(:,:,1);
                        IDX(inind+df)=probs(:,:,2);
                        IDX(inind+2*df)=probs(:,:,3);
                    end
                    if(app.blend.Value==1)
                        IDX=imgaussfilt(IDX,app.blendamount.Value*size(IDX,1)/2000);
                    end
                    app.MK{ii}=IDX;
                end

                % replotPP(app);
                replotII(app);
            end
        end

        % Selection changed function: dispStyle2
        function GMMdispStyleChanged(app, event)
            GMMApplyButtonPushed(app);
        end

        % Selection changed function: dispStyle
        function GMMdisp2StyleChanged(app, event)
            replotPP(app);
        end

        % Value changed function: blendamount
        function GMMblendamountValueChanged(app, event)
            GMMApplyButtonPushed(app);
        end

        % Value changed function: gmmexport
        function GMMexportValueChanged(app, event)
            if(app.gmmexport.Value==1)
                app.ExMagH.Text='GMM-colored image';
                app.ExMagS.Text='GMM-colored x intensity';
                app.PhBg.Value=0;
            else
                app.ExMagH.Text='Magnitude-colored image';
                app.ExMagS.Text='Magnitude-colored x intensity';
            end
        end

        % Value changed function: blend
        function GMMblendeValueChanged(app, event)
            GMMApplyButtonPushed(app);
        end

        % Button pushed function: TrainModelButton
        function GMMTrainButtonPushed(app, event)
            app.PhBg.Value=0;
            rang=checkedFiles(app);
            if(numel(rang)>0)
                if(numel(unique(app.roidad))==1)&&(isempty(app.GMfit))
                    uialert(app.GSLabUIFigure,['Please add cursors to set # of clusters for GMM'],'Warning','icon','warning');
                else
                    app.running=1;
                    d=barraprogress(app,0,[],'Please wait...');
                    alldata=[];
                    for ii=rang
                        aux=[app.SSi{ii}(:) app.GGi{ii}(:)];
                        aux(isnan(aux(:,1)),:)=[];% remove nans
                        alldata=[alldata;aux];
                    end
                    options = statset('MaxIter',1500);

                    if(numel(unique(app.roidad))==1)
                        S.mu=app.GMfit.mu;% keep old mu to rematch
                        curind=app.GMfitcolinds;
                        K=size(app.GMfit.mu,1);
                        gmfit = fitgmdist(alldata,K,'CovarianceType','full','SharedCovariance',false,'Options',options);

                        startpoint=0;
                    else
                        curind=find(app.roidad(:,3)>0);
                        K=numel(curind);
                        S.mu=app.roidad(curind,1:2);
                        sig=zeros(2,2,K);
                        for ii=1:K
                            sig(:,:,ii)=[app.roidad(curind(ii),3) 0;0 app.roidad(curind(ii),3)];
                        end
                        S.Sigma=sig;
                        gmfit = fitgmdist(alldata,K,'Start',S,'CovarianceType','full','SharedCovariance',false,'Options',options);
                        startpoint=1;
                    end

                    % app.clust=0;

                    app.GMfit=gmfit;
                    % if(startpoint==0)
                    %     app.GMfitcolinds=curind;
                    % end% check distance to starting points to match user set colors
                    dd=zeros(size(S.mu,1)*size(S.mu,1),3);% save ii,jj and dist
                    cc=0;
                    for ii=1:size(app.GMfit.mu,1)
                        for jj=1:size(S.mu,1)
                            cc=cc+1;
                            dd(cc,1:2)=[ii jj];
                            dd(cc,3)=norm(app.GMfit.mu(ii,:)-S.mu(jj,:));
                        end
                    end
                    [~,ord]=sort(dd(:,3));
                    dd=dd(ord,:);
                    newcurind=curind;
                    for ii=1:numel(curind)
                        i2=dd(1,2);i1=dd(1,1);
                        newcurind(i1)=curind(i2);
                        dd(1,:)=[];
                        dd(dd(:,2)==i2,:)=[];
                        dd(dd(:,1)==i1,:)=[];
                    end
                    app.GMfitcolinds=newcurind;

                    XButtonPushed(app);
                    replotPP(app);
                    GMMApplyButtonPushed(app);
                    try, close(d);end
                    app.running=0;
                end
            end
        end

        % Value changed function: conf
        function GMMconfValueChanged(app, event)
            replotPP(app);
        end

        % Button pushed function: ClearModelButton
        function GMMclearButtonPushed(app, event)
            app.GMfit=[];
            app.GMfitcolinds=[];
            replotPP(app);
        end

        % Window scroll wheel function: GSLabUIFigure
        function GSLabUIFigureWindowScrollWheel(app, event)
            aux = event.VerticalScrollCount;
            xl=.01*diff(app.PP.XLim);
            if(aux>0)
                % app.radi=app.radi+1;if(app.radi>16),app.radi=16;end
                app.radi=app.radi+xl;
                if(app.radi>xl*100),app.radi=xl*100;end
                app.curs1.Radius=app.radi;
            else
                % app.radi=app.radi-1;if(app.radi<1),app.radi=1;end
                app.radi=app.radi-xl;
                if(app.radi<xl*.001),app.radi=xl*.001;end
                app.curs1.Radius=app.radi;
            end
            % app.GSLabUIFigure.PointerShapeCData=app.curs(:,:,app.radi);
            app.GSLabUIFigureWindowButtonMotion();
            % disp(rand);

        end

        % Window key press function: GSLabUIFigure
        function GSLabUIFigureWindowKeyPress(app, event)
            switch event.Key
                case 'f1', tabselector(app,[],[],1);
                case 'f2', tabselector(app,[],[],2);
                case 'f3', tabselector(app,[],[],3);
                case 'f4', tabselector(app,[],[],4);
                case 'f5', tabselector(app,[],[],5);
                case 'f6', tabselector(app,[],[],6);
                case 'f7', tabselector(app,[],[],7);
                case 'f9'

                case 'f10'
                    if(app.LoadFLAME31nBwithBCheckBox.Value==1)
                        app.LoadFLAME31nBwithBCheckBox.Value=0;
                        ensenya('Loading 31nB without Blue channel');
                    else
                        app.LoadFLAME31nBwithBCheckBox.Value=1;
                        ensenya('Loading 31nB with Blue channel');
                    end
                case 'f11'
                    [gg,gg2]=memory;
                    % ensenya(['Current loaded data: (' niceNums(gg.MemUsedMATLAB/gg.MemAvailableAllArrays) ')'],'e');
                    ensenya(['Current loaded data: (mem @' niceNums(1-gg2.PhysicalMemory.Available/gg2.PhysicalMemory.Total) ')'],'e');
                    cols=4;
                    N=zeros(app.LoadedIms,cols);
                    for ii=1:app.LoadedIms
                        N(ii,1)=max(N(ii,1),numel([app.nms{ii} app.nmsx{ii}]));
                        N(ii,2)=max(N(ii,2),numel(num2str(size(app.IIo{ii},1))));
                        N(ii,3)=max(N(ii,3),numel(num2str(size(app.IIo{ii},2))));
                        N(ii,4)=max(N(ii,4),numel(num2str(size(app.IIo{ii},3))));
                    end
                    N=max(N,[],1)+2;
                    for ii=1:app.LoadedIms
                        f1=[app.nms{ii} app.nmsx{ii} ];
                        fra=['    ' repmat(char(32),[1 N(1)-numel(f1)]) f1 ];
                        f1=num2str(size(app.IIo{ii},1));
                        fra=[fra ' : ' f1 '  x' repmat(char(32),[1 N(2)-numel(f1)])];
                        f1=num2str(size(app.IIo{ii},2));
                        fra=[fra f1 '  x' repmat(char(32),[1 N(3)-numel(f1)])];
                        f1=num2str(size(app.IIo{ii},3));
                        fra=[fra f1 repmat(char(32),[1 N(4)-numel(f1)])];
                        disp(fra)
                    end

                case 'f12'% used for debugging
                    % test33(app);
                    % fontsize(app.GSLabUIFigure,"increase");

                    % disp(app.keepPeakCounts);

                    %%

                    % aux=app.GSLabUIFigure.Position;
                    % disp('curr');
                    %                 disp(aux(3)-app.PhasorPanel.Position(1)-app.PhasorPanel.Position(3))
                    %                 disp(aux(4)-app.PhasorPanel.Position(2)-app.PhasorPanel.Position(4))
                    %
                    % saveParams(app);disp('y');
                    FilterAndPlotPhasor(app);
                case 'add', app.currcol=app.currcol+1;if(app.currcol>16),app.currcol=1;end;setcolors(app);
                case 'multiply',app.DM=1-app.DM;setcolors(app);
                case 'p',app.word='p';% restart pwd for 'pdf'
                otherwise
                    app.word=[app.word event.Key];
            end
            if(strcmp(app.word,'pdf')),exportManual(app);end
        end

        % Window button motion function: GSLabUIFigure
        function GSLabUIFigureWindowButtonMotion(app, event)

            if(app.running==0)
                if(app.autotab.Value==1)
                    cp=app.TabEixos.CurrentPoint;
                    if(cp(1,3)<=1)
                        aux=find(app.tabpos<cp(1,1),1,'last');
                        if(~isempty(aux))
                            if(aux~=app.TabGroup.SelectedTab.UserData)
                                tabselector(app,[],[],aux);
                            end
                        end
                    end
                end
                % change to phasor units
                app.imcur.XData=-1000;
                cp=app.PP.CurrentPoint;
                cp=cp(1,1:2);
                yn=0;
                if(cp(1)<app.Gmax.Value)&&(cp(1)>app.Gmin.Value)
                    if(cp(2)<app.Smax.Value)&&(cp(2)>app.Smin.Value)
                        lag=cp(1);
                        las=cp(2);
                        % app.coolab.Text=['$$\textsf{GS=(' niceNums(lag,2) ',' niceNums(las,2) '), }{\tau_{pnm}}\textsf{=(' niceNums(tp,2) ',' niceNums(tn,2) ',' niceNums(tm,2) ')}$$'];
                        if(app.LifetimeStyle.Value==1)
                            tp=magnitude(las,lag,5,app.freq,app.Harm.Value);
                            tn=magnitude(las,lag,7,app.freq,app.Harm.Value);
                            tm=magnitude(las,lag,6,app.freq,app.Harm.Value);
                            app.coolab.Text=['$$\rm \textsf{G=' niceNums(lag,2) ' S=' niceNums(las,2) ' }\tau_{p}\textsf{=' niceNums(tp,2) ' } \tau_{n}\textsf{=' niceNums(tn,2) ' }\tau_{m}\textsf{=' niceNums(tm,2) '}$$'];
                        elseif(app.SpectralStyle.Value==1)
                            app.coolab.Text=['$$\rm \textsf{G=' niceNums(lag,2) ' S=' niceNums(las,2) ' }$$'];
                        else
                            app.coolab.Text=['$$\rm ' app.currentMag '\textsf{='  niceNums(lag,2) ' I=' niceNums(las,2) ' }$$'];
                        end

                        yn=1;
                        app.lastyn=1;


                        app.GSLabUIFigure.Pointer='custom';
                        app.GSLabUIFigure.PointerShapeCData=app.blk;
                        % radiP=estimateCursorSize(app);
                        app.curs1.Center=cp;
                        radiP=app.curs1.Radius;
                        radiP=radiP*radiP;
                        % disp(radiP);
                        %                         rang=[];
                        % for ii=1:numel(app.Tree.CheckedNodes)
                        %     rang=[rang app.Tree.CheckedNodes(ii).NodeData];
                        % end

                        for ii=1:app.LoadedIms
                            S1=app.SSi{ii}-cp(2);
                            G1=app.GGi{ii}-cp(1);
                            blank=G1;blank(:)=1;
                            colmk=app.colMks(app.currCol,:);
                            aux=(S1.*S1)+(G1.*G1)-radiP<0;
                            % N=numel(aux);
                            % aux=find(aux==1);%indexes in 1chim
                            % app.MK{ii}(aux)=colmk(1);%R
                            % app.MK{ii}(aux+N)=colmk(2);%G
                            % app.MK{ii}(aux+2*N)=colmk(3);%B
                            for jj=1:3
                                aux0=blank;
                                aux0(aux==1)=colmk(jj);
                                app.MK{ii}(:,:,jj)=aux0;
                            end
                        end
                        % figure(55);clf;
                        % hh=axes();
                        % images.roi.Circle(hh,'center',cp,'color',app.colMks(app.currCol,:),'radius',sqrt(radiP));
                        % fov=[app.Gmin.Value app.Gmax.Value;app.Smin.Value app.Smax.Value];
                        % xlim(fov(1,:));ylim(fov(2,:));
                        % universalCircle(fov,'k',8e7);app.MKp
                        % axis equal;
                        createMasksFromRois(app);
                        if(app.LoadedIms>0)
                            replotII(app);
                        end

                    end
                end
                if(yn==0)
                    if(app.lastyn==1)
                        app.lastyn=0;
                        % disp('exit');
                        if(sum(app.roidad(:,3))==0)% no cursors
                            if(app.PhBg.Value==1)
                                app.MK=app.MKp;
                                replotII(app);
                            end
                        end
                    end
                    app.GSLabUIFigure.Pointer='arrow';
                    app.curs1.Center=[-1000 0];
                    app.coolab.Text='';
                    app.coolab.FontColor=app.FGcol;
                end
            end

        end

        % Close request function: GSLabUIFigure
        function GSLabUIFigureCloseRequest(app, event)
            saveParams(app);
            try close(app.IWh);end
            try close(app.PDh);end
            delete(app);
        end

        % Value changed function: Harm
        function HarmValueChanged(app, event)
            % FilterAndPlotPhasor(app);
            if(app.Harm.Value>1)
                app.Harm.FontColor=[1 0 0];
                app.Harm.FontWeight='bold';
            else
                app.Harm.FontColor=[0 0 0];
                app.Harm.FontWeight='normal';
            end
            FilterAndPlotPhasor(app);
        end

        % Button pushed function: hidecalib
        function hidecalibButtonPushed(app, event)
            if(app.CalibHolder.Visible==1)
                app.CalibHolder.Visible=0;
                app.hidecalib.Text='▴';
                % app.hidecalib.Position(2)=app.Tree.Position(2)+2;
            else
                app.CalibHolder.Visible=1;
                app.hidecalib.Text='▾';
                % app.hidecalib.Position(2)=app.CalibHolder.Position(2)+app.CalibHolder.Position(4)-app.Tree.Position(2)-1;
            end
        end

        % Value changed function: hdr
        function HDRValueChanged(app, event)
            if(app.hdr.Value==1)
                app.logint.Value=0;
                app.Islide.Value=app.Islide.Limits;
                IslideValueChanged(app);
            else
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
            end
        end

        % Value changed function: Imin
        function IminValueChanged(app, event)
            app.Islide.Value=[app.Imin.Value app.Imax.Value];
            updateTh(app);
        end

        % Value changed function: Imax
        function ImaxValueChanged(app, event)
            app.Islide.Value=[app.Imin.Value app.Imax.Value];
            updateTh(app);
        end

        % Value changed function: IntDispTop
        function IntDispTopValueChanged(app, event)
            d=barraprogress(app,0,[],'Computing intensity percentiles');

            for ii=1:app.LoadedIms
                Im1=sum(app.IIo{ii},3);
                app.keepPeakCounts(ii)=quantile(Im1(:),app.IntDispTop.Value);
                d=barraprogress(app,ii/app.LoadedIms,d,'Computing intensity percentiles');
            end
            try,close(d);end
            app.Imaxrange=max(app.keepPeakCounts);
            if(app.relative.Value==1)
                app.Imin.Limits=[0 1];
                app.Imax.Limits=[0 1];
                app.Islide.Limits(2)=1;
                app.Imax.Value=1;
                app.Imin.Value=0;
            else

                app.Imin.Limits=[0 ceil(app.Imaxrange)];
                app.Imax.Limits=[0 ceil(app.Imaxrange)];
                app.Islide.Limits(2)=ceil(app.Imaxrange);
                app.Imax.Value=ceil(app.Imaxrange);
                app.Imin.Value=0;
            end


            %%%
            app.ImaxValueChanged(app);
        end

        % Value changed function: Islide
        function IslideValueChanged(app, event)
            value = app.Islide.Value;
            if(app.relative.Value==1)
                app.Imin.Value=round(100*value(1))/100;
                app.Imax.Value=round(100*value(2))/100;
            else
                app.Imin.Value=round(value(1));
                app.Imax.Value=round(value(2));
            end
            updateTh(app);
        end

        % Value changed function: Invertmask
        function InvertmaskValueChanged(app, event)
            for ii=1:numel(app.MKL)% attention, masks can be conncomp labeled and inversion will mess up if they are
                % if(app.integrateRegions.Value==0)
                app.MKL{ii}=1-app.MKL{ii};
                % else
                % app.MKL{ii}=-app.MKL{ii}+1;
                % end
            end
            % app.Invertmask.Value=1-app.Invertmask.Value;
            mostramask(app);
            if(app.Applymask.Value==1)
            ApplymaskValueChanged(app);
            end
        end

        % Value changed function: imagecols
        function imagecolsValueChanged(app, event)
            imagewindowtileValueChanged(app);
        end

        % Value changed function: imagerows
        function imagerowsValueChanged(app, event)
            if(app.imagerows.Value*app.imagecols.Value<app.LoadedIms)
                app.imagecols.Value=ceil(app.LoadedIms/app.imagerows.Value);
            end
            imagewindowtileValueChanged(app);
        end

        % Value changed function: imagewindowtile
        function imagewindowtileValueChanged(app, event)
            if(app.imagewindowtile.Value==1)
                app.imagecols.Enable=1;
                app.imagerows.Enable=1;
                app.tilinglabel.Enable=1;
            else
                app.imagecols.Enable=0;
                app.imagerows.Enable=0;
                app.tilinglabel.Enable=0;
            end

            createImageFigure(app);
            replotII(app);
        end

        % Button pushed function: LoadCursorsButton
        function LoadCursorsButtonPushed(app, event)
            [filepath] = uigetFiles('Select cursor file',1);
            figure(app.GSLabUIFigure);
            if(~isempty(filepath))
                try

                    aux=whos('-file',filepath);
                    if(strcmp(aux(1).name,'roidad'))
                        for ii=1:numel(app.curhan)
                            delete(app.curhan(ii));
                        end
                        clearMasks(app);
                        load(filepath,'roidad');
                        app.roidad=roidad;
                        app.currCol=find(roidad(:,3)>0,1,'last')+1;
                        if(app.currCol>numel(app.CCM)),app.currCol=1;end
                        app.curs1.Color=app.colMks(app.currCol,:);
                        % paintRois(app);
                        FilterAndPlotPhasor(app);
                        app.Botonera.Visible=1;
                    else
                        error();
                    end
                catch
                    uialert(app.GSLabUIFigure,['No cursors in file!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: LoadTIFSequenceContextMenu
        function LoadTIFSequenceContextMenuSelected(app, event)
            [filepaths] = uigetFiles('Select tif file(s)',1,1,{'*.tif;*.tiff'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadTifSeqFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    AutoIrange(app);
                    % createImageFigure(app);
                    % FilterAndPlotPhasor(app);
                    plotHists(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: LoadFLAMErawContextMenu
        function LoadFLAMErawContextMenuSelected(app, event)
            % BrowseDataButtonPushed(app);
            aux = uigetDirs('Select folder containing image folders',1);
            figure(app.GSLabUIFigure);
            if(~isempty(aux))
                app.folderD=aux;
                imsbefore=app.LoadedIms;
                LoadFlameRawFun(app);

                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    AutoIrange(app);
                    saveParams(app);
                    % createImageFigure(app);
                    % FilterAndPlotPhasor(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: LoadSimFCSrefContextMenu
        function LoadSimFCSrefContextMenuSelected(app, event)

            % delete(app.Tree.Children);

            [filepaths] = uigetFiles('Select ref file(s)',1,1,{'*.R64;*.ref;*.re1;*.re3;*.re4;*.re5;*.re6;*.re7;*.re8'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                % if(app.relative.Value==0),app.relative.Value=1;relativeValueChanged(app);end
                % app.relative.Value=1;
                LoadSimFCSrefFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    AutoIrange(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: LoadFALCONSnGContextMenu
        function LoadFALCONSnGContextMenuSelected(app, event)

            %
            filepaths = uigetDirs('Select FALCON folder',1);
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadFalconSnGFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    AutoIrange(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end

            end
        end

        % Menu selected function: FLIMLABSjsonMenu
        function LoadFLIMlabsjsonMenuSelected(app, event)

            % delete(app.Tree.Children);
            [filepaths] = uigetFiles('Select Phasors - Imaging .json file(s)',1,1,{'*.json'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadFLIMlabsFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    AutoIrange(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: PicoQuantptuMenu
        function LoadPicoQuantptuMenuSelected(app, event)

            % delete(app.Tree.Children);
            [filepaths] = uigetFiles('Select PicoQuant file(s)',1,1,{'*.ptu'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadCGohlkeFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    AutoIrange(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: BeckerHicklsdtMenu
        function LoadBeckerHicklsdtMenuSelected(app, event)

            % delete(app.Tree.Children);
            [filepaths] = uigetFiles('Select Becker n Hickle file(s)',1,1,{'*.sdt'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadCGohlkeFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    AutoIrange(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: LoadZeisslsmContextMenu
        function LoadZeisslsmContextMenuSelected(app, event)
            [filepaths] = uigetFiles('Select lsm file(s)',1,1,{'*.lsm'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadZeissLSMFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    app.Style.SelectedObject=app.SpectralStyle;
                    saveParams(app);
                    StyleSelectionChanged(app,1);% extraflag to prevent replotting
                    AutoIrange(app);                    
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: TracetxtcsvMenu
        function LoadTraceContextMenuSelected(app, event)
            [filepaths] = uigetFiles('Select trace file(s)',1,1,{'*.txt;*.csv'});
            figure(app.GSLabUIFigure);
            if(~isempty(filepaths))
                imsbefore=app.LoadedIms;
                LoadTraceFun(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    AutoIrange(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);
                else
                    uialert(app.GSLabUIFigure,['No data loaded!'],'Warning','icon','warning');
                end
            end
        end

        % Menu selected function: SavedSessionMenu
        function LoadSavedSessionMenuSelected(app, event)
            [filepaths] = uigetFiles('Select file session',1,0,{'*.mat'});
            ClearAllContextSelected(app);
            figure(app.GSLabUIFigure);
            imsbefore=app.LoadedIms;
            if(~isempty(filepaths))
                applyUserSettings(app,filepaths);
                if(app.LoadedIms>imsbefore)% we have accumulated some data
                    saveParams(app);
                    StyleSelectionChanged(app,1); % extra 1 to prevent replot
                    AutoIrange(app);
                    plotHists(app);
                    SourceButtonGroupSelectionChanged(app);                    
                else
                    ensenya('Finished','e');
                end
            end
        end

        % Button pushed function: LoadmaskButton
        function LoadmaskButtonPushed(app, event)
            if(app.LoadedIms==0)
                uialert(app.GSLabUIFigure,['Load data before masking please!'],'Warning','icon','warning');
            else
                [filepath] = uigetFiles('Select mask file',1,0,{'*.png;*.tif'});
                figure(app.GSLabUIFigure);
                if(~isempty(filepath))
                    try
                        if(strcmp(filepath(end-2:end),'png'))
                            I=imread(filepath);
                            %
                            if(size(I,3)>1)
                                I=sum(double(I),3)/3;
                            else% in case indexed images and colors are sorted in a wierd way
                                [I,cm]=imread(filepath);
                                if(~isempty(cm))
                                    I=ind2rgb(double(I),cm);
                                    I=sum(I,3);
                                end
                            end
                            if(numel(unique(I))>2)&&((min(I(:))==0)&&(max(I(:))>1))
                                ensenya('Mask is not binary, treating as label image','a');
                                app.labeledmk=1;
                                % ensure the label for backgound is 0 (assume bg is most common value in image edges)
                                aux=I;aux=[aux(:,1);aux(:,end);aux(1,:)';aux(end,:)'];
                                vv=unique(aux);mx=-Inf;quin=0;
                                for ii=1:numel(vv)
                                    aux1=numel(find(aux==vv(ii)));
                                    if(aux1>mx)
                                        mx=aux1;
                                        quin=ii;
                                    end
                                end
                                zer=min(I(:));
                                I(I==vv(quin))=-1;
                                I(I==zer)=vv(quin);
                                I(I==-1)=zer;
                                % ensure integer continuous values
                                NL=unique(I);
                                for ii=1:numel(NL)
                                    I(I==NL(ii))=ii-1;
                                end
                            else
                                app.labeledmk=0;
                                I=NormArray(I);
                                if(max(I(:))>1),I=I>.5;end
                            end
                            % rang=checkedFiles(app);
                            for ii=1:app.LoadedIms
                                if(isempty(app.MKL)||(app.overwritemask.Value==1))
                                    app.MKL{ii}=double(I);
                                else
                                    try % size(app.MKL{ii}) size(I) gg=imresize(double(I),size(app.MKL{ii})).*app.MKL{ii}
                                        app.MKL{ii}=imresize(double(I),size(app.MKL{ii}),'nearest').*app.MKL{ii};
                                    catch
                                        ensenya('ERROR: Could not load as product, overwriting instead.','r')
                                        app.MKL{ii}=double(I);
                                    end
                                end
                            end
                            % if(isempty(rang))
                            %     app.MKL{1}=I;
                            % end
                            ensenya('Warning: Applying 1 mask to all images','a');
                        end
                        if(strcmp(filepath(end-2:end),'tif'))
                            nn=numel(imfinfo(filepath));
                            rang=checkedFiles(app);
                            if(nn>numel(rang))
                                ensenya(['Warning: More masks than selected files! Loading only first ' num2str(numel(rang)) ],'a');
                            end
                            if(nn<numel(rang))
                                ensenya(['Warning: Less masks than selected files! '],'a');
                            end
                            labeled=0;
                            I=imread(filepath,1);
                            if(numel(unique(I))>2)&&((min(I(:))==0)&&(max(I(:))>1))
                                labeled=1;
                                ensenya('Mask is not binary, treating as label image','a');
                            end
                            for ii=1:min(nn,numel(rang))
                                I=imread(filepath,ii);
                                if(labeled==1)
                                    app.labeledmk=1;
                                    % ensure the label for backgound is 0 (assume bg is most common value in image edges)
                                    aux=I;aux=[aux(:,1);aux(:,end);aux(1,:)';aux(end,:)'];
                                    vv=unique(aux);mx=-Inf;quin=0;
                                    for jj=1:numel(vv)
                                        aux1=numel(find(aux==vv(jj)));
                                        if(aux1>mx)
                                            mx=aux1;
                                            quin=jj;
                                        end
                                    end
                                    zer=min(I(:));
                                    I(I==vv(quin))=-1;
                                    I(I==zer)=vv(quin);
                                    I(I==-1)=zer;
                                    % ensure integer continuous values
                                    NL=unique(I);
                                    for jj=1:numel(NL)
                                        I(I==NL(jj))=jj-1;
                                    end
                                else
                                    if(size(I,3)>1),I=sum(I,3)/3;end
                                    if(max(I(:))>1),I=I>128;end% assume uint8
                                end
                                if(isempty(app.MKL)||(app.overwritemask.Value==1))
                                    app.MKL{rang(ii)}=double(I);
                                else
                                    try
                                        app.MKL{rang(ii)}=double(I).*app.MKL{rang(ii)};
                                    catch
                                        ensenya('ERROR: Could not load as product, overwriting instead.','r')
                                        app.MKL{rang(ii)}=double(I);
                                    end
                                end

                            end
                        end

                        % FilterAndPlotPhasor(app);
                        mostramask(app);
                        ApplymaskValueChanged(app);
                        app.integrateRegions.Enable=1;
    
                    catch
                        uialert(app.GSLabUIFigure,['Error loading file!'],'Warning','icon','error');
                    end
                end
            end
        end

        % Value changed function: logint
        function LogIntValueChanged(app, event)
            if(app.logint.Value==1)
                app.hdr.Value=0;
            end
            if(app.LoadedIms>0)
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: Logp
        function LogpValueChanged(app, event)

            plotHists(app);

        end

        % Value changed function: UCcb
        function LinesCheckBoxValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: logDens
        function logDensValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: HarmLim
        function HarmLimValueChanged(app, event)

            if(app.LoadedIms>0)&&(app.preval<app.HarmLim.Value)
                gg='Ok';
                % gg=questdlg({'Data must be reloaded to recompute harmonics. Clear everything?',' '},'','Ok','Cancel','Ok');
                gg=uiconfirm(app.GSLabUIFigure,{'Data must be reloaded to recompute harmonics. Clear everything?'},'Warning', 'Options', {'Ok','Cancel'},'icon','warning');
                if(strcmp(gg,'Ok'))
                    clearCalib(app);
                    ClearAllContextSelected(app);
                    LoadCalibrationFile(app);
                else
                    app.HarmLim.Value=app.preval;
                end
            end
            app.preval=app.HarmLim.Value;
            app.Harm.Limits(2)=app.HarmLim.Value;

        end

        % Selection changed function: MeasuredmagnitudeButtonGroup
        function MagnitudeButtonGroupSelectionChanged(app, event)
            % app.MeasuredmagnitudeButtonGroup.SelectedObject.Text
            if((app.CustomAngular.Value==1)||(app.CustomRadial.Value==1))
                app.Gcenter.Enable='on';
                app.Scenter.Enable='on';
                app.CenterLabel.Enable='on';
                if(app.CustomAngular.Value==1)
                    app.currentMag='\phi';unitats='rad';
                else
                    app.currentMag='M';unitats='phasor units';
                end
            else
                app.Gcenter.Enable='off';
                app.Scenter.Enable='off';
                app.CenterLabel.Enable='off';
                switch app.MeasuredmagnitudeButtonGroup.SelectedObject.Text
                    case 'S',app.currentMag='S';unitats='phasor units';
                    case 'G',app.currentMag='G';unitats='phasor units';
                    case 'Phase',app.currentMag='\phi';unitats='rad';
                    case 'Mod',app.currentMag='M';unitats='phasor units';
                    case 'NormPhase',app.currentMag='\phi_{n}';unitats='rad';
                    case 'TauPhase',app.currentMag='\tau_{\phi}';unitats='ns';
                    case 'TauMod',app.currentMag='\tau_{M}';unitats='ns';
                    case 'TauNorm',app.currentMag='\tau_{n}';unitats='ns';
                end
            end
            if(strcmp(unitats,'ns')&&(strcmp(app.Style.SelectedObject.Text,'Spectral')))
                unitats='nm';
            end
            if(app.NormColunits.Value==0)
                app.CLabel.Text=['$$\rm ' app.currentMag '$$'];
                app.CLabel.Interpreter = 'latex';
                app.CLabel.Tooltip='Magnitude for color range';
                app.Cmin.Tooltip=['Lower limit for colormap in ' unitats];
                app.Cmax.Tooltip=['Upper limit for colormap in ' unitats];
            else
                app.CLabel.Text=['$$\rm (u)$$'];
                app.CLabel.Interpreter = 'latex';
                switch app.MeasuredmagnitudeButtonGroup.SelectedObject.Text
                    case {'S','G','Mod','TauMod','CustomRadial'}
                        app.Cmin.Tooltip=['Lower limit for colormap in phasor units'];
                        app.Cmin.Tooltip=['Upper limit for colormap in phasor units'];
                        app.CLabel.Tooltip='Color range in phasor units';
                    case {'Phase','NormPhase','TauPhase','TauNorm','CustomAngular'}
                        app.Cmin.Tooltip=['Lower limit for colormap in units over unit circle'];
                        app.Cmin.Tooltip=['Upper limit for colormap in units over unit circle'];
                        app.CLabel.Tooltip='Color range in units over unit circle';
                end
            end

            if(app.IvMagStyle.Value==1)
                app.SLabel.Text='I';
                app.GLabel.Interpreter = 'latex';
                app.GLabel.Text=['$$\rm ' app.currentMag '$$'];
                % app.GrangeLabel.FontSize=10;
            else
                app.SLabel.Text='S';
                app.GLabel.Text='G';
            end

            if(app.LoadedIms>0)
                app.PhBg.Value=1;
                AutoColor(app);
            else
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: modS
        function modSValueChanged(app, event)
            if(app.DoCalib.Value==1)
                DoCalibValueChanged(app);
            end
        end

        % Menu selected function: TopMenu
        function MoveTopMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                new=1;
                moveTreeElement(app,quin,new,'before');
            else
                % warndlg('Select a file to move it!','Warning') ;
                uialert(app.GSLabUIFigure,'Select a file to move it!','Warning','Icon','Warning') ;
            end
        end

        % Menu selected function: UpMenu
        function MoveUpMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                new=quin-1;if(new==0),new=1;end
                moveTreeElement(app,quin,new,'before');
            else
                % warndlg('Select a file to move it!','Warning') ;
                uialert(app.GSLabUIFigure,'Select a file to move it!','Warning','Icon','Warning') ;
            end
        end

        % Menu selected function: DownMenu
        function MoveDownMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                new=quin+1;if(new>app.LoadedIms),new=app.LoadedIms;end
                moveTreeElement(app,quin,new,'after');
            else
                % warndlg('Select a file to move it!','Warning') ;
                uialert(app.GSLabUIFigure,'Select a file to move it!','Warning','Icon','Warning') ;
            end
        end

        % Menu selected function: BotMenu
        function MoveBotMenuSelected(app, event)
            if(~isempty(event.ContextObject.SelectedNodes))
                quin=event.ContextObject.SelectedNodes.NodeData;
                new=app.LoadedIms;
                moveTreeElement(app,quin,new,'after');
            else
                % warndlg('Select a file to move it!','Warning') ;
                uialert(app.GSLabUIFigure,'Select a file to move it!','Warning','Icon','Warning') ;
            end
        end

        % Menu selected function: EvenOddMenu
        function MoveEvenOdd(app, event)
            rang=sort([2:2:app.LoadedIms],'descend');
            d=barraprogress(app,0,[],'Rearranging...');
            NN=numel(rang);
            for ii=1:NN% ii=3
                moveTreeElement(app,rang(ii)+ii-1,1,'before');
                d=barraprogress(app,ii/NN,d,'Rearranging...');
            end
            pause(.5);
            try
                close(d);
            end
        end

        % Value changed function: NormColunits
        function NormColunitsValueChanged(app, event)
            if(app.NormColunits.Value==1)
                Crange=crangeinunity(app,1);
                app.Cmin.Value=Crange(1);
                app.Cmax.Value=Crange(2);
            else
                [Crange] = crangefromunity(app);
                app.Cmin.Value=Crange(1);
                app.Cmax.Value=Crange(2);
            end
            MagnitudeButtonGroupSelectionChanged(app);
        end

        % Value changed function: normFile
        function NormFileValueChanged(app, event)
            replotPP(app);
        end

        % Button down function: PP
        function PPButtonDown(app, event)
            if(event.Button==1)% left click
                app.Botonera.Visible=1;
                radiP=app.radi;
                centre=app.PP.CurrentPoint(1,1:2);
                roi = images.roi.Circle(app.PP,'center',centre,'color',app.colMks(app.currCol,:),'radius',radiP);
                roi.InteractionsAllowed='none';
                app.curhan=[app.curhan roi];
                app.roidad(app.currCol,1:3)=[centre radiP];
                app.currCol=app.currCol+1;
                if(app.currCol>numel(app.CCM)),app.currCol=1;end
                app.curs1.Color=app.colMks(app.currCol,:);
                if(app.CursorButton.Value==1)
                    SourceButtonGroupSelectionChanged(app);
                end
            end
            if(event.Button==3)% right click
                FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: PhBg
        function PhBgValueChanged(app, event)
            if(app.gmmexport.Value==1)
                app.gmmexport.Value=0;
            end
            FilterAndPlotPhasor(app);

        end

        % Button pushed function: PlotTrueSize
        function PlotTrueSizeButtonPushed(app, event)
            aux1=app.IIa;
            aux2=app.SSa;
            aux3=app.GGa;
            for ii=1:app.LoadedIms
                app.IIa{ii}=sum(app.IIo{ii},3);
            end
            app.SSa=app.SSo;
            app.GGa=app.GGo;
            if(app.DoCalib.Value==1)
                for ii=1:app.LoadedIms
                    for hh=1:app.HarmLim.Value
                        S1=app.SSa{ii,hh};
                        G1=app.GGa{ii,hh};
                        % fill nans before resizing (otherwise only nearest algorithm works)!
                        % S1=phasorFillNaN2(S1);
                        % G1=phasorFillNaN2(G1);
                        % calib
                        if(app.calibflag(ii)>0)
                            pha=atan2(S1,G1)+app.PD(app.calibflag(ii),hh);
                            modu=sqrt((S1.*S1)+(G1.*G1))*app.MF(app.calibflag(ii),hh);
                            S1=modu.*sin(pha);G1=modu.*cos(pha);
                        end
                        app.SSa{ii,hh}=S1;
                        app.GGa{ii,hh}=G1;
                    end
                end
            end

            FilterAndPlotPhasor(app);
            app.IIa=aux1;
            app.SSa=aux2;
            app.GGa=aux3;
        end

        % Value changed function: PhasorresEditField
        function PhasorresEditFieldValueChanged(app, event)
            app.phasores = app.PhasorresEditField.Value;
            FilterAndPlotPhasor(app);
        end

        % Value changed function: phaS
        function phaSValueChanged(app, event)
            if(app.DoCalib.Value==1)
                DoCalibValueChanged(app);
            end
        end

        % Value changed function: PromptFLIMlabsloadoptionCheckBox
        function PromptFLIMlabsloadoptionCheckBoxValueChanged(app, event)
            if(app.PromptFLIMlabsloadoptionCheckBox.Value==1)
                app.LoadFLIMlabsrawCheckBox.Enable=0;
            else
                app.LoadFLIMlabsrawCheckBox.Enable=1;
            end

        end

        % Value changed function: relative
        function relativeValueChanged(app, event)
            for ii=1:app.LoadedIms
                app.hdrkeep{ii}=[];
            end
            if(app.relative.Value==1)
                app.Imax.Value=round(100*app.Imax.Value/app.Imaxrange)/100;
                app.Imin.Value=round(100*app.Imin.Value/app.Imaxrange)/100;
                app.Imax.Limits=[0 1];
                app.Imin.Limits=[0 1];
                app.Imax.ValueDisplayFormat='%.2f';
                app.Imin.ValueDisplayFormat='%.2f';
                app.Islide.Limits(2)=1;
                app.Islide.Value=[app.Imin.Value app.Imax.Value];
            else

                app.Imax.Limits=[0 ceil(app.Imaxrange)];
                app.Imin.Limits=[0 app.Imaxrange];
                app.Imax.ValueDisplayFormat='%.0f';
                app.Imin.ValueDisplayFormat='%.0f';
                app.Islide.Limits(2)=ceil(app.Imaxrange);
                try
                    app.Imax.Value=round(app.Imax.Value*app.Imaxrange);
                    app.Imin.Value=round(app.Imin.Value*app.Imaxrange);
                    app.Islide.Value=[app.Imin.Value app.Imax.Value];
                end

            end
            if(app.LoadedIms>0)
                plotHists(app);
                FilterAndPlotPhasor(app);
                % replotII(app);

            end
        end

        % Button pushed function: SaveCursorsButton
        function SaveCursorsButtonPushed(app, event)
            if(numel(unique(app.roidad))==1)
                uialert(app.GSLabUIFigure,['No cursors to save!'],'Warning','icon','warning');
            else
                [name,folder] = uisaveFile('Save current cursors to file');
                if(ischar(name))
                    roidad=app.roidad;
                    save([folder name],'roidad');
                end
            end
        end

        % Menu selected function: asSimFCSrefMenu
        function SaveSimFCSrefContextSelected(app, event)
            if(numel(app.nms)==0)
                uialert(app.GSLabUIFigure,['No data to save!'],'Warning','icon','warning');
            else

                [folder] = uigetDirs('Select output folder',1);
                d=barraprogress(app,0,[],'Saving...');
                ensenya('Saving');
                rang=checkedFiles(app);
                for ii=rang
                    ima=sum(app.IIo{ii},3);
                    if(size(app.SSo,2)~=app.HarmLim.Value)
                        ensenya('Warning:  Issue with harmonic logging - contact Alex','a');
                        % errordlg("Ref files require 2 harmonics!");break;
                    end

                    % force square ims
                    if(size(ima,1)~=size(ima,2))
                        rg=[1:min(size(ima))];
                        vec=ima(rg,rg);vec=vec(:);
                    else
                        vec=ima(:);
                    end
                    for hh=1:app.HarmLim.Value
                        S0=app.SSo{ii,hh};%S0=phasorFillNaN2(S0);
                        G0=app.GGo{ii,hh};%G0=phasorFillNaN2(G0);
                        if(size(ima,1)~=size(ima,2))
                            S0=S0(rg,rg);G0=G0(rg,rg);
                        end
                        if(app.DoCalib.Value==1)
                            if(app.calibflag(ii)>0)
                                pha=atan2(S0,G0)+app.PD(app.calibflag(ii),hh);
                                modu=sqrt((S0.*S0)+(G0.*G0))*app.MF(app.calibflag(ii),hh);
                                S0=modu.*sin(pha);G0=modu.*cos(pha);
                            end
                        end
                        vec=[vec;180*atan2(S0(:),G0(:))/3.1416;sqrt(S0(:).*S0(:)+G0(:).*G0(:))];
                    end
                    fmt='ref';
                    if(app.HarmLim.Value~=2)
                        fmt=['re' num2str(app.HarmLim.Value)];
                    end
                    pumpum = fopen([folder filesep app.nms{ii} app.nmsx{ii} '.' fmt],'wb');
                    fwrite(pumpum,vec,'single');
                    fclose(pumpum);
                    d=barraprogress(app,ii/numel(app.nms),d,'Saving...');
                end
                d=barraprogress(app,1,d,'Saving...');
                pause(.1);
                try
                    close(d);
                end
            end

            ensenya('Finished!','e');
        end

        % Button pushed function: SavemaskButton
        function SavemaskButtonPushed(app, event)
            try
                rang=checkedFiles(app);
                if(numel(rang)==1)
                    [name,folder] = uisaveFile('Save current mask to file','*.png');
                    imwrite300(app.MKL{rang(1)},[folder name]);
                end
                if(numel(rang)>1)
                    [name,folder] = uisaveFile('Save current mask to file','*.tif');
                    for ii=1:numel(rang)
                        if(ii==1)
                            imwrite300(app.MKL{rang(ii)},[folder name]);
                        else
                            imwrite(app.MKL{rang(ii)},[folder name],'WriteMode','append');
                        end
                    end
                end
            end
        end

        % Menu selected function: CurrentSessionMenu
        function SaveCurrentSessionMenuSelected(app, event)
            [name,folder] = uisaveFile('Save current session to file');
            if(ischar(name))
                saveParams(app,[folder name]);
            end
        end

        % Value changed function: singleimage
        function singleimageValueChanged(app, event)
            if(app.singleimage.Value==1)&&(app.singleimage.Enable==1)
                app.addmargins.Enable=1;
                app.groupfile.Enable=1;
                app.mergecomps.Enable=1;
            else
                app.addmargins.Enable=0;
                app.groupfile.Enable=0;
                app.mergecomps.Enable=0;
            end
        end

        % Value changed function: SoftCursor
        function SoftCursorValueChanged(app, event)
            replotII(app);
        end

        % Selection changed function: SourceButtonGroup
        function SourceButtonGroupSelectionChanged(app, event)
            if(app.FileButton.Value==1)
                app.ComponentTree.Visible=1;
                app.ComponentTree.Enable=1;
                delete(app.ComponentTree.Children);
                for ii=1:numel(app.Tree.Children)
                    aux=uitreenode(app.ComponentTree,'Text',app.Tree.Children(ii).Text,'NodeData',app.Tree.Children(ii).NodeData);
                end
                app.compNum=app.compNumE;
                for ii=1:8
                    h=app.comphans(ii,2);
                    set(h,'Visible',0);
                end
            end
            if(app.InputButton.Value==1)
                app.ComponentTree.Visible=0;
                app.compNum=app.compNumT;
                app.plusB.Enable=1;
                app.minusB.Enable=1;

                for ii=1:8
                    h=app.comphans(ii,2);
                    set(h,'Visible',0);
                end
                for ii=1:app.compNum
                    h=app.comphans(ii,2);
                    set(h,'Visible',1);
                end
                componentLifeChange(app,[],[],[1:app.compNum]);
            end
            if(app.CursorButton.Value==1)
                for ii=1:8
                    h=app.comphans(ii,2);
                    set(h,'Visible',0);
                end
                app.ComponentTree.Visible=1;
                app.ComponentTree.Enable=0;
                delete(app.ComponentTree.Children);

                inds=find(app.roidad(:,3)>0);
                app.compNum=numel(inds);
                if(app.compNum>3),app.compNum=3;end
                for ii=1:app.compNum
                    aux=uitreenode(app.ComponentTree,'Text',['Cursor #' num2str(ii)]);
                end
                try
                    app.ComponentTree.CheckedNodes=app.ComponentTree.Children;
                end
                componentTreeColors(app);
                componentLifeChange(app,[],[],[1:app.compNum]);
            end


        end

        % Value changed function: ShowComponents
        function ShowComponentsValueChanged(app, event)
            replotPP(app);
        end

        % Selection changed function: Style
        function StyleSelectionChanged(app, event)
            
            app.CustomRadial.Enable=1;
            app.CustomAngular.Enable=1;
            

            app.GLabel.Interpreter = 'none';
            app.SLabel.Text='S';
            app.GLabel.Text='G';

            if(app.LifetimeStyle.Value==1)
                app.UCcb.Value=1;
                app.Smin.Value=0;app.Smax.Value=.6;
                app.Gmin.Value=0;app.Gmax.Value=1;
                app.sBandLab.Enable=0;
                app.SpecMin.Enable=0;
                app.SpecMax.Enable=0;
                app.freqEF.Enable=1;
                app.freqLab.Enable=1;
                app.TauPhaserad.Enable=1;
                app.TauNormrad.Enable=1;
                app.TauModrad.Enable=1;
                app.PhBg.Enable=1;
                if(app.LoadedIms>0)
                    app.Cmin.Value=0;app.Cmax.Value=.5;
                end
            end
            if(app.SpectralStyle.Value==1)
                app.UCcb.Value=1;
                app.Smin.Value=-1;app.Smax.Value=1;
                app.Gmin.Value=-1;app.Gmax.Value=1;
                app.sBandLab.Enable=1;
                app.SpecMin.Enable=1;
                app.SpecMax.Enable=1;
                app.freqEF.Enable=0;
                app.freqLab.Enable=0;
                app.Phaserad.Value=1;
                app.TauPhaserad.Enable=0;
                app.TauNormrad.Enable=0;
                app.TauModrad.Enable=0;
                app.PhBg.Enable=1;
                if(app.LoadedIms>0)
                    app.Cmin.Value=0;app.Cmax.Value=1;
                end
            end
            if(app.UmapStyle.Value==1)
                app.UCcb.Value=0;
                app.Smin.Value=-7;app.Smax.Value=8.5;
                app.Gmin.Value=-11.5;app.Gmax.Value=11.5;
                if(app.LoadedIms>0)
                    app.Cmin.Value=-.15;app.Cmax.Value=.55;
                end
            end
            if(app.IvMagStyle.Value==1)
                app.UCcb.Value=0;
                app.Smin.Value=0;app.Smax.Value=1;
                app.Gmin.Value=0;app.Gmax.Value=1;
                app.sBandLab.Enable=0;
                app.SpecMin.Enable=0;
                app.SpecMax.Enable=0;
                app.freqEF.Enable=0;
                app.freqLab.Enable=0;
                app.CustomRadial.Enable=0;
                app.CustomAngular.Enable=0;
                app.TauPhaserad.Enable=1;
                app.TauNormrad.Enable=1;
                app.TauModrad.Enable=1;
                app.PhBg.Enable=0;
                app.SLabel.Text='I';
                app.GLabel.Interpreter = 'latex';
                app.GLabel.Text=['$$\rm ' app.currentMag '$$'];
            end
            
            cursorSizeAdapt(app);


            if(nargin<2)||(~strcmp(class(event),'double'))
            FilterAndPlotPhasor(app);
            end
        end

        % Value changed function: SpecMin
        function SpecMinValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: SpecMax
        function SpecMaxValueChanged(app, event)
            replotPP(app);
        end

        % Value changed function: ticks
        function ticksValueChanged(app, event)
            replotPP(app);
            if(app.ticks.Value==0)
                app.tickfontsize.Enable=0;
                app.tickNumber.Enable=0;
            else
                app.tickfontsize.Enable=1;
                app.tickNumber.Enable=1;
            end
        end

        % Value changed function: tickNumber
        function tickNumberValueChanged(app, event)
            replotPP(app);
        end

        % Button pushed function: TomaskButton
        function TomaskButtonPushed(app, event)
            % see what nodes are checked
            rang=checkedFiles(app);
            if(numel(rang)>0)
                % if(numel(rang)>1)
                %     ensenya('Warning: Creating mask for first checked image on File panel!','a');
                % end

                for rr=rang
                    if(strcmp(app.masktype.Value,'Threshold'))


                        MKt=app.IIi{rr}>0;

                        % MK=app.IIa{rr};
                        % if(app.relative.Value==1)
                        %     MK=MK/max(MK(:));
                        % end
                        % if(app.Excess.Value==0)
                        %     MK=MK>app.Imin.Value;
                        % else
                        %     MK=(MK>app.Imin.Value)&(MK<app.Imax.Value);
                        % end
                    else
                        if(app.Applymask.Value==1)
                            % current SnG are already cropped by mask!
                            ApplymaskValueChanged(app);
                        end
                        MKt=zeros(size(app.GGi{rr}));
                        for ii=1:size(app.roidad,1)
                            if(app.roidad(ii,3)>0)
                                S1=app.SSi{rr}-app.roidad(ii,2);
                                G1=app.GGi{rr}-app.roidad(ii,1);
                                aux=(S1.*S1)+(G1.*G1)-app.roidad(ii,3)^2;
                                MKt=MKt+double(aux<0);%figure,imagesc(MK)
                            end
                        end
                        MKt=MKt>0;%figure,imagesc(MK)
                    end
                    app.MKL{rr}=MKt;
                end
                mostramask(app);
                ApplymaskValueChanged(app);
                app.integrateRegions.Enable=1;
                % app.MK{rang(1)};
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if(app.CalibHolder.Visible==1)
                hidecalibButtonPushed(app);
            end
        end

        % Clicked callback: Tree
        function TreeClicked(app, event)

            if(app.CalibHolder.Visible==1)
                hidecalibButtonPushed(app);
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            rang=checkedFiles(app);
            if(numel(setdiff(rang,app.curp))>0)
                FilterAndPlotPhasor(app);
            end
            replotPP(app);
            replotII(app);
            plotHists(app);
        end

        % Button pushed function: X
        function XButtonPushed(app, event)
            app.currCol=1;
            app.roidad(:,:)=0;
            FilterAndPlotPhasor(app);

            % app.curs1.Color=app.colMks(app.currCol,:);
            %
            % clearMasks(app)
            %  replotII(app);

            app.Botonera.Visible=0;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            vers1=versioncurrent(app);
            % if(~strcmp(vers1,'win'))
            vers2=versionweb(app);
            if(~strcmp(vers1,vers2))
                gg=uiconfirm(app.GSLabUIFigure,{'New version available.','Download now (~10s)?','GSLab restart required'},'','Options',{'Yes','No'},'Icon','logo2.png');
                if(strcmp(gg,'Yes'))
                    updateGSLab(app);
                    % app.UpdateButton.Enable=0;
                    % app.UpdateButton.Tooltip='Version is latest';
                    % app.UpdateButton.FontColor=app.FGcol;
                    % app.UpdateButton.Text='Update';
                end
            else
                uialert(app.GSLabUIFigure,'Current version is newest!','','Icon','logo2.png');
            end
            % else
            %     uialert(app.GSLabUIFigure,'Auto-update not implemented for compiled version.','','Icon','logo2.png');
            % end
        end

        % Button pushed function: UIcolorButton
        function UIcolorButtonPushed(app, event)
            %% pick color for gui
            pos=app.GSLabUIFigure.Position;
            tam=[200 200];
            % pos(1:2)=pos(1:2)+app.GSLabUIFigure.CurrentPoint-tam/2;
            pos(1)=pos(1)+pos(3);
            pos(3:4)=tam;
            aux=colorPicker2(0,pos);
            setcolors(app,aux);
            figure(app.GSLabUIFigure);


        end

        % Button pushed function: ImWinClipButton
        function ExpImWinClipButtonPushed(app, event)
            try
                copygraphics(app.IWh);
                ensenya('Image window copied to clipboard','Z');
            end
        end

        % Button pushed function: PhWinClipButton
        function ExpPhWinClipButtonPushed(app, event)
            copygraphics(app.PP);
            ensenya('Phasor plot window copied to clipboard','Z');
        end

        % Value changed function: integrateRegions
        function integrateRegionsValueChanged(app, event)
            if(app.Applymask.Value==1)
                FilterAndPlotPhasor(app);
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GSLabUIFigure = uifigure('Visible', 'off');
            app.GSLabUIFigure.Name = 'GSLab';
            app.GSLabUIFigure.Icon = fullfile(pathToMLAPP, 'scripts', 'logo1.png');
            app.GSLabUIFigure.CloseRequestFcn = createCallbackFcn(app, @GSLabUIFigureCloseRequest, true);
            app.GSLabUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @GSLabUIFigureWindowButtonMotion, true);
            app.GSLabUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @GSLabUIFigureWindowScrollWheel, true);
            app.GSLabUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @GSLabUIFigureWindowKeyPress, true);
            app.PhasorPanel = uipanel(app.GSLabUIFigure);
            app.PP = uiaxes(app.PhasorPanel);
            app.PP.YLim = [0 0.6];
            app.PP.ButtonDownFcn = createCallbackFcn(app, @PPButtonDown, true);
            app.coolab = uilabel(app.PhasorPanel);
            app.coolab.Text = '';
            app.Botonera = uipanel(app.GSLabUIFigure);
            app.Botonera.Visible = 'off';
            app.X = uibutton(app.Botonera, 'push');
            app.X.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.X.Tooltip = {'Clear cursors'};
            app.X.Text = 'x';
            app.TabGroup = uitabgroup(app.GSLabUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.Tree = uitree(app.FileTab, 'checkbox');
            app.Tree.ClickedFcn = createCallbackFcn(app, @TreeClicked, true);
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.MKax = uiaxes(app.ImageTab);
            app.MKax.XTick = [];
            app.MKax.YTick = [];
            app.histax = uiaxes(app.ImageTab);
            app.histax.XTick = [];
            app.histax.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.histax.YTickLabel = '';
            app.IntensityLabel = uilabel(app.ImageTab);
            app.IntensityLabel.Text = 'Intensity range';
            app.overwritemask = uicheckbox(app.ImageTab);
            app.overwritemask.Tooltip = {'Overwrite current mask or apply loaded mask on current'};
            app.overwritemask.Text = 'Overwrite';
            app.overwritemask.Value = true;
            app.LoadmaskButton = uibutton(app.ImageTab, 'push');
            app.LoadmaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmaskButtonPushed, true);
            app.LoadmaskButton.Tooltip = {'Load an image as mask'};
            app.LoadmaskButton.Text = 'Load mask';
            app.SavemaskButton = uibutton(app.ImageTab, 'push');
            app.SavemaskButton.ButtonPushedFcn = createCallbackFcn(app, @SavemaskButtonPushed, true);
            app.SavemaskButton.Tooltip = {'Save current mask as image'};
            app.SavemaskButton.Text = 'Save mask';
            app.ClearButton_2 = uibutton(app.ImageTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Tooltip = {'Clear all masks'};
            app.ClearButton_2.Text = 'Clear ';
            app.Invertmask = uicheckbox(app.ImageTab);
            app.Invertmask.ValueChangedFcn = createCallbackFcn(app, @InvertmaskValueChanged, true);
            app.Invertmask.Tooltip = {'Invert current mask'};
            app.Invertmask.Text = 'Invert';
            app.Applymask = uicheckbox(app.ImageTab);
            app.Applymask.ValueChangedFcn = createCallbackFcn(app, @ApplymaskValueChanged, true);
            app.Applymask.Tooltip = {'Apply current mask to data'};
            app.Applymask.Text = 'Apply mk';
            app.TomaskButton = uibutton(app.ImageTab, 'push');
            app.TomaskButton.ButtonPushedFcn = createCallbackFcn(app, @TomaskButtonPushed, true);
            app.TomaskButton.Tooltip = {'Generate binary image as mask'};
            app.TomaskButton.Text = 'To mask';
            app.masktype = uidropdown(app.ImageTab);
            app.masktype.Items = {'Threshold', 'Cursors'};
            app.masktype.Tooltip = {'Source for generating mask'};
            app.masktype.Value = 'Threshold';
            app.GaussianFilter = uieditfield(app.ImageTab, 'numeric');
            app.GaussianFilter.Limits = [0 100];
            app.GaussianFilter.ValueDisplayFormat = '%.1f';
            app.GaussianFilter.ValueChangedFcn = createCallbackFcn(app, @GaussianFilterValueChanged, true);
            app.GaussianFilter.Tooltip = {'Filter size in pixels'};
            app.AutoIrangeButton = uibutton(app.ImageTab, 'push');
            app.AutoIrangeButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIrange, true);
            app.AutoIrangeButton.Tooltip = {'Adjust I range to fit percentile of data'};
            app.AutoIrangeButton.Text = 'Auto';
            app.relative = uicheckbox(app.ImageTab);
            app.relative.ValueChangedFcn = createCallbackFcn(app, @relativeValueChanged, true);
            app.relative.Tooltip = {'Indicate values relative to each image maximum or absolute intensity'};
            app.relative.Text = 'Relative';
            app.relative.Value = true;
            app.hdr = uicheckbox(app.ImageTab);
            app.hdr.ValueChangedFcn = createCallbackFcn(app, @HDRValueChanged, true);
            app.hdr.Tooltip = {'Compute windowed dynamic range (experimental)'};
            app.hdr.Text = 'HDR int';
            app.Excess = uicheckbox(app.ImageTab);
            app.Excess.ValueChangedFcn = createCallbackFcn(app, @ExcessValueChanged, true);
            app.Excess.Tooltip = {'Exclude pixels above upper range value from analysis'};
            app.Excess.Text = 'Excess to zero';
            app.logint = uicheckbox(app.ImageTab);
            app.logint.ValueChangedFcn = createCallbackFcn(app, @LogIntValueChanged, true);
            app.logint.Tooltip = {'Render in log intensity'};
            app.logint.Text = 'Log intensity';
            app.Logp = uicheckbox(app.ImageTab);
            app.Logp.ValueChangedFcn = createCallbackFcn(app, @LogpValueChanged, true);
            app.Logp.Tooltip = {'Plot logarithmic counts'};
            app.Logp.Text = 'Log plot';
            app.Islide = uislider(app.ImageTab, 'range');
            app.Islide.Limits = [0 1];
            app.Islide.MajorTicks = [];
            app.Islide.ValueChangedFcn = createCallbackFcn(app, @IslideValueChanged, true);
            app.Islide.Value = [0 1];
            app.Imax = uieditfield(app.ImageTab, 'numeric');
            app.Imax.Limits = [0 1];
            app.Imax.ValueDisplayFormat = '%.2f';
            app.Imax.ValueChangedFcn = createCallbackFcn(app, @ImaxValueChanged, true);
            app.Imax.Tooltip = {'Upper value for intensity normalisation (over unity)'};
            app.Imax.Value = 1;
            app.Imin = uieditfield(app.ImageTab, 'numeric');
            app.Imin.Limits = [0 1];
            app.Imin.ValueDisplayFormat = '%.2f';
            app.Imin.ValueChangedFcn = createCallbackFcn(app, @IminValueChanged, true);
            app.Imin.Tooltip = {'Lower value for intensity thresholding (over unity)'};
            app.IntensityLabel_2 = uilabel(app.ImageTab);
            app.IntensityLabel_2.Text = 'Gaussian filter';
            app.integrateRegions = uicheckbox(app.ImageTab);
            app.integrateRegions.ValueChangedFcn = createCallbackFcn(app, @integrateRegionsValueChanged, true);
            app.integrateRegions.Enable = 'off';
            app.integrateRegions.Tooltip = {'Integrate mask regions to plot as single objects'};
            app.integrateRegions.Text = 'Integrate';
            app.PhasorTab = uitab(app.TabGroup);
            app.PhasorTab.Title = 'Phasor';
            app.SrangeLabel = uilabel(app.PhasorTab);
            app.SrangeLabel.Text = 'range';
            app.GrangeLabel = uilabel(app.PhasorTab);
            app.GrangeLabel.Text = 'range';
            app.CrangeLabel = uilabel(app.PhasorTab);
            app.CrangeLabel.Text = 'Color range    ';
            app.MeasuredmagnitudeButtonGroup = uibuttongroup(app.PhasorTab);
            app.MeasuredmagnitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MagnitudeButtonGroupSelectionChanged, true);
            app.MeasuredmagnitudeButtonGroup.Title = 'Measured magnitude';
            app.CustomRadial = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.CustomRadial.Tooltip = {'Input center for modulation-type projection'};
            app.CustomRadial.Text = 'CustomRadial';
            app.CustomAngular = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.CustomAngular.Tooltip = {'Input center for phase-type projection'};
            app.CustomAngular.Text = 'CustomAngular';
            app.TauNormrad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.TauNormrad.Tooltip = {'Tau-pseudo-phase projection centered around [.5 0]'};
            app.TauNormrad.Text = 'TauNorm';
            app.TauNormrad.Value = true;
            app.NormPhaserad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.NormPhaserad.Tooltip = {'Pseudo-phase projection centered around [.5 0]'};
            app.NormPhaserad.Text = 'NormPhase';
            app.TauModrad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.TauModrad.Tooltip = {'Tau modulation projection'};
            app.TauModrad.Text = 'TauMod';
            app.Modrad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.Modrad.Tooltip = {'Modulation projection'};
            app.Modrad.Text = 'Mod';
            app.Srad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.Srad.Tooltip = {'Measure S coordinate'};
            app.Srad.Text = 'S';
            app.Grad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.Grad.Tooltip = {'Measure G coordinate'};
            app.Grad.Text = 'G';
            app.TauPhaserad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.TauPhaserad.Tooltip = {'Tau phase projection'};
            app.TauPhaserad.Text = 'TauPhase';
            app.Phaserad = uiradiobutton(app.MeasuredmagnitudeButtonGroup);
            app.Phaserad.Tooltip = {'Phase projection'};
            app.Phaserad.Text = 'Phase';
            app.Line1 = uilabel(app.PhasorTab);
            app.Line1.Text = '_________________________________________________________';
            app.LoadCursorsButton = uibutton(app.PhasorTab, 'push');
            app.LoadCursorsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCursorsButtonPushed, true);
            app.LoadCursorsButton.Tooltip = {'Load set of cursors'};
            app.LoadCursorsButton.Text = 'Load Cursors';
            app.SaveCursorsButton = uibutton(app.PhasorTab, 'push');
            app.SaveCursorsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCursorsButtonPushed, true);
            app.SaveCursorsButton.Tooltip = {'Save current cursors'};
            app.SaveCursorsButton.Text = 'Save Cursors';
            app.Scenter = uieditfield(app.PhasorTab, 'numeric');
            app.Scenter.ValueChangedFcn = createCallbackFcn(app, @CoordScenterChanged, true);
            app.Scenter.Enable = 'off';
            app.Scenter.Tooltip = {'S coordinate of color gradient center'};
            app.Gcenter = uieditfield(app.PhasorTab, 'numeric');
            app.Gcenter.ValueChangedFcn = createCallbackFcn(app, @CoordGcenterChanged, true);
            app.Gcenter.Enable = 'off';
            app.Gcenter.Tooltip = {'G coordinate of color gradient center'};
            app.Gcenter.Value = 0.5;
            app.CenterLabel = uilabel(app.PhasorTab);
            app.CenterLabel.Enable = 'off';
            app.CenterLabel.Text = 'Centering';
            app.PhasorresEditFieldLabel = uilabel(app.PhasorTab);
            app.PhasorresEditFieldLabel.Text = 'Phasor res.';
            app.PlotTrueSize = uibutton(app.PhasorTab, 'push');
            app.PlotTrueSize.ButtonPushedFcn = createCallbackFcn(app, @PlotTrueSizeButtonPushed, true);
            app.PlotTrueSize.Tooltip = {'Compute full resolution phasor '};
            app.PlotTrueSize.Text = 'Plot HR';
            app.AutoButton = uibutton(app.PhasorTab, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoColor, true);
            app.AutoButton.Tooltip = {'Adjust color range to fit phasor data'};
            app.AutoButton.Text = 'Auto';
            app.Cmin = uieditfield(app.PhasorTab, 'numeric');
            app.Cmin.ValueDisplayFormat = '%.2f';
            app.Cmin.ValueChangedFcn = createCallbackFcn(app, @CoordCminChanged, true);
            app.Cmin.Tooltip = {'Lower limit for colormap'};
            app.Cmax = uieditfield(app.PhasorTab, 'numeric');
            app.Cmax.ValueDisplayFormat = '%.2f';
            app.Cmax.ValueChangedFcn = createCallbackFcn(app, @CoordCmaxChanged, true);
            app.Cmax.Tooltip = {'Upper limit for colormap'};
            app.Cmax.Value = 1;
            app.PhasorresEditField = uieditfield(app.PhasorTab, 'numeric');
            app.PhasorresEditField.Limits = [1 1000000];
            app.PhasorresEditField.ValueDisplayFormat = '%.0f';
            app.PhasorresEditField.ValueChangedFcn = createCallbackFcn(app, @PhasorresEditFieldValueChanged, true);
            app.PhasorresEditField.Tooltip = {'Resolution in points per G range'};
            app.PhasorresEditField.Value = 256;
            app.Gmin = uieditfield(app.PhasorTab, 'numeric');
            app.Gmin.ValueDisplayFormat = '%.2f';
            app.Gmin.ValueChangedFcn = createCallbackFcn(app, @CoordGminChanged, true);
            app.Gmin.Tooltip = {'Lower limit for display'};
            app.Gmax = uieditfield(app.PhasorTab, 'numeric');
            app.Gmax.ValueDisplayFormat = '%.2f';
            app.Gmax.ValueChangedFcn = createCallbackFcn(app, @CoordGmaxChanged, true);
            app.Gmax.Tooltip = {'Upper limit for display'};
            app.Gmax.Value = 1;
            app.Smin = uieditfield(app.PhasorTab, 'numeric');
            app.Smin.ValueDisplayFormat = '%.2f';
            app.Smin.ValueChangedFcn = createCallbackFcn(app, @CoordSminChanged, true);
            app.Smin.Tooltip = {'Lower limit for display'};
            app.Smax = uieditfield(app.PhasorTab, 'numeric');
            app.Smax.ValueDisplayFormat = '%.2f';
            app.Smax.ValueChangedFcn = createCallbackFcn(app, @CoordSmaxChanged, true);
            app.Smax.Tooltip = {'Upper limit for display'};
            app.Smax.Value = 0.5;
            app.Ti = uieditfield(app.PhasorTab, 'numeric');
            app.Ti.Limits = [0 Inf];
            app.Ti.RoundFractionalValues = 'on';
            app.Ti.ValueChangedFcn = createCallbackFcn(app, @FilterTimesValueChanged, true);
            app.Ti.Tooltip = {'Number of iterations'};
            app.TiLab = uilabel(app.PhasorTab);
            app.TiLab.Text = 'Times';
            app.Filtermatch = uilabel(app.PhasorTab);
            app.Filtermatch.Text = ' x 5';
            app.Eb = uieditfield(app.PhasorTab, 'numeric');
            app.Eb.Limits = [1 Inf];
            app.Eb.ValueChangedFcn = createCallbackFcn(app, @FilterSizeValueChanged, true);
            app.Eb.Tooltip = {'Pixel size for median filter'};
            app.Eb.Value = 5;
            app.AutoButton_2 = uibutton(app.PhasorTab, 'push');
            app.AutoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutoSrange, true);
            app.AutoButton_2.Tooltip = {'Adjust S range to fit phasor data'};
            app.AutoButton_2.Text = 'Auto';
            app.AutoButton_3 = uibutton(app.PhasorTab, 'push');
            app.AutoButton_3.ButtonPushedFcn = createCallbackFcn(app, @AutoGrange, true);
            app.AutoButton_3.Tooltip = {'Adjust G range to fit phasor data'};
            app.AutoButton_3.Text = 'Auto';
            app.FilterLabel = uilabel(app.PhasorTab);
            app.FilterLabel.Text = 'Phasor filter:';
            app.PhasorfilterDropDown = uidropdown(app.PhasorTab);
            app.PhasorfilterDropDown.Items = {'Median', 'Gaussian', 'Wavelet'};
            app.PhasorfilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.PhasorfilterDropDown.Tooltip = {'Filter type'};
            app.PhasorfilterDropDown.Value = 'Median';
            app.WavSupp = uidropdown(app.PhasorTab);
            app.WavSupp.Items = {'6.8'};
            app.WavSupp.ValueChangedFcn = createCallbackFcn(app, @FilterWavSuppValueChanged, true);
            app.WavSupp.Tooltip = {'Wavelet support'};
            app.WavSupp.Value = '6.8';
            app.WaveletFamilyDropDown = uidropdown(app.PhasorTab);
            app.WaveletFamilyDropDown.Items = {'families'};
            app.WaveletFamilyDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltWaveletFamilyDropDownValueChanged, true);
            app.WaveletFamilyDropDown.Tooltip = {'Wavelet familiy'};
            app.WaveletFamilyDropDown.Value = 'families';
            app.AnscombeCheckBox = uicheckbox(app.PhasorTab);
            app.AnscombeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnscombeCheckBoxValueChanged, true);
            app.AnscombeCheckBox.Text = 'Anscombe';
            app.AnscombeCheckBox.Value = true;
            app.SLabel = uilabel(app.PhasorTab);
            app.SLabel.Text = 'S';
            app.GLabel = uilabel(app.PhasorTab);
            app.GLabel.Text = 'G';
            app.CLabel = uilabel(app.PhasorTab);
            app.CLabel.Tooltip = {'Units for color range'};
            app.CLabel.Text = 'u';
            app.NormColunits = uicheckbox(app.PhasorTab);
            app.NormColunits.ValueChangedFcn = createCallbackFcn(app, @NormColunitsValueChanged, true);
            app.NormColunits.Tag = 'load FLIMlabs raw data';
            app.NormColunits.Tooltip = {'Use normalized color units'};
            app.NormColunits.Text = 'Norm color units';
            app.NormColunits.Value = true;
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.HarmonicLabel = uilabel(app.DisplayTab);
            app.HarmonicLabel.Text = 'Harmonic #';
            app.AxesfontsizeEditFieldLabel_2 = uilabel(app.DisplayTab);
            app.AxesfontsizeEditFieldLabel_2.Text = 'Axes font size';
            app.Style = uibuttongroup(app.DisplayTab);
            app.Style.SelectionChangedFcn = createCallbackFcn(app, @StyleSelectionChanged, true);
            app.LifetimeStyle = uiradiobutton(app.Style);
            app.LifetimeStyle.Tooltip = {'Set phasor limits for lifetime'};
            app.LifetimeStyle.Text = 'Lifetime';
            app.LifetimeStyle.Value = true;
            app.SpectralStyle = uiradiobutton(app.Style);
            app.SpectralStyle.Tooltip = {'Set phasor limits for spectra'};
            app.SpectralStyle.Text = 'Spectral';
            app.UmapStyle = uiradiobutton(app.Style);
            app.UmapStyle.Visible = 'off';
            app.UmapStyle.Tooltip = {'Set phasor limits for umap'};
            app.UmapStyle.Text = 'Umap';
            app.IvMagStyle = uiradiobutton(app.Style);
            app.IvMagStyle.Tooltip = {'Plot image intensity versus phasor magnitude'};
            app.IvMagStyle.Text = 'Int vs Mag';
            app.sBandLab = uilabel(app.DisplayTab);
            app.sBandLab.Text = 'Spectral band                          nm';
            app.SpecMax = uieditfield(app.DisplayTab, 'numeric');
            app.SpecMax.Limits = [0 Inf];
            app.SpecMax.ValueDisplayFormat = '%.0f';
            app.SpecMax.ValueChangedFcn = createCallbackFcn(app, @SpecMaxValueChanged, true);
            app.SpecMax.Tooltip = {'Upper limit for speectral band'};
            app.SpecMax.Value = 700;
            app.SpecMin = uieditfield(app.DisplayTab, 'numeric');
            app.SpecMin.Limits = [0 Inf];
            app.SpecMin.ValueDisplayFormat = '%.0f';
            app.SpecMin.ValueChangedFcn = createCallbackFcn(app, @SpecMinValueChanged, true);
            app.SpecMin.Tooltip = {'Lower limit for speectral band'};
            app.SpecMin.Value = 400;
            app.freqLab = uilabel(app.DisplayTab);
            app.freqLab.Text = 'Source frequency            MHz';
            app.freqEF = uieditfield(app.DisplayTab, 'numeric');
            app.freqEF.Limits = [1 Inf];
            app.freqEF.ValueDisplayFormat = '%.0f';
            app.freqEF.ValueChangedFcn = createCallbackFcn(app, @FreqEFValueChanged, true);
            app.freqEF.Tooltip = {'Frequency of excitation source'};
            app.freqEF.Value = 80;
            app.tilinglabel = uilabel(app.DisplayTab);
            app.tilinglabel.Text = 'x      ';
            app.fliprows = uicheckbox(app.DisplayTab);
            app.fliprows.ValueChangedFcn = createCallbackFcn(app, @DisplayfliprowsValueChanged, true);
            app.fliprows.Tooltip = {'Render images always left to right or in zig-zag mode'};
            app.fliprows.Text = 'Flip even rows';
            app.margns = uicheckbox(app.DisplayTab);
            app.margns.ValueChangedFcn = createCallbackFcn(app, @DisplayMargnsValueChanged, true);
            app.margns.Tooltip = {'Add margin between image panels'};
            app.margns.Text = 'Margins';
            app.margns.Value = true;
            app.imagecols = uieditfield(app.DisplayTab, 'numeric');
            app.imagecols.Limits = [1 Inf];
            app.imagecols.ValueDisplayFormat = '%.0f';
            app.imagecols.ValueChangedFcn = createCallbackFcn(app, @imagecolsValueChanged, true);
            app.imagecols.Tooltip = {'Number of columns in image window'};
            app.imagecols.Value = 1;
            app.imagerows = uieditfield(app.DisplayTab, 'numeric');
            app.imagerows.Limits = [1 Inf];
            app.imagerows.ValueDisplayFormat = '%.0f';
            app.imagerows.ValueChangedFcn = createCallbackFcn(app, @imagerowsValueChanged, true);
            app.imagerows.Tooltip = {'Number of rows in image window'};
            app.imagerows.Value = 1;
            app.imagewindowtile = uicheckbox(app.DisplayTab);
            app.imagewindowtile.ValueChangedFcn = createCallbackFcn(app, @imagewindowtileValueChanged, true);
            app.imagewindowtile.Tooltip = {'Force image window panel distribution'};
            app.imagewindowtile.Text = 'Image Window tiling ';
            app.SoftCursor = uicheckbox(app.DisplayTab);
            app.SoftCursor.ValueChangedFcn = createCallbackFcn(app, @SoftCursorValueChanged, true);
            app.SoftCursor.Tooltip = {'Hard mask is simFCS style, soft is mask times intensity'};
            app.SoftCursor.Text = 'Soft Masks';
            app.PhBg = uicheckbox(app.DisplayTab);
            app.PhBg.ValueChangedFcn = createCallbackFcn(app, @PhBgValueChanged, true);
            app.PhBg.Tooltip = {'Display color projection for phasor measurement'};
            app.PhBg.Text = 'Magnitude background color';
            app.AFSef = uieditfield(app.DisplayTab, 'numeric');
            app.AFSef.ValueChangedFcn = createCallbackFcn(app, @FontSizeAxesValueChanged, true);
            app.AFSef.Tooltip = {'Axes font size'};
            app.AFSef.Value = 14;
            app.Harm = uieditfield(app.DisplayTab, 'numeric');
            app.Harm.Limits = [1 Inf];
            app.Harm.ValueDisplayFormat = '%.0f';
            app.Harm.ValueChangedFcn = createCallbackFcn(app, @HarmValueChanged, true);
            app.Harm.Tooltip = {'Phasor transform harmonic number'};
            app.Harm.Value = 1;
            app.tickNumber = uieditfield(app.DisplayTab, 'numeric');
            app.tickNumber.Limits = [0 100];
            app.tickNumber.ValueChangedFcn = createCallbackFcn(app, @tickNumberValueChanged, true);
            app.tickNumber.Tooltip = {'Number of ticks'};
            app.tickNumber.Value = 10;
            app.UCcb = uicheckbox(app.DisplayTab);
            app.UCcb.ValueChangedFcn = createCallbackFcn(app, @LinesCheckBoxValueChanged, true);
            app.UCcb.Tooltip = {'Show lines in phasor plot'};
            app.UCcb.Text = 'Lines';
            app.UCcb.Value = true;
            app.fontsizeLabel_2 = uilabel(app.DisplayTab);
            app.fontsizeLabel_2.Text = 'Tick #';
            app.axes = uicheckbox(app.DisplayTab);
            app.axes.ValueChangedFcn = createCallbackFcn(app, @AxesValueChanged, true);
            app.axes.Tooltip = {'Export each phasor plot without the axes'};
            app.axes.Text = 'Axes';
            app.axes.Value = true;
            app.ticks = uicheckbox(app.DisplayTab);
            app.ticks.ValueChangedFcn = createCallbackFcn(app, @ticksValueChanged, true);
            app.ticks.Tooltip = {'Show ticks in phasor plot'};
            app.ticks.Text = 'Ticks';
            app.ticks.Value = true;
            app.fontsizeLabel = uilabel(app.DisplayTab);
            app.fontsizeLabel.Text = 'Tick font size';
            app.tickfontsize = uieditfield(app.DisplayTab, 'numeric');
            app.tickfontsize.ValueChangedFcn = createCallbackFcn(app, @FontSizeLineValueChanged, true);
            app.tickfontsize.Tooltip = {'Tick font size'};
            app.tickfontsize.Value = 10;
            app.PlotDecays = uibutton(app.DisplayTab, 'push');
            app.PlotDecays.ButtonPushedFcn = createCallbackFcn(app, @DecaysPlotButton, true);
            app.PlotDecays.Tooltip = {'Plot decays '};
            app.PlotDecays.Text = 'Plot Decay';
            app.LogDecays = uicheckbox(app.DisplayTab);
            app.LogDecays.ValueChangedFcn = createCallbackFcn(app, @DecaysLogChanged, true);
            app.LogDecays.Tooltip = {'Normalize decays'};
            app.LogDecays.Text = 'Log';
            app.NormDecays = uicheckbox(app.DisplayTab);
            app.NormDecays.ValueChangedFcn = createCallbackFcn(app, @DecayNormValueChanged, true);
            app.NormDecays.Tooltip = {'Normalize decays'};
            app.NormDecays.Text = 'Norm';
            app.contours = uicheckbox(app.DisplayTab);
            app.contours.ValueChangedFcn = createCallbackFcn(app, @ContoursValueChanged, true);
            app.contours.Tooltip = {'Add contours'};
            app.contours.Text = 'Contours';
            app.normFile = uicheckbox(app.DisplayTab);
            app.normFile.ValueChangedFcn = createCallbackFcn(app, @NormFileValueChanged, true);
            app.normFile.Tooltip = {'Normalize density to each file'};
            app.normFile.Text = 'Normalize by file';
            app.normFile.Value = true;
            app.logDens = uicheckbox(app.DisplayTab);
            app.logDens.ValueChangedFcn = createCallbackFcn(app, @logDensValueChanged, true);
            app.logDens.Tooltip = {'Display phasor data as log of density'};
            app.logDens.Text = 'Log density';
            app.contourOnly = uicheckbox(app.DisplayTab);
            app.contourOnly.ValueChangedFcn = createCallbackFcn(app, @ContourOnlyValueChanged, true);
            app.contourOnly.Tooltip = {'Add contours'};
            app.contourOnly.Text = 'Only';
            app.ClusterTab = uitab(app.TabGroup);
            app.ClusterTab.Title = 'Cluster';
            app.gmmexport = uicheckbox(app.ClusterTab);
            app.gmmexport.ValueChangedFcn = createCallbackFcn(app, @GMMexportValueChanged, true);
            app.gmmexport.Tooltip = {'In export tab use checkboxes to export cluster-colored images'};
            app.gmmexport.Text = 'Override magnitude for export';
            app.blend = uicheckbox(app.ClusterTab);
            app.blend.ValueChangedFcn = createCallbackFcn(app, @GMMblendeValueChanged, true);
            app.blend.Tooltip = {'Soften color borders'};
            app.blend.Text = 'Blending          %';
            app.blendamount = uieditfield(app.ClusterTab, 'numeric');
            app.blendamount.Limits = [1 100];
            app.blendamount.ValueDisplayFormat = '%.0f';
            app.blendamount.ValueChangedFcn = createCallbackFcn(app, @GMMblendamountValueChanged, true);
            app.blendamount.Tooltip = {'Blending filter size'};
            app.blendamount.Value = 5;
            app.dispStyle2 = uibuttongroup(app.ClusterTab);
            app.dispStyle2.SelectionChangedFcn = createCallbackFcn(app, @GMMdispStyleChanged, true);
            app.dispStyle2.Title = 'Image display style';
            app.prob = uiradiobutton(app.dispStyle2);
            app.prob.Tooltip = {'Color pixels weighted by posterior cluster probability'};
            app.prob.Text = 'Probability assign';
            app.prob.Value = true;
            app.hard = uiradiobutton(app.dispStyle2);
            app.hard.Tooltip = {'Color pixels by cluster with max probability'};
            app.hard.Text = 'Hard assign';
            app.ApplyModelButton = uibutton(app.ClusterTab, 'push');
            app.ApplyModelButton.ButtonPushedFcn = createCallbackFcn(app, @GMMApplyButtonPushed, true);
            app.ApplyModelButton.Tooltip = {'Apply trained GMM'};
            app.ApplyModelButton.Text = 'Apply Model';
            app.dispStyle = uibuttongroup(app.ClusterTab);
            app.dispStyle.SelectionChangedFcn = createCallbackFcn(app, @GMMdisp2StyleChanged, true);
            app.dispStyle.Title = 'Phasor display style';
            app.elipse = uiradiobutton(app.dispStyle);
            app.elipse.Tooltip = {'Draw elipses around each cluster'};
            app.elipse.Text = 'Ellipse with          % of data';
            app.conf = uieditfield(app.dispStyle, 'numeric');
            app.conf.Limits = [1 100];
            app.conf.ValueDisplayFormat = '%.0f';
            app.conf.ValueChangedFcn = createCallbackFcn(app, @GMMconfValueChanged, true);
            app.conf.Tooltip = {'Percentage of points inside elipse'};
            app.conf.Value = 68;
            app.contseparate = uiradiobutton(app.dispStyle);
            app.contseparate.Tooltip = {'Draw contours for each cluster'};
            app.contseparate.Text = 'Contour separate clusters';
            app.conttogether = uiradiobutton(app.dispStyle);
            app.conttogether.Tooltip = {'Draw contours of full GMM'};
            app.conttogether.Text = 'Contour of model';
            app.conttogether.Value = true;
            app.ClearModelButton = uibutton(app.ClusterTab, 'push');
            app.ClearModelButton.ButtonPushedFcn = createCallbackFcn(app, @GMMclearButtonPushed, true);
            app.ClearModelButton.Tooltip = {'Clear current GMM'};
            app.ClearModelButton.Text = 'Clear Model';
            app.TrainModelButton = uibutton(app.ClusterTab, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @GMMTrainButtonPushed, true);
            app.TrainModelButton.Tooltip = {'Train GMM with current data'};
            app.TrainModelButton.Text = 'Train Model';
            app.ModelDropDownLabel = uilabel(app.ClusterTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.ClusterTab);
            app.ModelDropDown.Items = {'GMM'};
            app.ModelDropDown.Value = 'GMM';
            app.UnmixTab = uitab(app.TabGroup);
            app.UnmixTab.Title = 'Unmix';
            app.ComponentsPanel = uipanel(app.UnmixTab);
            app.ComponentsPanel.Title = 'Components';
            app.plusB = uibutton(app.ComponentsPanel, 'push');
            app.plusB.ButtonPushedFcn = createCallbackFcn(app, @ComponentPlusButtonPressed, true);
            app.plusB.Tooltip = {'Add component'};
            app.plusB.Text = '+';
            app.minusB = uibutton(app.ComponentsPanel, 'push');
            app.minusB.ButtonPushedFcn = createCallbackFcn(app, @ComponentMinusButtonPressed, true);
            app.minusB.Tooltip = {'Remove component'};
            app.minusB.Text = '-';
            app.ratiocsv = uicheckbox(app.UnmixTab);
            app.ratiocsv.ValueChangedFcn = createCallbackFcn(app, @CompratiocsvValueChanged, true);
            app.ratiocsv.Tooltip = {'Export pixel ratio as spreadsheet'};
            app.ratiocsv.Text = 'Export ratio csv';
            app.ComponentvsLabel = uilabel(app.UnmixTab);
            app.ComponentvsLabel.Text = 'Component        vs';
            app.comp2 = uieditfield(app.UnmixTab, 'numeric');
            app.comp2.ValueDisplayFormat = '%.0f';
            app.comp2.Tooltip = {'Denominator sumand component N/(N+D)'};
            app.comp1 = uieditfield(app.UnmixTab, 'numeric');
            app.comp1.ValueDisplayFormat = '%.0f';
            app.comp1.Tooltip = {'Numerator component N/(N+D)'};
            app.exportratiometric = uicheckbox(app.UnmixTab);
            app.exportratiometric.ValueChangedFcn = createCallbackFcn(app, @ExportratiometricValueChanged, true);
            app.exportratiometric.Tooltip = {'Export ratiometric image of pair of components'};
            app.exportratiometric.Text = 'Export ratiometric';
            app.includeint = uicheckbox(app.UnmixTab);
            app.includeint.Tooltip = {'Add photon intensity column in spreadsheet'};
            app.includeint.Text = 'Include intensity';
            app.CompImsCSVExp = uicheckbox(app.UnmixTab);
            app.CompImsCSVExp.ValueChangedFcn = createCallbackFcn(app, @CompImsCSVExpValueChanged, true);
            app.CompImsCSVExp.Tooltip = {'Export pixel fraction as spreadsheet'};
            app.CompImsCSVExp.Text = 'Export fraction csv';
            app.CompImsIntExp = uicheckbox(app.UnmixTab);
            app.CompImsIntExp.Tooltip = {'Export images as pixel intensity times fraction (true component photon intensity) versus normalized fraction.'};
            app.CompImsIntExp.Text = 'Export fraction x int';
            app.ComputeHR = uibutton(app.UnmixTab, 'push');
            app.ComputeHR.ButtonPushedFcn = createCallbackFcn(app, @ComputeHRButtonPushed, true);
            app.ComputeHR.Tooltip = {'Launch unmixing with raw full resolution'};
            app.ComputeHR.Text = 'Compute HR';
            app.ComputeLR = uibutton(app.UnmixTab, 'push');
            app.ComputeLR.ButtonPushedFcn = createCallbackFcn(app, @ComputeLRButtonPushed, true);
            app.ComputeLR.Tooltip = {'Launch unmixing with current display resolution'};
            app.ComputeLR.Text = 'Compute LR';
            app.mergecomps = uicheckbox(app.UnmixTab);
            app.mergecomps.Tooltip = {'Export color merge of component images'};
            app.mergecomps.Text = 'Merge comps.';
            app.addmargins = uicheckbox(app.UnmixTab);
            app.addmargins.Tooltip = {'Add margins between images in output'};
            app.addmargins.Text = 'Add margins';
            app.groupfile = uicheckbox(app.UnmixTab);
            app.groupfile.ValueChangedFcn = createCallbackFcn(app, @GroupfileValueChanged, true);
            app.groupfile.Tooltip = {'Group images by file or by component'};
            app.groupfile.Text = 'Group by file';
            app.singleimage = uicheckbox(app.UnmixTab);
            app.singleimage.ValueChangedFcn = createCallbackFcn(app, @singleimageValueChanged, true);
            app.singleimage.Tooltip = {'Join all images into single file '};
            app.singleimage.Text = 'Single image';
            app.singleimage.Value = true;
            app.CompImsCol = uicheckbox(app.UnmixTab);
            app.CompImsCol.Tooltip = {'Add color to component fractional images'};
            app.CompImsCol.Text = 'Color components';
            app.CompImsExp = uicheckbox(app.UnmixTab);
            app.CompImsExp.ValueChangedFcn = createCallbackFcn(app, @CompImsExpValueChanged, true);
            app.CompImsExp.Tooltip = {'Export pixel component fraction as image'};
            app.CompImsExp.Text = 'Export fraction im';
            app.CompImsExp.Value = true;
            app.SourceButtonGroup = uibuttongroup(app.UnmixTab);
            app.SourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceButtonGroupSelectionChanged, true);
            app.SourceButtonGroup.Tooltip = {'Define pure components for unmixing'};
            app.SourceButtonGroup.Title = 'Source';
            app.InputButton = uiradiobutton(app.SourceButtonGroup);
            app.InputButton.Tooltip = {'Use cursor positions for pure components (max 3)'};
            app.InputButton.Text = 'Input';
            app.InputButton.Value = true;
            app.FileButton = uiradiobutton(app.SourceButtonGroup);
            app.FileButton.Tooltip = {'Use loaded files for pure components'};
            app.FileButton.Text = 'File';
            app.CursorButton = uiradiobutton(app.SourceButtonGroup);
            app.CursorButton.Tooltip = {'Use point-and-click cursors (first 3 max, 1harmonic limitation)'};
            app.CursorButton.Text = 'Cursor';
            app.ComponentTree = uitree(app.UnmixTab, 'checkbox');
            app.ComponentTree.Visible = 'off';
            % Assign Checked Nodes
            app.ComponentTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ComponentTreeCheckedNodesChanged, true);
            app.AddLines = uicheckbox(app.UnmixTab);
            app.AddLines.ValueChangedFcn = createCallbackFcn(app, @ComponentLinesValueChanged, true);
            app.AddLines.Tooltip = {'Display lines between components on phasor plot'};
            app.AddLines.Text = 'Add lines';
            app.AddLines.Value = true;
            app.ShowComponents = uicheckbox(app.UnmixTab);
            app.ShowComponents.ValueChangedFcn = createCallbackFcn(app, @ShowComponentsValueChanged, true);
            app.ShowComponents.Tooltip = {'Display component positions on phasor plot'};
            app.ShowComponents.Text = 'Draw components';
            app.ShowComponents.Value = true;
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.groupfiles = uicheckbox(app.ExportTab);
            app.groupfiles.Tooltip = {'Generate single image with all loaded files'};
            app.groupfiles.Text = 'Group all combined images';
            app.Combine = uicheckbox(app.ExportTab);
            app.Combine.ValueChangedFcn = createCallbackFcn(app, @CombineValueChanged, true);
            app.Combine.Tooltip = {'Combine all exports for each file into single image'};
            app.Combine.Text = 'Combine into single image';
            app.Combine.Value = true;
            app.ExCurS = uicheckbox(app.ExportTab);
            app.ExCurS.Tooltip = {'Export cursor-colored image times number of photons'};
            app.ExCurS.Text = 'Cursor-colored x intensity';
            app.ExCurH = uicheckbox(app.ExportTab);
            app.ExCurH.Tooltip = {'Export cursor-colored image'};
            app.ExCurH.Text = 'Cursor-colored image';
            app.ExMagS = uicheckbox(app.ExportTab);
            app.ExMagS.Tooltip = {'Export intensity image colored by magnitude times number of photons'};
            app.ExMagS.Text = 'Magnitude-colored x intensity';
            app.ExMagS.Value = true;
            app.ExMagH = uicheckbox(app.ExportTab);
            app.ExMagH.Tooltip = {'Export intensity image colored by magnitude'};
            app.ExMagH.Text = 'Magnitude-colored image';
            app.allasLabel = uilabel(app.ExportTab);
            app.allasLabel.Text = 'all as';
            app.ExportImageFileDropDown = uidropdown(app.ExportTab);
            app.ExportImageFileDropDown.Items = {'png', 'jpg', 'tif'};
            app.ExportImageFileDropDown.Tooltip = {'Image file format'};
            app.ExportImageFileDropDown.Value = 'png';
            app.ExInt = uicheckbox(app.ExportTab);
            app.ExInt.Tooltip = {'Export Intensity Image'};
            app.ExInt.Text = 'Intensity image';
            app.ExInt.Value = true;
            app.ExpPhWinButton = uibutton(app.ExportTab, 'push');
            app.ExpPhWinButton.ButtonPushedFcn = createCallbackFcn(app, @ExpPhWinButtonPushed, true);
            app.ExpPhWinButton.Tooltip = {'Export current phasor panel as image'};
            app.ExpPhWinButton.Text = 'Exp.Ph.Win.';
            app.ExpImWinButton = uibutton(app.ExportTab, 'push');
            app.ExpImWinButton.ButtonPushedFcn = createCallbackFcn(app, @ExpImWinButtonPushed, true);
            app.ExpImWinButton.Tooltip = {'Export current image window as image'};
            app.ExpImWinButton.Text = 'Exp.Im.Win.';
            app.ExportHRButton = uibutton(app.ExportTab, 'push');
            app.ExportHRButton.ButtonPushedFcn = createCallbackFcn(app, @ExportHRButtonPushed, true);
            app.ExportHRButton.Tooltip = {'Export data using raw image sizes'};
            app.ExportHRButton.Text = 'Export HR';
            app.ExportLRButton = uibutton(app.ExportTab, 'push');
            app.ExportLRButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLRButtonPushed, true);
            app.ExportLRButton.Tooltip = {'Export data current image sizes'};
            app.ExportLRButton.Text = 'Export LR';
            app.ExportPhasorFileDropDown = uidropdown(app.ExportTab);
            app.ExportPhasorFileDropDown.Items = {'png', 'jpg', 'tif', 'emf', 'pdf'};
            app.ExportPhasorFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportPhasorFileDropDownValueChanged, true);
            app.ExportPhasorFileDropDown.Tooltip = {'Image file format'};
            app.ExportPhasorFileDropDown.Value = 'png';
            app.ExPhasor = uicheckbox(app.ExportTab);
            app.ExPhasor.Tooltip = {'Export each file''s phasor plot'};
            app.ExPhasor.Text = 'Phasor plot as';
            app.ExPhasor.Value = true;
            app.Expptrans = uicheckbox(app.ExportTab);
            app.Expptrans.ValueChangedFcn = createCallbackFcn(app, @ExpptransValueChanged, true);
            app.Expptrans.Tooltip = {'Export transparent phasor plot background'};
            app.Expptrans.Text = 'Phasor plot alpha';
            app.PhRb = uicheckbox(app.ExportTab);
            app.PhRb.Tooltip = {'Generate magnitude background color image'};
            app.PhRb.Text = 'Save background image';
            app.Cbar = uicheckbox(app.ExportTab);
            app.Cbar.Tooltip = {'Generate a colorbar based on the phasor magnitude measured'};
            app.Cbar.Text = 'Print colorbar';
            app.DeacayFileDropDown = uidropdown(app.ExportTab);
            app.DeacayFileDropDown.Items = {'csv', 'txt'};
            app.DeacayFileDropDown.Tooltip = {'File format'};
            app.DeacayFileDropDown.Value = 'csv';
            app.ExDecay = uicheckbox(app.ExportTab);
            app.ExDecay.Tooltip = {'Export a file of average frame intensity'};
            app.ExDecay.Text = 'Average decay as';
            app.ReLab = uilabel(app.ExportTab);
            app.ReLab.Text = '*no folder selected*';
            app.BrowseResults = uibutton(app.ExportTab, 'push');
            app.BrowseResults.ButtonPushedFcn = createCallbackFcn(app, @BrowseResultsButtonPushed, true);
            app.BrowseResults.Tooltip = {'Set output folder location'};
            app.BrowseResults.Text = 'Output Folder:';
            app.CoordsFmtDD = uidropdown(app.ExportTab);
            app.CoordsFmtDD.Items = {'csv', 'txt', 'xls'};
            app.CoordsFmtDD.Tooltip = {'File format'};
            app.CoordsFmtDD.Value = 'csv';
            app.GScoords = uicheckbox(app.ExportTab);
            app.GScoords.Tooltip = {'Export a file of pixel phasor coordinates and phasor magnitudes'};
            app.GScoords.Text = 'Pixel GS & data as';
            app.LabelFmt = uidropdown(app.ExportTab);
            app.LabelFmt.Items = {'csv', 'txt', 'xls'};
            app.LabelFmt.Tooltip = {'File format'};
            app.LabelFmt.Value = 'csv';
            app.LabelCoords = uicheckbox(app.ExportTab);
            app.LabelCoords.Tooltip = {'Label connected components in mask and export phasor data as average for each separate component'};
            app.LabelCoords.Text = 'Masked obj data as';
            app.PhWinClipButton = uibutton(app.ExportTab, 'push');
            app.PhWinClipButton.ButtonPushedFcn = createCallbackFcn(app, @ExpPhWinClipButtonPushed, true);
            app.PhWinClipButton.Tooltip = {'Copy phasor window to clipboard'};
            app.PhWinClipButton.Text = 'Ph.Win.Clip';
            app.ImWinClipButton = uibutton(app.ExportTab, 'push');
            app.ImWinClipButton.ButtonPushedFcn = createCallbackFcn(app, @ExpImWinClipButtonPushed, true);
            app.ImWinClipButton.Tooltip = {'Copy image window to clipboard'};
            app.ImWinClipButton.Text = 'Im.Win.Clip';
            app.STab = uitab(app.TabGroup);
            app.STab.Title = 'S';
            app.Line2 = uilabel(app.STab);
            app.Line2.Text = '___________________________';
            app.CursorcolorsLabel = uilabel(app.STab);
            app.CursorcolorsLabel.Text = 'Cursor colors';
            app.ColPtile = uieditfield(app.STab, 'numeric');
            app.ColPtile.Limits = [0 1];
            app.ColPtile.ValueDisplayFormat = '%.4f';
            app.ColPtile.Tooltip = {'Range of data auto color should include'};
            app.ColPtile.Value = 0.9;
            app.CoordPtile = uieditfield(app.STab, 'numeric');
            app.CoordPtile.Limits = [0 1];
            app.CoordPtile.ValueDisplayFormat = '%.4f';
            app.CoordPtile.Tooltip = {'Range of data auto coordinate should include'};
            app.CoordPtile.Value = 0.9;
            app.IntPtile = uieditfield(app.STab, 'numeric');
            app.IntPtile.Limits = [0 1];
            app.IntPtile.ValueDisplayFormat = '%.4f';
            app.IntPtile.Tooltip = {'Range of data auto intensity should include'};
            app.IntPtile.Value = 0.9;
            app.SrangeLabel_2 = uilabel(app.STab);
            app.SrangeLabel_2.Tooltip = {'Range of data auto coordinate should include'};
            app.SrangeLabel_2.Text = 'Autocoord pctl';
            app.SrangeLabel_3 = uilabel(app.STab);
            app.SrangeLabel_3.Tooltip = {'Range of data auto intensity should include'};
            app.SrangeLabel_3.Text = 'Autointensity pctl';
            app.SrangeLabel_4 = uilabel(app.STab);
            app.SrangeLabel_4.Tooltip = {'Range of data auto color should include'};
            app.SrangeLabel_4.Text = 'Autocolor pctl';
            app.SrangeLabel_5 = uilabel(app.STab);
            app.SrangeLabel_5.Tooltip = {'Upper limit for range to display in histogram'};
            app.SrangeLabel_5.Text = 'Intensity histogram top pctl';
            app.IntDispTop = uieditfield(app.STab, 'numeric');
            app.IntDispTop.Limits = [0 1];
            app.IntDispTop.ValueDisplayFormat = '%.4f';
            app.IntDispTop.ValueChangedFcn = createCallbackFcn(app, @IntDispTopValueChanged, true);
            app.IntDispTop.Tooltip = {'Upper limit for range to display in histogram'};
            app.IntDispTop.Value = 0.99;
            app.LoadFLIMlabsrawCheckBox = uicheckbox(app.STab);
            app.LoadFLIMlabsrawCheckBox.Tag = 'load FLIMlabs raw data';
            app.LoadFLIMlabsrawCheckBox.Text = 'Load FLIMlabs raw';
            app.PromptFLIMlabsloadoptionCheckBox = uicheckbox(app.STab);
            app.PromptFLIMlabsloadoptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PromptFLIMlabsloadoptionCheckBoxValueChanged, true);
            app.PromptFLIMlabsloadoptionCheckBox.Tag = 'load FLIMlabs raw data';
            app.PromptFLIMlabsloadoptionCheckBox.Text = 'Prompt FLIMlabs load option';
            app.PromptFLIMlabsloadoptionCheckBox.Value = true;
            app.LoadFLAME31nBwithBCheckBox = uicheckbox(app.STab);
            app.LoadFLAME31nBwithBCheckBox.Tooltip = {'Load B channel for 31nB configuration'};
            app.LoadFLAME31nBwithBCheckBox.Text = 'Load FLAME 31nB with B';
            app.LoadFLAME31nBwithBCheckBox.Value = true;
            app.MaxharmonicsEditFieldLabel = uilabel(app.STab);
            app.MaxharmonicsEditFieldLabel.Text = 'Max harmonics';
            app.HarmLim = uieditfield(app.STab, 'numeric');
            app.HarmLim.Limits = [1 Inf];
            app.HarmLim.ValueDisplayFormat = '%.0f';
            app.HarmLim.ValueChangedFcn = createCallbackFcn(app, @HarmLimValueChanged, true);
            app.HarmLim.Tooltip = {'Max number of harmonics to compute when loading files (requires reloading)'};
            app.HarmLim.Value = 1;
            app.DownsamplingEditFieldLabel = uilabel(app.STab);
            app.DownsamplingEditFieldLabel.Text = 'Downsampling';
            app.DownsamplingEditField = uieditfield(app.STab, 'numeric');
            app.DownsamplingEditField.Limits = [0 2000];
            app.DownsamplingEditField.ValueDisplayFormat = '%.0f';
            app.DownsamplingEditField.ValueChangedFcn = createCallbackFcn(app, @DownsamplingEditFieldValueChanged, true);
            app.DownsamplingEditField.Tooltip = {'Downsample image size for faster computing (zero for true sizes)'};
            app.DownsamplingEditField.Value = 256;
            app.Panel = uipanel(app.STab);
            app.PhasordensitycolorLabel = uilabel(app.STab);
            app.PhasordensitycolorLabel.Text = 'Phasor density colormap';
            app.MagcolLabel = uilabel(app.STab);
            app.MagcolLabel.Text = 'Magnitude colormap';
            app.CmapFlipMag = uicheckbox(app.STab);
            app.CmapFlipMag.ValueChangedFcn = createCallbackFcn(app, @CmapFlipMagValueChanged, true);
            app.CmapFlipMag.Tooltip = {'Flip color order'};
            app.CmapFlipMag.Text = 'Flip';
            app.PhasorcolormapDD = uidropdown(app.STab);
            app.PhasorcolormapDD.ValueChangedFcn = createCallbackFcn(app, @CmapPhdensDDValueChanged, true);
            app.PhasorcolormapDD.Tooltip = {'Colormap for phasor density heat map'};
            app.CmapFlipDens = uicheckbox(app.STab);
            app.CmapFlipDens.ValueChangedFcn = createCallbackFcn(app, @CmapFlipDensValueChanged, true);
            app.CmapFlipDens.Tooltip = {'Flip color order'};
            app.CmapFlipDens.Text = 'Flip';
            app.RainbowcolormapDD = uidropdown(app.STab);
            app.RainbowcolormapDD.ValueChangedFcn = createCallbackFcn(app, @CmapMagDDValueChanged, true);
            app.RainbowcolormapDD.Tooltip = {'Colormap for phasor measurement '};
            app.UIcolorButton = uibutton(app.STab, 'push');
            app.UIcolorButton.ButtonPushedFcn = createCallbackFcn(app, @UIcolorButtonPushed, true);
            app.UIcolorButton.Text = 'UI color';
            app.DarkmodeCheckBox = uicheckbox(app.STab);
            app.DarkmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DarkmodeCheckBoxValueChanged, true);
            app.DarkmodeCheckBox.Tooltip = {'Experimental'};
            app.DarkmodeCheckBox.Text = 'Dark mode';
            app.UpdateButton = uibutton(app.STab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tooltip = {'Check online for updates'};
            app.UpdateButton.Text = 'Update';
            app.AddFolders = uicheckbox(app.STab);
            app.AddFolders.Tooltip = {'Filter before or after thresholding'};
            app.AddFolders.Text = 'Add folder to file name';
            app.autotab = uicheckbox(app.STab);
            app.autotab.Tooltip = {'Switch tabs on mouse hover'};
            app.autotab.Text = 'Auto select tabs';
            app.CalibHolder = uipanel(app.GSLabUIFigure);
            app.CalibrationPanel = uipanel(app.CalibHolder);
            app.calTable = uitable(app.CalibrationPanel);
            app.calTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.calTable.RowName = {};
            app.calTable.ColumnEditable = [true true false];
            app.calTable.CellEditCallback = createCallbackFcn(app, @CalibTableCellEdit, true);
            app.calTable.Multiselect = 'off';
            app.ClearButton = uibutton(app.CalibrationPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @CalibClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clear all calibration'};
            app.ClearButton.Text = 'Clear';
            app.manualcalibpanel = uipanel(app.CalibrationPanel);
            app.phaS = uispinner(app.manualcalibpanel);
            app.phaS.Step = 0.01;
            app.phaS.Limits = [-3.1416 3.1416];
            app.phaS.ValueDisplayFormat = '%.3f';
            app.phaS.ValueChangedFcn = createCallbackFcn(app, @phaSValueChanged, true);
            app.modS = uispinner(app.manualcalibpanel);
            app.modS.Step = 0.01;
            app.modS.Limits = [0.001 Inf];
            app.modS.ValueDisplayFormat = '%.3f';
            app.modS.ValueChangedFcn = createCallbackFcn(app, @modSValueChanged, true);
            app.modS.Value = 1;
            app.phaL = uilabel(app.manualcalibpanel);
            app.phaL.Text = 'Phase shift';
            app.modL = uilabel(app.manualcalibpanel);
            app.modL.Text = 'Mod factor';
            app.DoCalib = uicheckbox(app.CalibHolder);
            app.DoCalib.ValueChangedFcn = createCallbackFcn(app, @DoCalibValueChanged, true);
            app.DoCalib.Tooltip = {'Apply Calibration'};
            app.DoCalib.Text = 'Apply';
            app.LT = uieditfield(app.CalibHolder, 'numeric');
            app.LT.ValueChangedFcn = createCallbackFcn(app, @CalibLifetimeValueChanged, true);
            app.LT.Tooltip = {'Lifetime value for next calibration file'};
            app.CalibrationLifetimensLabel = uilabel(app.CalibHolder);
            app.CalibrationLifetimensLabel.Text = 'Calibration Lifetime (ns)';
            app.Calibrationtype = uibuttongroup(app.CalibHolder);
            app.Calibrationtype.SelectionChangedFcn = createCallbackFcn(app, @CalibrationtypeSelectionChanged, true);
            app.Filecalib = uiradiobutton(app.Calibrationtype);
            app.Filecalib.Tooltip = {'Use reference measurement'};
            app.Filecalib.Text = 'Files';
            app.Filecalib.Value = true;
            app.Manualcalib = uiradiobutton(app.Calibrationtype);
            app.Manualcalib.Tooltip = {'Input calibration'};
            app.Manualcalib.Text = 'Manual';
            app.AxisContainer = uipanel(app.GSLabUIFigure);
            app.TabEixos = uiaxes(app.AxisContainer);
            app.TabEixos.XTick = [];
            app.TabEixos.YTick = [];
            app.ExportType = uicheckbox(app.GSLabUIFigure);
            app.ExportType.Text = 'Phasor Export Issue';
            app.hidecalib = uibutton(app.GSLabUIFigure, 'push');
            app.hidecalib.ButtonPushedFcn = createCallbackFcn(app, @hidecalibButtonPushed, true);
            app.hidecalib.Tooltip = {'Calibration panel'};
            app.hidecalib.Text = '▴';
            app.barra = uiimage(app.GSLabUIFigure);
            app.vlabel = uilabel(app.GSLabUIFigure);
            app.vlabel.Text = '';
            app.Filterbefore = uicheckbox(app.GSLabUIFigure);
            app.Filterbefore.ValueChangedFcn = createCallbackFcn(app, @FilterbeforeValueChanged, true);
            app.Filterbefore.Tooltip = {'Filter before or after thresholding'};
            app.Filterbefore.Text = 'Filter before threshold';
            app.Filterbefore.Value = true;
            app.ContextMenu = uicontextmenu(app.GSLabUIFigure);
            app.CalibrationFileMenu = uimenu(app.ContextMenu);
            app.CalibrationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationFileMenuSelected, true);
            app.CalibrationFileMenu.Text = 'Calibration File';
            app.Load = uimenu(app.ContextMenu);
            app.Load.Text = 'Load';
            app.LoadTIFSequenceContextMenu = uimenu(app.Load);
            app.LoadTIFSequenceContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTIFSequenceContextMenuSelected, true);
            app.LoadTIFSequenceContextMenu.Text = 'TIF Sequence';
            app.LoadFLAMErawContextMenu = uimenu(app.Load);
            app.LoadFLAMErawContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFLAMErawContextMenuSelected, true);
            app.LoadFLAMErawContextMenu.Text = 'FLAME raw';
            app.LoadFALCONSnGContextMenu = uimenu(app.Load);
            app.LoadFALCONSnGContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFALCONSnGContextMenuSelected, true);
            app.LoadFALCONSnGContextMenu.Text = 'Leica FALCON SnG';
            app.LoadZeisslsmContextMenu = uimenu(app.Load);
            app.LoadZeisslsmContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadZeisslsmContextMenuSelected, true);
            app.LoadZeisslsmContextMenu.Text = 'Zeiss lsm';
            app.FLIMLABSjsonMenu = uimenu(app.Load);
            app.FLIMLABSjsonMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFLIMlabsjsonMenuSelected, true);
            app.FLIMLABSjsonMenu.Text = 'FLIM LABS json';
            app.PicoQuantptuMenu = uimenu(app.Load);
            app.PicoQuantptuMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPicoQuantptuMenuSelected, true);
            app.PicoQuantptuMenu.Tooltip = {'Requires CGohlke Python library'};
            app.PicoQuantptuMenu.Text = 'PicoQuant ptu';
            app.BeckerHicklsdtMenu = uimenu(app.Load);
            app.BeckerHicklsdtMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadBeckerHicklsdtMenuSelected, true);
            app.BeckerHicklsdtMenu.Tooltip = {'Requires CGohlke Python library'};
            app.BeckerHicklsdtMenu.Text = 'Becker&&Hickl sdt';
            app.LoadSimFCSrefContextMenu = uimenu(app.Load);
            app.LoadSimFCSrefContextMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSimFCSrefContextMenuSelected, true);
            app.LoadSimFCSrefContextMenu.Text = 'SimFCS ref';
            app.TracetxtcsvMenu = uimenu(app.Load);
            app.TracetxtcsvMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTraceContextMenuSelected, true);
            app.TracetxtcsvMenu.Text = 'Trace txt/csv';
            app.FLAMEcalibration = uimenu(app.Load);
            app.FLAMEcalibration.MenuSelectedFcn = createCallbackFcn(app, @CalibrationLoadMenuSelected, true);
            app.FLAMEcalibration.Separator = 'on';
            app.FLAMEcalibration.Text = 'FLAME Calibration';
            app.SavedSessionMenu = uimenu(app.Load);
            app.SavedSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSavedSessionMenuSelected, true);
            app.SavedSessionMenu.Separator = 'on';
            app.SavedSessionMenu.Text = 'Saved Session';
            app.SaveMenu = uimenu(app.ContextMenu);
            app.SaveMenu.Text = 'Save';
            app.asSimFCSrefMenu = uimenu(app.SaveMenu);
            app.asSimFCSrefMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSimFCSrefContextSelected, true);
            app.asSimFCSrefMenu.Text = 'as SimFCS ref';
            app.CurrentSessionMenu = uimenu(app.SaveMenu);
            app.CurrentSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCurrentSessionMenuSelected, true);
            app.CurrentSessionMenu.Text = 'Current Session';
            app.MoveMenu = uimenu(app.ContextMenu);
            app.MoveMenu.Separator = 'on';
            app.MoveMenu.Text = 'Move';
            app.TopMenu = uimenu(app.MoveMenu);
            app.TopMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveTopMenuSelected, true);
            app.TopMenu.Tooltip = {'Move selected to top'};
            app.TopMenu.Text = 'Top';
            app.UpMenu = uimenu(app.MoveMenu);
            app.UpMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveUpMenuSelected, true);
            app.UpMenu.Tooltip = {'Move selected one up'};
            app.UpMenu.Text = 'Up';
            app.DownMenu = uimenu(app.MoveMenu);
            app.DownMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveDownMenuSelected, true);
            app.DownMenu.Tooltip = {'Move selected one down'};
            app.DownMenu.Text = 'Down';
            app.BotMenu = uimenu(app.MoveMenu);
            app.BotMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveBotMenuSelected, true);
            app.BotMenu.Tooltip = {'Move selected to bot'};
            app.BotMenu.Text = 'Bot';
            app.EvenOddMenu = uimenu(app.MoveMenu);
            app.EvenOddMenu.MenuSelectedFcn = createCallbackFcn(app, @MoveEvenOdd, true);
            app.EvenOddMenu.Tooltip = {'List even first and odd after (deintercalate)'};
            app.EvenOddMenu.Text = 'Even/Odd';
            app.CheckMenu = uimenu(app.ContextMenu);
            app.CheckMenu.Text = 'Check';
            app.AllNoneMenu = uimenu(app.CheckMenu);
            app.AllNoneMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckedAllNoneMenuSelected, true);
            app.AllNoneMenu.Separator = 'on';
            app.AllNoneMenu.Text = 'All/None';
            app.InvertMenu = uimenu(app.CheckMenu);
            app.InvertMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckedInvertMenuSelected, true);
            app.InvertMenu.Tooltip = {'Check unchecked and uncheck checked'};
            app.InvertMenu.Text = 'Invert';
            app.AlternateMenu = uimenu(app.CheckMenu);
            app.AlternateMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckAlternateMenuSelected, true);
            app.AlternateMenu.Text = 'Alternate';
            app.EmphasisMenu = uimenu(app.ContextMenu);
            app.EmphasisMenu.Text = 'Emphasis';
            app.SelectedMenu = uimenu(app.EmphasisMenu);
            app.SelectedMenu.MenuSelectedFcn = createCallbackFcn(app, @EmphasisSelectedMenuSelected, true);
            app.SelectedMenu.Text = 'Selected';
            app.CheckedMenu = uimenu(app.EmphasisMenu);
            app.CheckedMenu.MenuSelectedFcn = createCallbackFcn(app, @EmphasisCheckedMenuSelected, true);
            app.CheckedMenu.Text = 'Checked';
            app.NoneMenu = uimenu(app.EmphasisMenu);
            app.NoneMenu.MenuSelectedFcn = createCallbackFcn(app, @EmphasisNoneMenuSelected, true);
            app.NoneMenu.Text = 'None';
            app.ClearMenu = uimenu(app.ContextMenu);
            app.ClearMenu.Text = 'Clear';
            app.AllMenu = uimenu(app.ClearMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllContextSelected, true);
            app.AllMenu.Text = 'All';
            app.UncheckedMenu = uimenu(app.ClearMenu);
            app.UncheckedMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearUncheckedMenuSelected, true);
            app.UncheckedMenu.Text = 'Unchecked';
            % Assign app.ContextMenu
            app.Tree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.GSLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GSLab

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GSLabUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.GSLabUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GSLabUIFigure)
        end
    end
end